classdef JSmolSelectionDialog_deployed < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        JSmolWindowPanel              matlab.ui.container.Panel
        HTML                          matlab.ui.control.HTML
        RightPanel                    matlab.ui.container.Panel
        Panel                         matlab.ui.container.Panel
        InstructionsPanel             matlab.ui.container.Panel
        SelectdeselectLabel           matlab.ui.control.Label
        DeselectallwithinboxLabel     matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        HoldSHIFTLabel                matlab.ui.control.Label
        HoldSHIFTALTSHIFTOPTIONonMacLabel  matlab.ui.control.Label
        StructurerepresentationPanel  matlab.ui.container.Panel
        CartoonButton                 matlab.ui.control.StateButton
        BallStickButton               matlab.ui.control.StateButton
        SelectionPanel                matlab.ui.container.Panel
        invertButton                  matlab.ui.control.Button
        SaveButton                    matlab.ui.control.Button
        DoneButton                    matlab.ui.control.Button
        clickAfterLoad                matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        JSmol
        callingApp
        win
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, JSmolLibPath, scriptStr, structureSel)
            warning off Matlab:structOnObject 
            
            app.HTML.HTMLSource = fullfile(JSmolLibPath, 'jsmol_matlab_static.htm');
                        
            app.callingApp = callingApp;
            app.JSmol.scriptStr = scriptStr;
            app.JSmol.structureSel = structureSel;
                        
            pause(1);
            
            figProps = struct(app.UIFigure);
            controller = figProps.Controller;
            controllerProps = struct(controller);
            platformHost = controllerProps.PlatformHost;
            platformHostProps = struct(platformHost);
            app.win = platformHostProps.CEF;
            
            pause(1);
            
            % Get the window object of the iframe (uihtml container )
            app.win.executeJS('var x = document.getElementsByTagName("iframe")[0].contentWindow;');
                        
            % Start load file dialog
            JScommand='x.window.eval(''Jmol.loadFileFromDialog(jmolApplet0)'')';
            app.win.executeJS(JScommand);
            
        end

        % Button pushed function: invertButton
        function invertButtonPushed(app, event)
                               
            app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"select not selected")'')');
            
        end

        % Value changed function: BallStickButton
        function BallStickButtonValueChanged(app, event)
           
            if app.BallStickButton.Value
                app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"spacefill reset; wireframe reset")'')');
                app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"select not selected")'')');
                app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"spacefill reset; wireframe reset")'')');
                app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"select not selected")'')');
            else
                app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"spacefill off; wireframe off")'')');
                app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"select not selected")'')');
                app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"spacefill off; wireframe off")'')');
                app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"select not selected")'')');
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {545, 545};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {552, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: CartoonButton
        function CartoonButtonValueChanged(app, event)
                       
            if app.CartoonButton.Value
                app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"cartoon on")'')');
                app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"select not selected")'')');
                app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"cartoon on")'')');
                app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"select not selected")'')');
            else
                app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"cartoon off")'')');
                app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"select not selected")'')');
                app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"cartoon off")'')');
                app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"select not selected")'')');
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
         
            if strcmp(app.JSmol.structureSel, 'rotating')
                defaultPdbpath=fullfile(app.callingApp.paths.userDir,'ROIatmIndex_rot.txt');               
            else
                defaultPdbpath=fullfile(app.callingApp.paths.userDir,'ROIatmIndex_fix.txt');
            end
            
            if isdeployed
                [filename, pathname] = uiputfile('*', 'Save ROI', defaultPdbpath);
                pdbpath = fullfile(pathname, filename);
            else
                pdbpath = defaultPdbpath;
            end
                        
            app.callingApp.paths.ROIpdbpath = pdbpath;
            
            JScommand = sprintf('x.window.eval(''Jmol.script(jmolApplet0,"x={selected}; write VAR x %s")'')', pdbpath);
            app.win.executeJS(JScommand);
            
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: clickAfterLoad
        function clickAfterLoadButtonPushed(app, event)
            JScommand = sprintf('x.window.eval(''Jmol.script(jmolApplet0,"%s")'')', app.JSmol.scriptStr);
            
            fprintf('\n JScommand: %s', JScommand);
            app.win.executeJS(JScommand);
            
            app.Panel.Visible = true;
            app.clickAfterLoad.Visible = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.JSmolWindowPanel = uipanel(app.LeftPanel);
            app.JSmolWindowPanel.Title = 'JSmol Window';
            app.HTML = uihtml(app.JSmolWindowPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.Panel = uipanel(app.RightPanel);
            app.Panel.Visible = 'off';
            app.InstructionsPanel = uipanel(app.Panel);
            app.InstructionsPanel.Title = 'Instructions';
            app.SelectdeselectLabel = uilabel(app.InstructionsPanel);
            app.SelectdeselectLabel.Text = {'Select/'; 'deselect'};
            app.DeselectallwithinboxLabel = uilabel(app.InstructionsPanel);
            app.DeselectallwithinboxLabel.Text = {'Deselect all'; 'within box'};
            app.Label = uilabel(app.InstructionsPanel);
            app.Label.Text = {'Click-and-drag inside the JSmol window '; 'while pressing the select/deselct keys '; 'defined below.'};
            app.HoldSHIFTLabel = uilabel(app.InstructionsPanel);
            app.HoldSHIFTLabel.Text = 'Hold SHIFT';
            app.HoldSHIFTALTSHIFTOPTIONonMacLabel = uilabel(app.InstructionsPanel);
            app.HoldSHIFTALTSHIFTOPTIONonMacLabel.Text = {'Hold SHIFT+ALT'; '(SHIFT+OPTION on Mac)'};
            app.StructurerepresentationPanel = uipanel(app.Panel);
            app.StructurerepresentationPanel.Title = 'Structure representation';
            app.CartoonButton = uibutton(app.StructurerepresentationPanel, 'state');
            app.CartoonButton.ValueChangedFcn = createCallbackFcn(app, @CartoonButtonValueChanged, true);
            app.CartoonButton.Text = 'Cartoon';
            app.BallStickButton = uibutton(app.StructurerepresentationPanel, 'state');
            app.BallStickButton.ValueChangedFcn = createCallbackFcn(app, @BallStickButtonValueChanged, true);
            app.BallStickButton.Text = 'Ball&Stick';
            app.BallStickButton.Value = true;
            app.SelectionPanel = uipanel(app.Panel);
            app.SelectionPanel.Title = 'Selection';
            app.invertButton = uibutton(app.SelectionPanel, 'push');
            app.invertButton.ButtonPushedFcn = createCallbackFcn(app, @invertButtonPushed, true);
            app.invertButton.Text = 'invert';
            app.SaveButton = uibutton(app.SelectionPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.DoneButton = uibutton(app.Panel, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.clickAfterLoad = uibutton(app.RightPanel, 'push');
            app.clickAfterLoad.ButtonPushedFcn = createCallbackFcn(app, @clickAfterLoadButtonPushed, true);
            app.clickAfterLoad.Text = {'Load the pdb file of the'; 'structure and then click here'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = JSmolSelectionDialog_deployed(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end