classdef JSmolViewer_alignment < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ZEALUIFigure                   matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        FixedstructureTab              matlab.ui.container.Tab
        DisplayPanel_fix               matlab.ui.container.Panel
        DisplayButtonGroup_fix         matlab.ui.container.ButtonGroup
        SpacefillButton_fix            matlab.ui.control.RadioButton
        BallStickButton_fix            matlab.ui.control.RadioButton
        WireframeButton_fix            matlab.ui.control.RadioButton
        SticksButton_fix               matlab.ui.control.RadioButton
        Panel_fix                      matlab.ui.container.Panel
        AtomsCheckBox_fix              matlab.ui.control.CheckBox
        BondsCheckBox_fix              matlab.ui.control.CheckBox
        StructurePanel_fix             matlab.ui.container.Panel
        BackboneCheckBox_fix           matlab.ui.control.CheckBox
        CartoonCheckBox_fix            matlab.ui.control.CheckBox
        TraceCheckBox_fix              matlab.ui.control.CheckBox
        ColorschemePanel_fix           matlab.ui.container.Panel
        DropDown_fix                   matlab.ui.control.DropDown
        ButtonGroup_CS_fix             matlab.ui.container.ButtonGroup
        CPKButton_2                    matlab.ui.control.RadioButton
        AminoButton_2                  matlab.ui.control.RadioButton
        StructureButton_2              matlab.ui.control.RadioButton
        PickcolorButton                matlab.ui.control.Button
        hideCheckBox_fix               matlab.ui.control.CheckBox
        SurfacePanel_fix               matlab.ui.container.Panel
        TypeDropDownLabel              matlab.ui.control.Label
        TypeDropDown_fix               matlab.ui.control.DropDown
        TransparencyDropDownLabel_fix  matlab.ui.control.Label
        TransparencyDropDown_fix       matlab.ui.control.DropDown
        reloadScene_fix                matlab.ui.control.Button
        RotatingstructureTab           matlab.ui.container.Tab
        DisplayPanel                   matlab.ui.container.Panel
        DisplayButtonGroup_rot         matlab.ui.container.ButtonGroup
        SpacefillButton_rot            matlab.ui.control.RadioButton
        BallStickButton_rot            matlab.ui.control.RadioButton
        WireframeButton_rot            matlab.ui.control.RadioButton
        SticksButton_rot               matlab.ui.control.RadioButton
        Panel                          matlab.ui.container.Panel
        AtomsCheckBox_rot              matlab.ui.control.CheckBox
        BondsCheckBox_rot              matlab.ui.control.CheckBox
        StructurePanel                 matlab.ui.container.Panel
        BackboneCheckBox_rot           matlab.ui.control.CheckBox
        CartoonCheckBox_rot            matlab.ui.control.CheckBox
        TraceCheckBox_rot              matlab.ui.control.CheckBox
        ColorschemePanel               matlab.ui.container.Panel
        DropDown_rot                   matlab.ui.control.DropDown
        ButtonGroup_rot                matlab.ui.container.ButtonGroup
        CPKButton                      matlab.ui.control.RadioButton
        AminoButton                    matlab.ui.control.RadioButton
        StructureButton                matlab.ui.control.RadioButton
        PickcolorButton_rot            matlab.ui.control.Button
        hideCheckBox_rot               matlab.ui.control.CheckBox
        SurfacePanel_rot               matlab.ui.container.Panel
        TypeDropDownLabel_2            matlab.ui.control.Label
        TypeDropDown_rot               matlab.ui.control.DropDown
        TransparencyDropDownLabel_rot  matlab.ui.control.Label
        TransparencyDropDown_rot       matlab.ui.control.DropDown
        reloadScene_rot                matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        JSmolWindowPanel               matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        HTML                           matlab.ui.control.HTML
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        win
        callingApp
        JSmol
    end
    
    methods (Access = private)
        
        function loadScene(app)
            % Get the window object of the iframe (uihtml container )
            app.win.executeJS('var x = document.getElementsByTagName("iframe")[0].contentWindow;');
            
            JScommand = sprintf('x.window.eval(''Jmol.script(jmolApplet0,"%s")'')', app.JSmol.defaultSceneScript);
            
            fprintf('\n JScommand: %s', JScommand);
            app.win.executeJS(JScommand);
            
%             app.reloadScene_fix.Visible = false;
%             app.reloadScene_rot.Visible = false;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, pdbJSmolMatStruct, JSmolLibPath, webGLop, fixStructure, rotStructure, scriptStr)
            warning off Matlab:structOnObject
            warning off MATLAB:ui:javaframe:PropertyToBeRemoved
            
            %Position app            
            screen_res = get(0,'screensize');
            
            app_position = app.ZEALUIFigure.Position;
            
            app_xpos = screen_res(3)/2-app_position(3)/2;
            app_ypos = screen_res(4)/2-app_position(4)/2;
            
            app.ZEALUIFigure.Position = [app_xpos app_ypos app_position(3) app_position(4)];
            
            app.HTML.Data = pdbJSmolMatStruct;
            waitTime = 0.1; % number of seconds to wait until trying to send commands to Jmol
            
            if webGLop
                %app.HTML.HTMLSource = fullfile(JSmolLibPath, 'jsmolWebGL_matlab_static.htm');
                app.HTML.HTMLSource = fullfile(JSmolLibPath, 'jsmolWebGL_matlabDataTransfer.htm');
                app.TypeDropDown_fix.Items = {'None','van der Waals surface','solvent-accessible surface'};
                app.TransparencyDropDown_fix.Enable = false;
                app.TransparencyDropDown_fix.Tooltip = 'Deactivate webGL to enable translucent surfaces; untick the webGL checkbox in ZEAL''s graphics options.';
                
                app.TypeDropDown_rot.Items = {'None','van der Waals surface','solvent-accessible surface'};
                app.TransparencyDropDown_rot.Enable = false;
                app.TransparencyDropDown_rot.Tooltip = 'Deactivate webGL to enable translucent surfaces; untick the webGL checkbox in ZEAL''s graphics options.';
                
                fprintf('\n Using WebGL and HTML5\n');
            else
                app.HTML.HTMLSource = fullfile(JSmolLibPath, 'jsmol_matlab_static.htm');
                app.TypeDropDown_fix.Items = {'None','van der Waals surface','solvent-accessible surface','solvent-excluded surface'};
                
                app.TypeDropDown_rot.Items = {'None','van der Waals surface','solvent-accessible surface','solvent-excluded surface'};
                
                fprintf('\n Using HTML5\n');
            end
            
            app.JSmol.defaultSceneScript = scriptStr;
            app.JSmol.rotAlpha = 0;
            app.JSmol.fixAlpha = 0;
            
            app.JSmol.rotColor = rotStructure.color;
            app.JSmol.fixColor = fixStructure.color;
            
            app.FixedstructureTab.ForegroundColor = app.JSmol.fixColor/255;
            app.RotatingstructureTab.ForegroundColor = app.JSmol.rotColor/255;
            
            app.callingApp = callingApp;
            
            pause(0.1);
            
            figProps = struct(app.ZEALUIFigure);
            
            ControllerExist = isfield(figProps,'Controller');
            
            while ControllerExist ~= true
                pause(0.2);
                ControllerExist = isfield(figProps,'Controller');
            end
            
            fprintf('\n Controller object created: %d', ControllerExist);
            
            controller = figProps.Controller;
            controllerProps = struct(controller);
            
            PlatformHostExist = isfield(controllerProps,'PlatformHost');
            
            while PlatformHostExist ~= true
                pause(0.2);
                PlatformHostExist = isfield(controllerProps,'PlatformHost');
            end
            
            fprintf('\n Platformhost object created: %d', PlatformHostExist);
                       
            platformHost = controllerProps.PlatformHost;
            platformHostProps = struct(platformHost);
            
            CefExist = isfield(platformHostProps,'CEF');
            
            while CefExist ~= true
                pause(0.2);
                CefExist = isfield(platformHostProps,'CEF');
            end
            
            fprintf('\n CEF object created: %d', CefExist);
                     
            app.win = platformHostProps.CEF;
            
            fprintf('\nWaiting %d s for the Jmol object to be created', waitTime);
            pause(waitTime);
            
            try
                % Get the window object of the iframe (uihtml container )
                app.win.executeJS('var x = document.getElementsByTagName("iframe")[0].contentWindow;');
                
                JScommand = sprintf('x.window.eval(''Jmol.script(jmolApplet0,"%s")'')', app.JSmol.defaultSceneScript);
                
                fprintf('\n JScommand: %s', JScommand);
                app.win.executeJS(JScommand);
                
            catch
%                 app.reloadScene_fix.Visible = true;
%                 app.reloadScene_rot.Visible = true;
                
                warningMsg = sprintf('Could not syncronize correctly with JSmol. Click reload to fix it.');
                uialert(app.ZEALUIFigure, warningMsg, 'Warning', 'icon', 'warning');                
            end
           
        end

        % Selection changed function: DisplayButtonGroup_rot
        function rotDisplayButtonGroupSelectionChanged(app, event)
            selectedButton = app.DisplayButtonGroup_rot.SelectedObject;
            
            switch selectedButton.Text
                case 'Spacefill'
                    app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"select 1.2; wireframe off; backbone off;wireframe off;spacefill 100%")'')');
                    app.AtomsCheckBox_rot.Value = true;
                    app.BondsCheckBox_rot.Value = false;
                case 'Wireframe'
                    app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"select 1.2; cpk off; wireframe on; backbone off;spacefill off;wireframe on")'')');
                    app.BondsCheckBox_rot.Value = true;
                    app.AtomsCheckBox_rot.Value = false;
                case 'Ball & Stick'
                    app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"select 1.2; cpk 20%; backbone off;spacefill 20%;wireframe 0.15")'')');
                    app.AtomsCheckBox_rot.Value = true;
                    app.BondsCheckBox_rot.Value = true;
                case 'Sticks'
                    app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"select 1.2; cpk off; backbone off;spacefill off;wireframe 0.3")'')');
                    app.BondsCheckBox_rot.Value = true;
                    app.AtomsCheckBox_rot.Value = false;
            end
            
            app.rotBackboneCheckBoxValueChanged(app);
            
        end

        % Value changed function: AtomsCheckBox_rot
        function rotAtomsCheckBoxValueChanged(app, event)
            
            if app.AtomsCheckBox_rot.Value
                app.rotDisplayButtonGroupSelectionChanged(app);
                %app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"select 1.2; cpk 100%")'')');
            else
                app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"select 1.2; cpk off")'')');
            end

        end

        % Value changed function: BondsCheckBox_rot
        function rotBondsCheckBoxValueChanged(app, event)
            
            if app.BondsCheckBox_rot.Value
                app.rotDisplayButtonGroupSelectionChanged(app);
                %app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"select 1.2; wireframe on")'')');
            else
                app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"select 1.2; wireframe off")'')');
            end
            
            app.rotBackboneCheckBoxValueChanged(app);
            
        end

        % Value changed function: CartoonCheckBox_rot
        function rotCartoonCheckBoxValueChanged(app, event)
            
            if app.CartoonCheckBox_rot.Value
                
                JScommand = 'x.window.eval(''Jmol.script(jmolApplet0,"select 1.2; cartoon on;")'')';
                
                app.win.executeJS(JScommand);                
               
            else
                
                JScommand = 'x.window.eval(''Jmol.script(jmolApplet0,"select 1.2; cartoon off;")'')';
                
                app.win.executeJS(JScommand);
                
            end
            
        end

        % Value changed function: BackboneCheckBox_rot
        function rotBackboneCheckBoxValueChanged(app, event)
           
            if app.BackboneCheckBox_rot.Value
                
                JSmolCommand = 'x.window.eval(''Jmol.script(jmolApplet0,"select 1.2; backbone 0.3;")'')';
                
                app.win.executeJS(JSmolCommand);
               
            else
                
                JSmolCommand = 'x.window.eval(''Jmol.script(jmolApplet0,"select 1.2; backbone off;")'')';
                
                app.win.executeJS(JSmolCommand);
               
            end
            
        end

        % Value changed function: TraceCheckBox_rot
        function rotTraceCheckBoxtValueChanged(app, event)
            
            if app.TraceCheckBox_rot.Value
                app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"select 1.2; trace 0.3;")'')');
               
            else
                app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"select 1.2; trace off;")'')');
               
            end
        end

        % Button pushed function: PickcolorButton_rot
        function rotPickcolorButtonPushed(app, event)
            
            defaultColor = [0    0.4470    0.7410];
            pickcolor = uisetcolor(defaultColor);
            
            setCol = sprintf('select 1.2; cartoon ONLY; color [%d %d %d]', pickcolor(1), pickcolor(2), pickcolor(3)); % all atoms in model 2
            
            JScommand = sprintf('x.window.eval(''Jmol.script(jmolApplet0,"%s")'')', setCol);
            app.win.executeJS(JScommand);
            
        end

        % Selection changed function: ButtonGroup_rot
        function rotColorButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup_rot.SelectedObject;
            
            colMode = lower(app.DropDown_rot.Value);
            
            switch selectedButton.Text
                case 'CPK'
                    JSmolCommand = sprintf('x.window.eval(''Jmol.script(jmolApplet0,"select 2.1; color %s CPK")'')', colMode);
                    
                case 'Amino'
                    JSmolCommand = sprintf('x.window.eval(''Jmol.script(jmolApplet0,"select 2.1; color %s Amino")'')', colMode);
                    
                case 'Structure'
                    JSmolCommand = sprintf('x.window.eval(''Jmol.script(jmolApplet0,"select 2.1; color %s Structure")'')', colMode);
            end
            
            app.win.executeJS(JSmolCommand);
            
        end

        % Value changed function: hideCheckBox_fix, hideCheckBox_rot
        function hideCheckBoxValueChanged(app, event)
            
            if app.hideCheckBox_rot.Value && ~app.hideCheckBox_fix.Value
                JSmolCommand = 'x.window.eval(''Jmol.script(jmolApplet0,"display 1.1")'')';
                
            elseif ~app.hideCheckBox_rot.Value && app.hideCheckBox_fix.Value
                JSmolCommand = 'x.window.eval(''Jmol.script(jmolApplet0,"display 1.2")'')';
                
            elseif app.hideCheckBox_rot.Value && app.hideCheckBox_fix.Value
                JSmolCommand = 'x.window.eval(''Jmol.script(jmolApplet0,"hide 1.1,1.2")'')';
                
            elseif ~app.hideCheckBox_rot.Value && ~app.hideCheckBox_fix.Value
                JSmolCommand = 'x.window.eval(''Jmol.script(jmolApplet0,"display 1.1,1.2")'')';
            end
            
            fprintf('\n %s\n ', JSmolCommand);
            app.win.executeJS(JSmolCommand);
            
        end

        % Selection changed function: DisplayButtonGroup_fix
        function fixDisplayButtonGroupSelectionChanged(app, event)
            selectedButton = app.DisplayButtonGroup_fix.SelectedObject;

            switch selectedButton.Text
                case 'Spacefill'
                    app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"select 1.1; wireframe off; backbone off;wireframe off;spacefill 100%")'')');
                    app.AtomsCheckBox_fix.Value = true;
                    app.BondsCheckBox_fix.Value = false;
                case 'Wireframe'
                    app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"select 1.1; cpk off; wireframe on; backbone off;spacefill off;wireframe on")'')');
                    app.BondsCheckBox_fix.Value = true;
                    app.AtomsCheckBox_fix.Value = false;
                case 'Ball & Stick'
                    %app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"select 1.1; cpk 20%; backbone off;spacefill 20%;wireframe 0.15")'')');
                    app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"select 1.1; cpk 20%; backbone off;spacefill 20%;wireframe 0.15")'')');

                    app.AtomsCheckBox_fix.Value = true;
                    app.BondsCheckBox_fix.Value = true;
                case 'Sticks'
                    app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"select 1.1; cpk off; backbone off;spacefill off;wireframe 0.3")'')');
                    app.BondsCheckBox_fix.Value = true;
                    app.AtomsCheckBox_fix.Value = false;
            end
            
            app.fixBackboneCheckBoxValueChanged(app);
        end

        % Value changed function: AtomsCheckBox_fix
        function fixAtomsCheckBoxValueChanged(app, event)
          
            if app.AtomsCheckBox_fix.Value
                fixDisplayButtonGroupSelectionChanged(app);
                %app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"select 1.1; cpk 100%")'')');
            else
                app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"select 1.1; cpk off")'')');
            end
            
            app.fixBackboneCheckBoxValueChanged(app);
            
        end

        % Value changed function: BondsCheckBox_fix
        function fixBondsCheckBoxValueChanged(app, event)
     
            if app.BondsCheckBox_fix.Value
                fixDisplayButtonGroupSelectionChanged(app);
                %app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"select 1.1; wireframe on")'')');
            else
                app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"select 1.1; wireframe off")'')');
            end
            
            app.fixBackboneCheckBoxValueChanged(app);
        end

        % Value changed function: BackboneCheckBox_fix
        function fixBackboneCheckBoxValueChanged(app, event)
          
            if app.BackboneCheckBox_fix.Value
                
                JSmolCommand = 'x.window.eval(''Jmol.script(jmolApplet0,"select 1.1; backbone 0.3;")'')';
                
                app.win.executeJS(JSmolCommand);
             
            else
                
                JSmolCommand = 'x.window.eval(''Jmol.script(jmolApplet0,"select 1.1; backbone off;")'')';
                
                app.win.executeJS(JSmolCommand);                
            end
        end

        % Value changed function: TraceCheckBox_fix
        function fixTraceCheckBoxValueChanged(app, event)
           
            if app.TraceCheckBox_fix.Value
                app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"select 1.1; trace 0.3; ")'')');
                
            else
                app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"select 1.1; trace off;")'')');
               
            end
        end

        % Value changed function: CartoonCheckBox_fix
        function fixCartoonCheckBoxValueChanged(app, event)
           
            if app.CartoonCheckBox_fix.Value
                
                JScommand = 'x.window.eval(''Jmol.script(jmolApplet0,"select 1.1; cartoon on;")'')';
                
                app.win.executeJS(JScommand);
                
            else
                
                JScommand = 'x.window.eval(''Jmol.script(jmolApplet0,"select 1.1; cartoon off;")'')';
                
                app.win.executeJS(JScommand);
                                
            end
        end

        % Selection changed function: ButtonGroup_CS_fix
        function ButtonGroup_CS_fixSelectionChanged(app, event)
            selectedButton = app.ButtonGroup_CS_fix.SelectedObject;
            
            colMode = lower(app.DropDown_fix.Value);
            
            switch selectedButton.Text
                case 'CPK'
                    JSmolCommand = sprintf('x.window.eval(''Jmol.script(jmolApplet0,"select 1.1; color %s CPK")'')', colMode);
                    
                case 'Amino'
                    JSmolCommand = sprintf('x.window.eval(''Jmol.script(jmolApplet0,"select 1.1; color %s Amino")'')', colMode);
                    
                case 'Structure'
                    JSmolCommand = sprintf('x.window.eval(''Jmol.script(jmolApplet0,"select 1.1; color %s Structure")'')', colMode);
            end
            
            app.win.executeJS(JSmolCommand);
        end

        % Button pushed function: PickcolorButton
        function fixPickcolorButtonPushed(app, event)
            defaultColor = [0.8500    0.3250    0.0980];
            pickcolor = uisetcolor(defaultColor);
            
            setCol = sprintf('select 1.1; cartoon ONLY; color [%d %d %d]', pickcolor(1), pickcolor(2), pickcolor(3)); % all atoms in model 2
            
            JScommand = sprintf('x.window.eval(''Jmol.script(jmolApplet0,"%s")'')', setCol);
            app.win.executeJS(JScommand);
        end

        % Value changed function: TypeDropDown_fix
        function TypeDropDown_fixValueChanged(app, event)
            
             switch app.TypeDropDown_fix.Value
                case '1'
                    surfType = 'none';
                case '2'
                    surfType = 'sasurface';
                    probeRadius = 0;
                case '3'
                    surfType = 'sasurface';
                    probeRadius = 1.4;
                case '4'
                    surfType = 'solvent';
                    probeRadius = 1.4;                    
            end
            
            if strcmp(surfType,'none')
                JSmolCommand = sprintf('x.window.eval(''Jmol.script(jmolApplet0,"select 1.1; isosurface A off;")'')');
                app.win.executeJS(JSmolCommand);
            else
                 JSmolCommand = sprintf('x.window.eval(''Jmol.script(jmolApplet0,"select 1.1; isosurface A %s %1.1f;isosurface off;")'')', surfType, probeRadius);
                app.win.executeJS(JSmolCommand);
                
                setCol = sprintf('select 1.1; color isosurface [%d %d %d] translucent %1.1f; isosurface A on;', app.JSmol.fixColor(1), app.JSmol.fixColor(2), app.JSmol.fixColor(3), app.JSmol.fixAlpha); % all atoms in model 2
                
                JScommand = sprintf('x.window.eval(''Jmol.script(jmolApplet0,"%s")'')', setCol);
                app.win.executeJS(JScommand);
                
            end
        end

        % Value changed function: TypeDropDown_rot
        function TypeDropDown_rotValueChanged(app, event)
            switch app.TypeDropDown_rot.Value
                case '1'
                    surfType = 'none';
                case '2'
                    surfType = 'sasurface';
                    probeRadius = 0;
                case '3'
                    surfType = 'sasurface';
                    probeRadius = 1.4;
                case '4'
                    surfType = 'solvent';
                    probeRadius = 1.4;                    
            end
            
            if strcmp(surfType,'none')
                JSmolCommand = sprintf('x.window.eval(''Jmol.script(jmolApplet0,"select 2.1; isosurface B off;")'')');
                app.win.executeJS(JSmolCommand);
            else
                 JSmolCommand = sprintf('x.window.eval(''Jmol.script(jmolApplet0,"select 2.1; isosurface B %s %1.1f;isosurface B off;")'')', surfType, probeRadius);
                app.win.executeJS(JSmolCommand);
                
                setCol = sprintf('select 1.1; color isosurface [%d %d %d] translucent %2.1f; isosurface B on;', app.JSmol.rotColor(1), app.JSmol.rotColor(2), app.JSmol.rotColor(3), app.JSmol.rotAlpha); % all atoms in model 2
                
                JScommand = sprintf('x.window.eval(''Jmol.script(jmolApplet0,"%s")'')', setCol);
                app.win.executeJS(JScommand);
                
            end
        end

        % Value changed function: TransparencyDropDown_fix
        function TransparencyDropDown_fixValueChanged(app, event)
            
            switch app.TransparencyDropDown_fix.Value
                case '1'
                    app.JSmol.fixAlpha = 0;
                case '2'
                    app.JSmol.fixAlpha = 0.25;
                case '3'
                    app.JSmol.fixAlpha = 0.5;
                case '4'
                    app.JSmol.fixAlpha = 0.75;
            end
            
             if ~strcmp(app.TypeDropDown_fix.Value,'1')
                setCol = sprintf('select 1.1; color isosurface [%d %d %d] translucent %1.1f; isosurface A on;', app.JSmol.fixColor(1), app.JSmol.fixColor(2), app.JSmol.fixColor(3), app.JSmol.fixAlpha); % all atoms in model 2
                
                JScommand = sprintf('x.window.eval(''Jmol.script(jmolApplet0,"%s")'')', setCol);
                app.win.executeJS(JScommand);
             end
             
        end

        % Value changed function: TransparencyDropDown_rot
        function TransparencyDropDown_rotValueChanged(app, event)
            
            switch app.TransparencyDropDown_rot.Value
                case '1'
                    app.JSmol.rotAlpha = 0;
                case '2'
                    app.JSmol.rotAlpha = 0.25;
                case '3'
                    app.JSmol.rotAlpha = 0.5;
                case '4'
                    app.JSmol.rotAlpha = 0.75;
            end
            
             if ~strcmp(app.TypeDropDown_rot.Value,'1')
                setCol = sprintf('select 2.1; color isosurface [%d %d %d] translucent %1.1f; isosurface A on;', app.JSmol.rotColor(1), app.JSmol.rotColor(2), app.JSmol.rotColor(3), app.JSmol.rotAlpha); % all atoms in model 2
                
                JScommand = sprintf('x.window.eval(''Jmol.script(jmolApplet0,"%s")'')', setCol);
                app.win.executeJS(JScommand);
             end
        end

        % Button pushed function: reloadScene_fix, reloadScene_rot
        function reloadScene_fixPushed(app, event)
            loadScene(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ZEALUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {558, 558};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {289, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ZEALUIFigure = uifigure('Visible', 'off');
            app.ZEALUIFigure.Name = 'ZEAL';
            app.ZEALUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.ZEALUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.FixedstructureTab = uitab(app.TabGroup);
            app.FixedstructureTab.Title = 'Fixed structure';
            app.DisplayPanel_fix = uipanel(app.FixedstructureTab);
            app.DisplayPanel_fix.Title = 'Display';
            app.DisplayButtonGroup_fix = uibuttongroup(app.DisplayPanel_fix);
            app.DisplayButtonGroup_fix.SelectionChangedFcn = createCallbackFcn(app, @fixDisplayButtonGroupSelectionChanged, true);
            app.SpacefillButton_fix = uiradiobutton(app.DisplayButtonGroup_fix);
            app.SpacefillButton_fix.Text = 'Spacefill';
            app.SpacefillButton_fix.Value = true;
            app.BallStickButton_fix = uiradiobutton(app.DisplayButtonGroup_fix);
            app.BallStickButton_fix.Text = 'Ball & Stick';
            app.WireframeButton_fix = uiradiobutton(app.DisplayButtonGroup_fix);
            app.WireframeButton_fix.Text = 'Wireframe';
            app.SticksButton_fix = uiradiobutton(app.DisplayButtonGroup_fix);
            app.SticksButton_fix.Text = 'Sticks';
            app.Panel_fix = uipanel(app.DisplayPanel_fix);
            app.AtomsCheckBox_fix = uicheckbox(app.Panel_fix);
            app.AtomsCheckBox_fix.ValueChangedFcn = createCallbackFcn(app, @fixAtomsCheckBoxValueChanged, true);
            app.AtomsCheckBox_fix.Text = 'Atoms';
            app.BondsCheckBox_fix = uicheckbox(app.Panel_fix);
            app.BondsCheckBox_fix.ValueChangedFcn = createCallbackFcn(app, @fixBondsCheckBoxValueChanged, true);
            app.BondsCheckBox_fix.Text = 'Bonds';
            app.StructurePanel_fix = uipanel(app.FixedstructureTab);
            app.StructurePanel_fix.Title = 'Structure';
            app.BackboneCheckBox_fix = uicheckbox(app.StructurePanel_fix);
            app.BackboneCheckBox_fix.ValueChangedFcn = createCallbackFcn(app, @fixBackboneCheckBoxValueChanged, true);
            app.BackboneCheckBox_fix.Text = 'Backbone';
            app.CartoonCheckBox_fix = uicheckbox(app.StructurePanel_fix);
            app.CartoonCheckBox_fix.ValueChangedFcn = createCallbackFcn(app, @fixCartoonCheckBoxValueChanged, true);
            app.CartoonCheckBox_fix.Text = 'Cartoon';
            app.CartoonCheckBox_fix.Value = true;
            app.TraceCheckBox_fix = uicheckbox(app.StructurePanel_fix);
            app.TraceCheckBox_fix.ValueChangedFcn = createCallbackFcn(app, @fixTraceCheckBoxValueChanged, true);
            app.TraceCheckBox_fix.Text = 'Trace';
            app.ColorschemePanel_fix = uipanel(app.FixedstructureTab);
            app.ColorschemePanel_fix.Title = 'Color scheme';
            app.DropDown_fix = uidropdown(app.ColorschemePanel_fix);
            app.DropDown_fix.Items = {'Atoms', 'Backbone', 'Cartoon', 'Trace'};
            app.DropDown_fix.Value = 'Atoms';
            app.ButtonGroup_CS_fix = uibuttongroup(app.ColorschemePanel_fix);
            app.ButtonGroup_CS_fix.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_CS_fixSelectionChanged, true);
            app.CPKButton_2 = uiradiobutton(app.ButtonGroup_CS_fix);
            app.CPKButton_2.Text = 'CPK';
            app.CPKButton_2.Value = true;
            app.AminoButton_2 = uiradiobutton(app.ButtonGroup_CS_fix);
            app.AminoButton_2.Text = 'Amino';
            app.StructureButton_2 = uiradiobutton(app.ButtonGroup_CS_fix);
            app.StructureButton_2.Text = 'Structure';
            app.PickcolorButton = uibutton(app.ColorschemePanel_fix, 'push');
            app.PickcolorButton.ButtonPushedFcn = createCallbackFcn(app, @fixPickcolorButtonPushed, true);
            app.PickcolorButton.Text = 'Pick color';
            app.hideCheckBox_fix = uicheckbox(app.FixedstructureTab);
            app.hideCheckBox_fix.ValueChangedFcn = createCallbackFcn(app, @hideCheckBoxValueChanged, true);
            app.hideCheckBox_fix.Text = 'hide';
            app.SurfacePanel_fix = uipanel(app.FixedstructureTab);
            app.SurfacePanel_fix.Title = 'Surface';
            app.SurfacePanel_fix.Visible = 'off';
            app.TypeDropDownLabel = uilabel(app.SurfacePanel_fix);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown_fix = uidropdown(app.SurfacePanel_fix);
            app.TypeDropDown_fix.Items = {'None', 'van der Waals surface', 'Solvent-accessible surface', 'Solvent excluded (molecular) surface'};
            app.TypeDropDown_fix.ItemsData = {'1', '2', '3', '4'};
            app.TypeDropDown_fix.ValueChangedFcn = createCallbackFcn(app, @TypeDropDown_fixValueChanged, true);
            app.TypeDropDown_fix.Value = '1';
            app.TransparencyDropDownLabel_fix = uilabel(app.SurfacePanel_fix);
            app.TransparencyDropDownLabel_fix.Text = 'Transparency';
            app.TransparencyDropDown_fix = uidropdown(app.SurfacePanel_fix);
            app.TransparencyDropDown_fix.Items = {' 0 %', '25 %', '50 %', '75 %'};
            app.TransparencyDropDown_fix.ItemsData = {'1', '2', '3', '4'};
            app.TransparencyDropDown_fix.ValueChangedFcn = createCallbackFcn(app, @TransparencyDropDown_fixValueChanged, true);
            app.TransparencyDropDown_fix.Value = '1';
            app.reloadScene_fix = uibutton(app.FixedstructureTab, 'push');
            app.reloadScene_fix.ButtonPushedFcn = createCallbackFcn(app, @reloadScene_fixPushed, true);
            app.reloadScene_fix.Icon = 'refresh_100px.png';
            app.reloadScene_fix.Text = 'Reload';
            app.RotatingstructureTab = uitab(app.TabGroup);
            app.RotatingstructureTab.Title = 'Rotating structure';
            app.DisplayPanel = uipanel(app.RotatingstructureTab);
            app.DisplayPanel.Title = 'Display';
            app.DisplayButtonGroup_rot = uibuttongroup(app.DisplayPanel);
            app.DisplayButtonGroup_rot.SelectionChangedFcn = createCallbackFcn(app, @rotDisplayButtonGroupSelectionChanged, true);
            app.SpacefillButton_rot = uiradiobutton(app.DisplayButtonGroup_rot);
            app.SpacefillButton_rot.Text = 'Spacefill';
            app.SpacefillButton_rot.Value = true;
            app.BallStickButton_rot = uiradiobutton(app.DisplayButtonGroup_rot);
            app.BallStickButton_rot.Text = 'Ball & Stick';
            app.WireframeButton_rot = uiradiobutton(app.DisplayButtonGroup_rot);
            app.WireframeButton_rot.Text = 'Wireframe';
            app.SticksButton_rot = uiradiobutton(app.DisplayButtonGroup_rot);
            app.SticksButton_rot.Text = 'Sticks';
            app.Panel = uipanel(app.DisplayPanel);
            app.AtomsCheckBox_rot = uicheckbox(app.Panel);
            app.AtomsCheckBox_rot.ValueChangedFcn = createCallbackFcn(app, @rotAtomsCheckBoxValueChanged, true);
            app.AtomsCheckBox_rot.Text = 'Atoms';
            app.BondsCheckBox_rot = uicheckbox(app.Panel);
            app.BondsCheckBox_rot.ValueChangedFcn = createCallbackFcn(app, @rotBondsCheckBoxValueChanged, true);
            app.BondsCheckBox_rot.Text = 'Bonds';
            app.StructurePanel = uipanel(app.RotatingstructureTab);
            app.StructurePanel.Title = 'Structure';
            app.BackboneCheckBox_rot = uicheckbox(app.StructurePanel);
            app.BackboneCheckBox_rot.ValueChangedFcn = createCallbackFcn(app, @rotBackboneCheckBoxValueChanged, true);
            app.BackboneCheckBox_rot.Text = 'Backbone';
            app.CartoonCheckBox_rot = uicheckbox(app.StructurePanel);
            app.CartoonCheckBox_rot.ValueChangedFcn = createCallbackFcn(app, @rotCartoonCheckBoxValueChanged, true);
            app.CartoonCheckBox_rot.Text = 'Cartoon';
            app.CartoonCheckBox_rot.Value = true;
            app.TraceCheckBox_rot = uicheckbox(app.StructurePanel);
            app.TraceCheckBox_rot.ValueChangedFcn = createCallbackFcn(app, @rotTraceCheckBoxtValueChanged, true);
            app.TraceCheckBox_rot.Text = 'Trace';
            app.ColorschemePanel = uipanel(app.RotatingstructureTab);
            app.ColorschemePanel.Title = 'Color scheme';
            app.DropDown_rot = uidropdown(app.ColorschemePanel);
            app.DropDown_rot.Items = {'Atoms', 'Backbone', 'Cartoon', 'Trace'};
            app.DropDown_rot.Value = 'Atoms';
            app.ButtonGroup_rot = uibuttongroup(app.ColorschemePanel);
            app.ButtonGroup_rot.SelectionChangedFcn = createCallbackFcn(app, @rotColorButtonGroupSelectionChanged, true);
            app.CPKButton = uiradiobutton(app.ButtonGroup_rot);
            app.CPKButton.Text = 'CPK';
            app.CPKButton.Value = true;
            app.AminoButton = uiradiobutton(app.ButtonGroup_rot);
            app.AminoButton.Text = 'Amino';
            app.StructureButton = uiradiobutton(app.ButtonGroup_rot);
            app.StructureButton.Text = 'Structure';
            app.PickcolorButton_rot = uibutton(app.ColorschemePanel, 'push');
            app.PickcolorButton_rot.ButtonPushedFcn = createCallbackFcn(app, @rotPickcolorButtonPushed, true);
            app.PickcolorButton_rot.Text = 'Pick color';
            app.hideCheckBox_rot = uicheckbox(app.RotatingstructureTab);
            app.hideCheckBox_rot.ValueChangedFcn = createCallbackFcn(app, @hideCheckBoxValueChanged, true);
            app.hideCheckBox_rot.Text = 'hide';
            app.SurfacePanel_rot = uipanel(app.RotatingstructureTab);
            app.SurfacePanel_rot.Title = 'Surface';
            app.SurfacePanel_rot.Visible = 'off';
            app.TypeDropDownLabel_2 = uilabel(app.SurfacePanel_rot);
            app.TypeDropDownLabel_2.Text = 'Type';
            app.TypeDropDown_rot = uidropdown(app.SurfacePanel_rot);
            app.TypeDropDown_rot.Items = {'None', 'van der Waals surface', 'Solvent-accessible surface', 'Solvent excluded (molecular) surface'};
            app.TypeDropDown_rot.ItemsData = {'1', '2', '3', '4'};
            app.TypeDropDown_rot.ValueChangedFcn = createCallbackFcn(app, @TypeDropDown_rotValueChanged, true);
            app.TypeDropDown_rot.Value = '1';
            app.TransparencyDropDownLabel_rot = uilabel(app.SurfacePanel_rot);
            app.TransparencyDropDownLabel_rot.Text = 'Transparency';
            app.TransparencyDropDown_rot = uidropdown(app.SurfacePanel_rot);
            app.TransparencyDropDown_rot.Items = {' 0 %', '25 %', '50 %', '75 %'};
            app.TransparencyDropDown_rot.ItemsData = {'1', '2', '3', '4'};
            app.TransparencyDropDown_rot.ValueChangedFcn = createCallbackFcn(app, @TransparencyDropDown_rotValueChanged, true);
            app.TransparencyDropDown_rot.Value = '1';
            app.reloadScene_rot = uibutton(app.RotatingstructureTab, 'push');
            app.reloadScene_rot.ButtonPushedFcn = createCallbackFcn(app, @reloadScene_fixPushed, true);
            app.reloadScene_rot.Icon = 'refresh_100px.png';
            app.reloadScene_rot.Text = 'Reload';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.JSmolWindowPanel = uipanel(app.GridLayout3);
            app.JSmolWindowPanel.Title = 'JSmol Window';
            app.GridLayout2 = uigridlayout(app.JSmolWindowPanel);
            app.HTML = uihtml(app.GridLayout2);
            % Show the figure after all components are created
            app.ZEALUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = JSmolViewer_alignment(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ZEALUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ZEALUIFigure)
        end
    end
end