classdef JSmolViewer_deployed < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ZEALUIFigure               matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        JSmolWindowPanel           matlab.ui.container.Panel
        HTML                       matlab.ui.control.HTML
        RightPanel                 matlab.ui.container.Panel
        DisplayPanel               matlab.ui.container.Panel
        DisplayButtonGroup         matlab.ui.container.ButtonGroup
        SpacefillButton_           matlab.ui.control.RadioButton
        BallStickButton_           matlab.ui.control.RadioButton
        WireframeButton            matlab.ui.control.RadioButton
        SticksButton               matlab.ui.control.RadioButton
        Panel                      matlab.ui.container.Panel
        AtomsCheckBox              matlab.ui.control.CheckBox
        BondsCheckBox              matlab.ui.control.CheckBox
        StructurePanel             matlab.ui.container.Panel
        BackboneCheckBox           matlab.ui.control.CheckBox
        CartoonCheckBox            matlab.ui.control.CheckBox
        TraceCheckBox              matlab.ui.control.CheckBox
        ColorschemePanel_          matlab.ui.container.Panel
        DropDown                   matlab.ui.control.DropDown
        ButtonGroup_CS             matlab.ui.container.ButtonGroup
        CPKButton                  matlab.ui.control.RadioButton
        AminoButton                matlab.ui.control.RadioButton
        StructureButton            matlab.ui.control.RadioButton
        PickcolorButton            matlab.ui.control.Button
        SurfacePanel               matlab.ui.container.Panel
        TypeDropDownLabel          matlab.ui.control.Label
        TypeDropDown               matlab.ui.control.DropDown
        TransparencyDropDownLabel  matlab.ui.control.Label
        TransparencyDropDown       matlab.ui.control.DropDown
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        win
        JSmol
        callingApp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, JSmolLibPath, webGLop, structure, scriptStr)
           warning off Matlab:structOnObject 
           
           if webGLop
                app.HTML.HTMLSource = fullfile(JSmolLibPath, 'jsmolWebGL_matlab_static.htm');
                app.TypeDropDown.Items = {'None','van der Waals surface','solvent-accessible surface'};
                app.TransparencyDropDown.Enable = false;
                app.TransparencyDropDown.Tooltip = 'Deactivate webGL to enable translucent surfaces; untick the webGL checkbox in ZEAL''s graphics options.';
                fprintf('\n Using WebGL and HTML5\n');
            else
                app.HTML.HTMLSource = fullfile(JSmolLibPath, 'jsmol_matlab_static.htm');
                app.TypeDropDown.Items = {'None','van der Waals surface','solvent-accessible surface','solvent-excluded surface'};
                fprintf('\n Using HTML5\n');
           end
            
            app.JSmol.alpha = 0;
            app.JSmol.scriptStr = scriptStr;
            app.JSmol.defaultColor = structure.color;
            
            app.callingApp = callingApp;

            pause(1);
            
            figProps = struct(app.ZEALUIFigure);
            controller = figProps.Controller;
            controllerProps = struct(controller);
            platformHost = controllerProps.PlatformHost;
            platformHostProps = struct(platformHost);
            app.win = platformHostProps.CEF;
            
            pause(1);
            
            % Get the window object of the iframe (uihtml container )
            app.win.executeJS('var x = document.getElementsByTagName("iframe")[0].contentWindow;');
                 
            JScommand = sprintf('x.window.eval(''Jmol.script(jmolApplet0,"%s")'')', app.JSmol.scriptStr);
            
            fprintf('\n JScommand: %s', JScommand);
            app.win.executeJS(JScommand);
            
            pause(0.5);
            
            % start load file dialog
            JScommand='x.window.eval(''Jmol.loadFileFromDialog(jmolApplet0),"Load"'')';
            app.win.executeJS(JScommand);


        end

        % Selection changed function: DisplayButtonGroup
        function DisplayButtonGroupSelectionChanged(app, event)
            selectedButton = app.DisplayButtonGroup.SelectedObject;

            
            switch selectedButton.Text
                case 'Spacefill'
                    app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"wireframe off; backbone off;wireframe off;spacefill 100%")'')');
                    app.AtomsCheckBox.Value = true;
                    app.BondsCheckBox.Value = false;
                case 'Wireframe'
                    app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"cpk off; wireframe on; backbone off;spacefill off;wireframe on")'')');
                    app.BondsCheckBox.Value = true;
                    app.AtomsCheckBox.Value = false;
                case 'Ball & Stick'
                    app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"cpk 20%; backbone off;spacefill 20%;wireframe 0.15")'')');
                    app.AtomsCheckBox.Value = true;
                    app.BondsCheckBox.Value = true;
                case 'Sticks'
                    app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"cpk off; backbone off;spacefill off;wireframe 0.3")'')');
                    app.BondsCheckBox.Value = true;
                    app.AtomsCheckBox.Value = false;
            end
        end

        % Value changed function: AtomsCheckBox
        function AtomsCheckBoxValueChanged(app, event)
          
            if app.AtomsCheckBox.Value
                app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"cpk 20%")'')');
            else
                app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"cpk off")'')');
            end
        end

        % Value changed function: BondsCheckBox
        function BondsCheckBoxValueChanged(app, event)
     
            if app.BondsCheckBox.Value
                app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"wireframe on")'')');
            else
                app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"wireframe off")'')');
            end
        end

        % Value changed function: BackboneCheckBox
        function BackboneCheckBoxValueChanged(app, event)
          
            if app.BackboneCheckBox.Value
                
                JSmolCommand = 'x.window.eval(''Jmol.script(jmolApplet0,"backbone 0.3; cpk off; wireframe off")'')';
                
                app.win.executeJS(JSmolCommand);
                app.AtomsCheckBox.Value = false;
                app.BondsCheckBox.Value = false;
            else
                
                JSmolCommand = 'x.window.eval(''Jmol.script(jmolApplet0,"backbone off; cpk 20%; wireframe on")'')';
                
                app.win.executeJS(JSmolCommand);
                app.AtomsCheckBox.Value = true;
                app.BondsCheckBox.Value = true;
            end
        end

        % Value changed function: TraceCheckBox
        function TraceCheckBoxValueChanged(app, event)
           
            if app.TraceCheckBox.Value
                app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"trace 0.3; cpk off; wireframe off")'')');
                app.AtomsCheckBox.Value = false;
                app.BondsCheckBox.Value = false;
            else
                app.win.executeJS('x.window.eval(''Jmol.script(jmolApplet0,"trace off; cpk 20%; wireframe on")'')');
                app.AtomsCheckBox.Value = true;
                app.BondsCheckBox.Value = true;
            end
        end

        % Value changed function: CartoonCheckBox
        function CartoonCheckBoxValueChanged(app, event)
           
            if app.CartoonCheckBox.Value
                
                JScommand = 'x.window.eval(''Jmol.script(jmolApplet0,"cartoon on; cpk off; wireframe off")'')';
                
                app.win.executeJS(JScommand);
                
                app.AtomsCheckBox.Value = false;
                app.BondsCheckBox.Value = false;
            else
                
                JScommand = 'x.window.eval(''Jmol.script(jmolApplet0,"cartoon off; cpk 20%; wireframe on")'')';
                
                app.win.executeJS(JScommand);
                
                app.AtomsCheckBox.Value = true;
                app.BondsCheckBox.Value = true;
            end
        end

        % Selection changed function: ButtonGroup_CS
        function ButtonGroup_CSSelectionChanged(app, event)
            selectedButton = app.ButtonGroup_CS.SelectedObject;
            
            colMode = lower(app.DropDown.Value);
            
            switch selectedButton.Text
                case 'CPK'
                    JSmolCommand = sprintf('x.window.eval(''Jmol.script(jmolApplet0,"color %s CPK")'')', colMode);
                    
                case 'Amino'
                    JSmolCommand = sprintf('x.window.eval(''Jmol.script(jmolApplet0,"color %s Amino")'')', colMode);
                    
                case 'Structure'
                    JSmolCommand = sprintf('x.window.eval(''Jmol.script(jmolApplet0,"color %s Structure")'')', colMode);
            end
            
            app.win.executeJS(JSmolCommand);
        end

        % Button pushed function: PickcolorButton
        function PickcolorButtonPushed(app, event)
            defaultColor = [0.8500    0.3250    0.0980];
            pickcolor = uisetcolor(defaultColor);
            
            app.JSmol.defaultColor = pickcolor;
            
            setCol = sprintf('color [%d %d %d]', pickcolor(1), pickcolor(2), pickcolor(3)); % all atoms in model 2
            
            JScommand = sprintf('x.window.eval(''Jmol.script(jmolApplet0,"%s")'')', setCol);
            app.win.executeJS(JScommand);
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            switch app.TypeDropDown.Value
                case '1'
                    surfType = 'none';
                case '2'
                    surfType = 'sasurface';
                    probeRadius = 0;
                case '3'
                    surfType = 'sasurface';
                    probeRadius = 1.4;
                case '4'
                    surfType = 'solvent';
                    probeRadius = 1.4;                    
            end
            
            if strcmp(surfType,'none')
                JSmolCommand = sprintf('x.window.eval(''Jmol.script(jmolApplet0,"isosurface off;")'')');
                app.win.executeJS(JSmolCommand);
            else
                 JSmolCommand = sprintf('x.window.eval(''Jmol.script(jmolApplet0,"isosurface %s %1.1f;isosurface off;")'')', surfType, probeRadius);
                app.win.executeJS(JSmolCommand);
                
                setCol = sprintf('color isosurface [%d %d %d] translucent %1.1f; isosurface on;', app.JSmol.defaultColor(1), app.JSmol.defaultColor(2), app.JSmol.defaultColor(3), app.JSmol.alpha); % all atoms in model 2
                
                JScommand = sprintf('x.window.eval(''Jmol.script(jmolApplet0,"%s")'')', setCol);
                app.win.executeJS(JScommand);
                
            end
        end

        % Value changed function: TransparencyDropDown
        function TransparencyDropDownValueChanged(app, event)
             switch app.TransparencyDropDown.Value
                case '1'
                    app.JSmol.alpha = 0;
                case '2'
                    app.JSmol.alpha = 0.25;
                case '3'
                    app.JSmol.alpha = 0.5;
                case '4'
                    app.JSmol.alpha = 0.75;
            end
            
            if ~strcmp(app.TypeDropDown.Value,'1')
                setCol = sprintf('color isosurface [%d %d %d] translucent %1.1f; isosurface on;', app.JSmol.defaultColor(1), app.JSmol.defaultColor(2), app.JSmol.defaultColor(3), app.JSmol.alpha); % all atoms in model 2
                
                JScommand = sprintf('x.window.eval(''Jmol.script(jmolApplet0,"%s")'')', setCol);
                app.win.executeJS(JScommand);
            end
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ZEALUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {543, 543};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {550, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ZEALUIFigure = uifigure('Visible', 'off');
            app.ZEALUIFigure.Name = 'ZEAL';
            app.ZEALUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.ZEALUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.JSmolWindowPanel = uipanel(app.LeftPanel);
            app.JSmolWindowPanel.Title = 'JSmol Window';
            app.HTML = uihtml(app.JSmolWindowPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.DisplayPanel = uipanel(app.RightPanel);
            app.DisplayPanel.Title = 'Display';
            app.DisplayButtonGroup = uibuttongroup(app.DisplayPanel);
            app.DisplayButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DisplayButtonGroupSelectionChanged, true);
            app.SpacefillButton_ = uiradiobutton(app.DisplayButtonGroup);
            app.SpacefillButton_.Text = 'Spacefill';
            app.SpacefillButton_.Value = true;
            app.BallStickButton_ = uiradiobutton(app.DisplayButtonGroup);
            app.BallStickButton_.Text = 'Ball & Stick';
            app.WireframeButton = uiradiobutton(app.DisplayButtonGroup);
            app.WireframeButton.Text = 'Wireframe';
            app.SticksButton = uiradiobutton(app.DisplayButtonGroup);
            app.SticksButton.Text = 'Sticks';
            app.Panel = uipanel(app.DisplayPanel);
            app.AtomsCheckBox = uicheckbox(app.Panel);
            app.AtomsCheckBox.ValueChangedFcn = createCallbackFcn(app, @AtomsCheckBoxValueChanged, true);
            app.AtomsCheckBox.Text = 'Atoms';
            app.BondsCheckBox = uicheckbox(app.Panel);
            app.BondsCheckBox.ValueChangedFcn = createCallbackFcn(app, @BondsCheckBoxValueChanged, true);
            app.BondsCheckBox.Text = 'Bonds';
            app.StructurePanel = uipanel(app.RightPanel);
            app.StructurePanel.Title = 'Structure';
            app.BackboneCheckBox = uicheckbox(app.StructurePanel);
            app.BackboneCheckBox.ValueChangedFcn = createCallbackFcn(app, @BackboneCheckBoxValueChanged, true);
            app.BackboneCheckBox.Text = 'Backbone';
            app.CartoonCheckBox = uicheckbox(app.StructurePanel);
            app.CartoonCheckBox.ValueChangedFcn = createCallbackFcn(app, @CartoonCheckBoxValueChanged, true);
            app.CartoonCheckBox.Text = 'Cartoon';
            app.CartoonCheckBox.Value = true;
            app.TraceCheckBox = uicheckbox(app.StructurePanel);
            app.TraceCheckBox.ValueChangedFcn = createCallbackFcn(app, @TraceCheckBoxValueChanged, true);
            app.TraceCheckBox.Text = 'Trace';
            app.ColorschemePanel_ = uipanel(app.RightPanel);
            app.ColorschemePanel_.Title = 'Color scheme';
            app.DropDown = uidropdown(app.ColorschemePanel_);
            app.DropDown.Items = {'Atoms', 'Backbone', 'Cartoon', 'Trace'};
            app.DropDown.Value = 'Atoms';
            app.ButtonGroup_CS = uibuttongroup(app.ColorschemePanel_);
            app.ButtonGroup_CS.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_CSSelectionChanged, true);
            app.CPKButton = uiradiobutton(app.ButtonGroup_CS);
            app.CPKButton.Text = 'CPK';
            app.CPKButton.Value = true;
            app.AminoButton = uiradiobutton(app.ButtonGroup_CS);
            app.AminoButton.Text = 'Amino';
            app.StructureButton = uiradiobutton(app.ButtonGroup_CS);
            app.StructureButton.Text = 'Structure';
            app.PickcolorButton = uibutton(app.ColorschemePanel_, 'push');
            app.PickcolorButton.ButtonPushedFcn = createCallbackFcn(app, @PickcolorButtonPushed, true);
            app.PickcolorButton.Text = 'Pick color';
            app.SurfacePanel = uipanel(app.RightPanel);
            app.SurfacePanel.Title = 'Surface';
            app.TypeDropDownLabel = uilabel(app.SurfacePanel);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.SurfacePanel);
            app.TypeDropDown.Items = {'None', 'van der Waals surface', 'Solvent-accessible surface', 'Solvent excluded (molecular) surface'};
            app.TypeDropDown.ItemsData = {'1', '2', '3', '4'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = '1';
            app.TransparencyDropDownLabel = uilabel(app.SurfacePanel);
            app.TransparencyDropDownLabel.Text = 'Transparency';
            app.TransparencyDropDown = uidropdown(app.SurfacePanel);
            app.TransparencyDropDown.Items = {' 0 %', '25 %', '50 %', '75 %'};
            app.TransparencyDropDown.ItemsData = {'1', '2', '3', '4'};
            app.TransparencyDropDown.ValueChangedFcn = createCallbackFcn(app, @TransparencyDropDownValueChanged, true);
            app.TransparencyDropDown.Value = '1';
            % Show the figure after all components are created
            app.ZEALUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = JSmolViewer_deployed(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ZEALUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ZEALUIFigure)
        end
    end
end