classdef globalOptSettingsDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        OkButton                       matlab.ui.control.Button
        StoppingcriteriaPanel          matlab.ui.container.Panel
        MaxfunctioniterationsSpinnerLabel  matlab.ui.control.Label
        MaxfunctioniterationsSpinner   matlab.ui.control.Spinner
        MaxtimesSpinnerLabel           matlab.ui.control.Label
        MaxtimesSpinner                matlab.ui.control.Spinner
        MaxCorrEditFieldLabel          matlab.ui.control.Label
        MaxCorrEditField               matlab.ui.control.NumericEditField
        SurrogateoptimizationsettingsLabel  matlab.ui.control.Label
        OtherPanel                     matlab.ui.container.Panel
        ViewalignmentprogressCheckBox  matlab.ui.control.CheckBox
        SurrogatecreationphasePanel    matlab.ui.container.Panel
        RandomsamplesSpinnerLabel      matlab.ui.control.Label
        RandomsamplesSpinner           matlab.ui.control.Spinner
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            screen_res = get(0,'screensize');
            
            app_position = app.UIFigure.Position;
            
            app_xpos = screen_res(3)/2-app_position(3)/2;
            app_ypos = screen_res(4)/2-app_position(4)/2;
            
            app.UIFigure.Position = [app_xpos app_ypos app_position(3) app_position(4)];
            
            app.CallingApp = mainapp;
            
            app.MaxfunctioniterationsSpinner.Value = app.CallingApp.surropt.options.MaxFunctionEvaluations;
            app.MaxtimesSpinner.Value = app.CallingApp.surropt.options.MaxTime;
            app.MaxCorrEditField.Value = -1*app.CallingApp.surropt.options.ObjectiveLimit;
            
            app.ViewalignmentprogressCheckBox.Value = app.CallingApp.surropt.viewprogress;
            

        end

        % Value changed function: MaxtimesSpinner
        function MaxtimesSpinnerValueChanged(app, event)
            app.CallingApp.surropt.MaxTime = app.MaxtimesSpinner.Value;
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            delete(app)
        end

        % Value changed function: ViewalignmentprogressCheckBox
        function ViewalignmentprogressCheckBoxValueChanged(app, event)
            app.CallingApp.surropt.viewprogress = app.ViewalignmentprogressCheckBox.Value;
        end

        % Value changed function: MaxfunctioniterationsSpinner
        function MaxfunctioniterationsSpinnerValueChanged(app, event)
            app.CallingApp.surropt.options.MaxFunctionEvaluations =  app.MaxfunctioniterationsSpinner.Value;
        end

        % Callback function
        function RandomsamplesSpinnerValueChanged(app, event)
            app.CallingApp.surropt.options.MinSurrogatePoints = app.RandomsamplesSpinner.Value;   
        end

        % Value changed function: MaxCorrEditField
        function MaxCorrEditFieldValueChanged(app, event)
            app.CallingApp.surropt.options.ObjectiveLimit = -1*app.MaxCorrEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.OkButton = uibutton(app.UIFigure, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.StoppingcriteriaPanel = uipanel(app.UIFigure);
            app.StoppingcriteriaPanel.Title = 'Stopping criteria';
            app.MaxfunctioniterationsSpinnerLabel = uilabel(app.StoppingcriteriaPanel);
            app.MaxfunctioniterationsSpinnerLabel.Text = 'Max function iterations';
            app.MaxfunctioniterationsSpinner = uispinner(app.StoppingcriteriaPanel);
            app.MaxfunctioniterationsSpinner.Step = 10;
            app.MaxfunctioniterationsSpinner.Limits = [1 Inf];
            app.MaxfunctioniterationsSpinner.RoundFractionalValues = 'on';
            app.MaxfunctioniterationsSpinner.ValueDisplayFormat = '%.0f';
            app.MaxfunctioniterationsSpinner.ValueChangedFcn = createCallbackFcn(app, @MaxfunctioniterationsSpinnerValueChanged, true);
            app.MaxfunctioniterationsSpinner.Value = 300;
            app.MaxtimesSpinnerLabel = uilabel(app.StoppingcriteriaPanel);
            app.MaxtimesSpinnerLabel.Text = 'Max time (s)';
            app.MaxtimesSpinner = uispinner(app.StoppingcriteriaPanel);
            app.MaxtimesSpinner.ValueChangedFcn = createCallbackFcn(app, @MaxtimesSpinnerValueChanged, true);
            app.MaxtimesSpinner.Value = Inf;
            app.MaxCorrEditFieldLabel = uilabel(app.StoppingcriteriaPanel);
            app.MaxCorrEditFieldLabel.Text = 'Shape correlation coefficient';
            app.MaxCorrEditField = uieditfield(app.StoppingcriteriaPanel, 'numeric');
            app.MaxCorrEditField.Limits = [0 1];
            app.MaxCorrEditField.ValueChangedFcn = createCallbackFcn(app, @MaxCorrEditFieldValueChanged, true);
            app.MaxCorrEditField.Value = 0.8;
            app.SurrogateoptimizationsettingsLabel = uilabel(app.UIFigure);
            app.SurrogateoptimizationsettingsLabel.Text = 'Surrogate optimization settings';
            app.OtherPanel = uipanel(app.UIFigure);
            app.OtherPanel.Title = 'Other';
            app.ViewalignmentprogressCheckBox = uicheckbox(app.OtherPanel);
            app.ViewalignmentprogressCheckBox.ValueChangedFcn = createCallbackFcn(app, @ViewalignmentprogressCheckBoxValueChanged, true);
            app.ViewalignmentprogressCheckBox.Text = 'View alignment progress';
            app.ViewalignmentprogressCheckBox.Value = true;
            app.SurrogatecreationphasePanel = uipanel(app.UIFigure);
            app.SurrogatecreationphasePanel.Title = 'Surrogate creation phase';
            app.RandomsamplesSpinnerLabel = uilabel(app.SurrogatecreationphasePanel);
            app.RandomsamplesSpinnerLabel.Text = 'Random samples';
            app.RandomsamplesSpinner = uispinner(app.SurrogatecreationphasePanel);
            app.RandomsamplesSpinner.Limits = [6 Inf];
            app.RandomsamplesSpinner.Value = 20;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = globalOptSettingsDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end