classdef graphicsSettingsDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        OkButton                       matlab.ui.control.Button
        GraphicssettingsLabel          matlab.ui.control.Label
        SceneLightingPanel             matlab.ui.container.Panel
        updateCamLightButton           matlab.ui.control.Button
        camLightFollowButton           matlab.ui.control.StateButton
        UpdatelightLabel               matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        LightfollowsviewLabel          matlab.ui.control.Label
        SurfaceSmoothingPanel          matlab.ui.container.Panel
        FilterDropDownLabel            matlab.ui.control.Label
        FilterDropDown                 matlab.ui.control.DropDown
        SizegridunitsSpinnerLabel      matlab.ui.control.Label
        SizegridunitsSpinner           matlab.ui.control.Spinner
        ApplysurfacesmoothingCheckBox  matlab.ui.control.CheckBox
        StandarddeviationEditFieldLabel  matlab.ui.control.Label
        StandarddeviationEditField     matlab.ui.control.NumericEditField
        DefaultButton                  matlab.ui.control.Button
        ShowcoordinatesystemboxCheckBox  matlab.ui.control.CheckBox
        JSmolPanel                     matlab.ui.container.Panel
        UsewebGLCheckBox               matlab.ui.control.CheckBox
        TruetranslucencyCheckBox       matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            screen_res = get(0,'screensize');
            
            app_position = app.UIFigure.Position;
            
            app_xpos = screen_res(3)/2-app_position(3)/2;
            app_ypos = screen_res(4)/2-app_position(4)/2;
            
            app.UIFigure.Position = [app_xpos app_ypos app_position(3) app_position(4)];
            
            app.CallingApp = mainapp;
            
            app.FilterDropDown.ItemsData = [1 2];
            app.FilterDropDown.Value = 1;
            
            % update settings
            app.StandarddeviationEditField.Value = app.CallingApp.plot_settings.smoothing.var;
            app.SizegridunitsSpinner.Value = app.CallingApp.plot_settings.smoothing.size;
            app.FilterDropDown.Value = app.CallingApp.plot_settings.smoothing.filter_type;
            
            app.UsewebGLCheckBox.Value = app.CallingApp.JSmol.webGL;
            if strcmp(app.CallingApp.JSmol.trueTrans,'true')
                app.TruetranslucencyCheckBox.Value = true;
            else
                app.TruetranslucencyCheckBox.Value = false;
            end
            
            app.camLightFollowButton.Value = app.CallingApp.plot_settings.update_camlight_op;
            
            app.ShowcoordinatesystemboxCheckBox.Value = app.CallingApp.plot_settings.crd_box;
            
            switch app.CallingApp.shape_settings.shape_rep_type
                case 1
                    app.ApplysurfacesmoothingCheckBox.Value = app.CallingApp.plot_settings.smoothing.active;
                    app.ApplysurfacesmoothingCheckBox.Enable = true;
                    if app.ApplysurfacesmoothingCheckBox.Value
                        app.FilterDropDown.Enable = true;
                        app.FilterDropDownLabel.Enable = true;
                        
                        app.SizegridunitsSpinner.Enable = true;
                        app.SizegridunitsSpinnerLabel.Enable = true;
                        
                        app.CallingApp.plot_settings.smoothing.active = true;
                        
                        switch app.FilterDropDown.Value
                            case 1
                                app.StandarddeviationEditFieldLabel.Enable = true;
                                app.StandarddeviationEditField.Enable = true;
                                
                            case 2
                                app.StandarddeviationEditFieldLabel.Enable = false;
                                app.StandarddeviationEditField.Enable = false;
                        end
                    else
                        
                        app.CallingApp.plot_settings.smoothing.active = false;
                        
                        app.FilterDropDown.Enable = false;
                        app.FilterDropDownLabel.Enable = false;
                        
                        app.SizegridunitsSpinner.Enable = false;
                        app.SizegridunitsSpinnerLabel.Enable = false;
                        
                        app.StandarddeviationEditField.Enable = false;
                        app.StandarddeviationEditFieldLabel.Enable = false;
                    end
                    
                case 2
                    app.ApplysurfacesmoothingCheckBox.Value = false;
                    app.ApplysurfacesmoothingCheckBox.Enable = false;
            end
            
        end

        % Value changed function: ApplysurfacesmoothingCheckBox
        function ApplysurfacesmoothingCheckBoxValueChanged(app, event)
            
            if app.ApplysurfacesmoothingCheckBox.Value
                app.FilterDropDown.Enable = true;
                app.FilterDropDownLabel.Enable = true;
                
                app.SizegridunitsSpinner.Enable = true;
                app.SizegridunitsSpinnerLabel.Enable = true;
                
                app.CallingApp.plot_settings.smoothing.active = true;
                
                switch app.FilterDropDown.Value
                    case 1
                        app.StandarddeviationEditFieldLabel.Enable = true;
                        app.StandarddeviationEditField.Enable = true;
                        
                    case 2
                        app.StandarddeviationEditFieldLabel.Enable = false;
                        app.StandarddeviationEditField.Enable = false;
                end
            else
                
                app.CallingApp.plot_settings.smoothing.active = false;
                
                app.FilterDropDown.Enable = false;
                app.FilterDropDownLabel.Enable = false;
                
                app.SizegridunitsSpinner.Enable = false;
                app.SizegridunitsSpinnerLabel.Enable = false;
                
                app.StandarddeviationEditField.Enable = false;
                app.StandarddeviationEditFieldLabel.Enable = false;
            end
            
            updateAllShapesAndZCcc(app.CallingApp);
            
        end

        % Value changed function: FilterDropDown
        function FilterDropDownValueChanged(app, event)
            
            app.CallingApp.plot_settings.smoothing.filter_type = app.FilterDropDown.Value;
            
            switch app.FilterDropDown.Value
                
                case 1
                    app.StandarddeviationEditFieldLabel.Enable = true;
                    app.StandarddeviationEditField.Enable = true;
                case 2
                    app.StandarddeviationEditFieldLabel.Enable = false;
                    app.StandarddeviationEditField.Enable = false;
            end
            
            updateAllShapesAndZCcc(app.CallingApp);
                                    
        end

        % Value changed function: SizegridunitsSpinner
        function SizegridunitsSpinnerValueChanged(app, event)
            app.CallingApp.plot_settings.smoothing.size = app.SizegridunitsSpinner.Value;
            
            updateAllShapesAndZCcc(app.CallingApp);
        end

        % Value changed function: StandarddeviationEditField
        function StandarddeviationEditFieldValueChanged(app, event)
            app.CallingApp.plot_settings.smoothing.var = app.StandarddeviationEditField.Value;
            
            updateAllShapesAndZCcc(app.CallingApp);
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: camLightFollowButton
        function camLightFollowButtonValueChanged(app, event)
            app.CallingApp.plot_settings.update_camlight_op = app.camLightFollowButton.Value;
        end

        % Button pushed function: updateCamLightButton
        function updateCamLightButtonPushed(app, event)
            camlight(app.CallingApp.plot_settings.ch,'left');
        end

        % Value changed function: ShowcoordinatesystemboxCheckBox
        function ShowcoordinatesystemboxCheckBoxValueChanged(app, event)
            app.CallingApp.plot_settings.crd_box = app.ShowcoordinatesystemboxCheckBox.Value;
            
            if app.ShowcoordinatesystemboxCheckBox.Value
                app.CallingApp.UIAxes.XColor = [0.5 0.5 0.5];
                app.CallingApp.UIAxes.YColor = [0.5 0.5 0.5];
                app.CallingApp.UIAxes.ZColor = [0.5 0.5 0.5];
            else
                app.CallingApp.UIAxes.XColor = 'none';
                app.CallingApp.UIAxes.YColor = 'none';
                app.CallingApp.UIAxes.ZColor = 'none';
            end
        end

        % Button pushed function: DefaultButton
        function DefaultButtonPushed(app, event)

            app.CallingApp.plot_settings.smoothing.var = 3;
            app.CallingApp.plot_settings.smoothing.size = 5;
            app.CallingApp.plot_settings.smoothing.filter_type = 1;            
            app.CallingApp.plot_settings.update_camlight_op = true;            
            app.CallingApp.plot_settings.crd_box = false;            
            app.CallingApp.plot_settings.smoothing.active = false; 
                
            app.StandarddeviationEditField.Value = app.CallingApp.plot_settings.smoothing.var;
            app.SizegridunitsSpinner.Value = app.CallingApp.plot_settings.smoothing.size;
            app.FilterDropDown.Value = app.CallingApp.plot_settings.smoothing.filter_type;            
            app.camLightFollowButton.Value = app.CallingApp.plot_settings.update_camlight_op;            
            app.ShowcoordinatesystemboxCheckBox.Value = app.CallingApp.plot_settings.crd_box;
            app.ApplysurfacesmoothingCheckBox.Value = app.CallingApp.plot_settings.smoothing.active;
            
            app.FilterDropDown.Enable = false;
            app.FilterDropDownLabel.Enable = false;
            
            app.SizegridunitsSpinner.Enable = false;
            app.SizegridunitsSpinnerLabel.Enable = false;
            
            app.StandarddeviationEditField.Enable = false;
            app.StandarddeviationEditFieldLabel.Enable = false;
            
            app.CallingApp.UIAxes.XColor = 'none';
            app.CallingApp.UIAxes.YColor = 'none';
            app.CallingApp.UIAxes.ZColor = 'none';
            
            
            updateAllShapesAndZCcc(app.CallingApp);
            
        end

        % Value changed function: UsewebGLCheckBox
        function UsewebGLCheckBoxValueChanged(app, event)
           
            app.CallingApp.JSmol.webGL = app.UsewebGLCheckBox.Value;
            
        end

        % Value changed function: TruetranslucencyCheckBox
        function TruetranslucencyCheckBoxValueChanged(app, event)
            
            if app.TruetranslucencyCheckBox.Value
                app.CallingApp.JSmol.trueTrans = 'true';
            else
                app.CallingApp.JSmol.trueTrans = 'false';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.OkButton = uibutton(app.UIFigure, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.GraphicssettingsLabel = uilabel(app.UIFigure);
            app.GraphicssettingsLabel.Text = 'Graphics settings';
            app.SceneLightingPanel = uipanel(app.UIFigure);
            app.SceneLightingPanel.Title = 'Scene Lighting';
            app.updateCamLightButton = uibutton(app.SceneLightingPanel, 'push');
            app.updateCamLightButton.ButtonPushedFcn = createCallbackFcn(app, @updateCamLightButtonPushed, true);
            app.updateCamLightButton.Icon = 'flash_light_100px.png';
            app.updateCamLightButton.Tooltip = {'Camlight is repositioned relative current view'};
            app.updateCamLightButton.Text = '';
            app.camLightFollowButton = uibutton(app.SceneLightingPanel, 'state');
            app.camLightFollowButton.ValueChangedFcn = createCallbackFcn(app, @camLightFollowButtonValueChanged, true);
            app.camLightFollowButton.Tooltip = {'Camlight is continously repositioned relative view'};
            app.camLightFollowButton.Icon = 'spotlight_100px.png';
            app.camLightFollowButton.Text = '';
            app.camLightFollowButton.Value = true;
            app.UpdatelightLabel = uilabel(app.SceneLightingPanel);
            app.UpdatelightLabel.Text = 'Update light';
            app.Label = uilabel(app.SceneLightingPanel);
            app.Label.Text = '';
            app.LightfollowsviewLabel = uilabel(app.SceneLightingPanel);
            app.LightfollowsviewLabel.Text = 'Light follows view';
            app.SurfaceSmoothingPanel = uipanel(app.UIFigure);
            app.SurfaceSmoothingPanel.Title = 'Surface Smoothing';
            app.FilterDropDownLabel = uilabel(app.SurfaceSmoothingPanel);
            app.FilterDropDownLabel.Enable = 'off';
            app.FilterDropDownLabel.Text = 'Filter';
            app.FilterDropDown = uidropdown(app.SurfaceSmoothingPanel);
            app.FilterDropDown.Items = {'Gaussian', 'Box'};
            app.FilterDropDown.ItemsData = {'1', '2', ''};
            app.FilterDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterDropDownValueChanged, true);
            app.FilterDropDown.Enable = 'off';
            app.FilterDropDown.Tooltip = {'The convolution kernel to apply'};
            app.FilterDropDown.Value = '1';
            app.SizegridunitsSpinnerLabel = uilabel(app.SurfaceSmoothingPanel);
            app.SizegridunitsSpinnerLabel.Enable = 'off';
            app.SizegridunitsSpinnerLabel.Text = 'Size (grid units)';
            app.SizegridunitsSpinner = uispinner(app.SurfaceSmoothingPanel);
            app.SizegridunitsSpinner.Step = 2;
            app.SizegridunitsSpinner.Limits = [3 Inf];
            app.SizegridunitsSpinner.ValueDisplayFormat = '%.0f';
            app.SizegridunitsSpinner.ValueChangedFcn = createCallbackFcn(app, @SizegridunitsSpinnerValueChanged, true);
            app.SizegridunitsSpinner.Enable = 'off';
            app.SizegridunitsSpinner.Tooltip = {'Size of the convolution kernel (must be odd integer)'};
            app.SizegridunitsSpinner.Value = 3;
            app.ApplysurfacesmoothingCheckBox = uicheckbox(app.SurfaceSmoothingPanel);
            app.ApplysurfacesmoothingCheckBox.ValueChangedFcn = createCallbackFcn(app, @ApplysurfacesmoothingCheckBoxValueChanged, true);
            app.ApplysurfacesmoothingCheckBox.Text = 'Apply surface smoothing';
            app.StandarddeviationEditFieldLabel = uilabel(app.SurfaceSmoothingPanel);
            app.StandarddeviationEditFieldLabel.Enable = 'off';
            app.StandarddeviationEditFieldLabel.Text = 'Standard deviation';
            app.StandarddeviationEditField = uieditfield(app.SurfaceSmoothingPanel, 'numeric');
            app.StandarddeviationEditField.Limits = [0 Inf];
            app.StandarddeviationEditField.ValueChangedFcn = createCallbackFcn(app, @StandarddeviationEditFieldValueChanged, true);
            app.StandarddeviationEditField.Enable = 'off';
            app.StandarddeviationEditField.Value = 0.65;
            app.DefaultButton = uibutton(app.UIFigure, 'push');
            app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonPushed, true);
            app.DefaultButton.Text = 'Default';
            app.ShowcoordinatesystemboxCheckBox = uicheckbox(app.UIFigure);
            app.ShowcoordinatesystemboxCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowcoordinatesystemboxCheckBoxValueChanged, true);
            app.ShowcoordinatesystemboxCheckBox.Text = 'Show coordinate system (box)';
            app.JSmolPanel = uipanel(app.UIFigure);
            app.JSmolPanel.Title = 'JSmol';
            app.UsewebGLCheckBox = uicheckbox(app.JSmolPanel);
            app.UsewebGLCheckBox.ValueChangedFcn = createCallbackFcn(app, @UsewebGLCheckBoxValueChanged, true);
            app.UsewebGLCheckBox.Tooltip = {'Untick to be able to show translucent surfaces or generate the molecular surface.'};
            app.UsewebGLCheckBox.Text = 'Use webGL';
            app.UsewebGLCheckBox.Value = true;
            app.TruetranslucencyCheckBox = uicheckbox(app.JSmolPanel);
            app.TruetranslucencyCheckBox.ValueChangedFcn = createCallbackFcn(app, @TruetranslucencyCheckBoxValueChanged, true);
            app.TruetranslucencyCheckBox.Text = 'True translucency';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = graphicsSettingsDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end