classdef manualSearchDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        manualDone            matlab.ui.control.Button
        MANUALALIGNMENTLabel  matlab.ui.control.Label
        Image                 matlab.ui.control.Image
        Label                 matlab.ui.control.Label
        trackballMode         matlab.ui.container.ButtonGroup
        StructureButton       matlab.ui.control.ToggleButton
        SceneButton           matlab.ui.control.ToggleButton
        ShowaxesCheckBox      matlab.ui.control.CheckBox
        Label_3               matlab.ui.control.Label
        EvaluateButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % Description            
    end
    
    methods (Access = public)        
        
    end
    
    methods (Access = private)
                
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            screen_res = get(0,'screensize');
            
            app_position = app.UIFigure.Position;
            
            app_xpos = screen_res(3)/2+mainapp.ZEALUIFigure.Position(3)/2;
            app_ypos = screen_res(4)/2-mainapp.ZEALUIFigure.Position(4)/2;
            
            app.UIFigure.Position = [app_xpos app_ypos app_position(3) app_position(4)];
            
            app.CallingApp = mainapp;
                        
            %rotate3d(app.CallingApp.UIAxes,'off');
            
             hold(app.CallingApp.UIAxes,'on');
           
             if app.ShowaxesCheckBox.Value
                 plotLocalAx(app.CallingApp, true);                 
             end
             
             hold(app.CallingApp.UIAxes,'off');
             
             app.CallingApp.plot_settings.btn.Value = 'off';
             
             app.CallingApp.trackball.active = true;
             
             app.CallingApp.trackball.manualAlignment = true;
             
             disableSetupTab(app.CallingApp);
                             
             rotateScene(app.CallingApp,false);
        end

        % Button pushed function: manualDone
        function manualDonePushed(app, event)
            app.CallingApp.trackball.active = false;
            updateAfterSearch(app.CallingApp,'manual');
            app.CallingApp.manual.zcCC = app.CallingApp.alignment.zcCC;
            
            enableSetupTab(app.CallingApp);
            
            plotLocalAx(app.CallingApp, false);             
                        
            app.CallingApp.trackball.active = true;
            app.CallingApp.trackball.scene = true;
            
            app.CallingApp.trackball.manualAlignment = false;
            app.CallingApp.plot_settings.btn.Value = 'on';
            
            pause(0.1);
            
            delete(app);
            
        end

        % Selection changed function: trackballMode
        function trackballModeSelectionChanged(app, event)
            
            if app.StructureButton.Value   
                rotateScene(app.CallingApp,false);                  
                fprintf('\n Virtual trackball rotates structure\n');                          
            else                
                rotateScene(app.CallingApp,true);                
                fprintf('\n Virtual trackball scene\n');                
            end
            
        end

        % Value changed function: ShowaxesCheckBox
        function ShowaxesCheckBoxValueChanged(app, event)
            value = app.ShowaxesCheckBox.Value;
            
            if value
                plotLocalAx(app.CallingApp, true);     
            else
                plotLocalAx(app.CallingApp, false);     
            end
        end

        % Button pushed function: EvaluateButton
        function EvaluateButtonPushed(app, event)
            updateAfterSearch(app.CallingApp,'manual');
            app.CallingApp.manual.zcCC = app.CallingApp.alignment.zcCC;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            manualDonePushed(app);
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.manualDone = uibutton(app.UIFigure, 'push');
            app.manualDone.ButtonPushedFcn = createCallbackFcn(app, @manualDonePushed, true);
            app.manualDone.Text = 'Done';
            app.MANUALALIGNMENTLabel = uilabel(app.UIFigure);
            app.MANUALALIGNMENTLabel.Text = 'MANUAL ALIGNMENT';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'virtualTrackball.png';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'Click-and-drag on the rotating structure to'; 'change its orientation. The structure will '; 'rotate as if it was placed within a virtual'; 'trackball that you move using the mouse.'; ''; 'The behaviour of the virtual trackball is '; 'illustrated below. To rotate about the z-axis'; 'for instance, move the mouse along the blue '; 'line on the trackball.'};
            app.trackballMode = uibuttongroup(app.UIFigure);
            app.trackballMode.SelectionChangedFcn = createCallbackFcn(app, @trackballModeSelectionChanged, true);
            app.StructureButton = uitogglebutton(app.trackballMode);
            app.StructureButton.Text = 'Structure';
            app.StructureButton.Value = true;
            app.SceneButton = uitogglebutton(app.trackballMode);
            app.SceneButton.Text = 'Scene';
            app.ShowaxesCheckBox = uicheckbox(app.trackballMode);
            app.ShowaxesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowaxesCheckBoxValueChanged, true);
            app.ShowaxesCheckBox.Text = 'Show axes';
            app.ShowaxesCheckBox.Value = true;
            app.Label_3 = uilabel(app.trackballMode);
            app.Label_3.Text = {'Switch between rotating the structure '; 'or the entire scene.'};
            app.EvaluateButton = uibutton(app.UIFigure, 'push');
            app.EvaluateButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateButtonPushed, true);
            app.EvaluateButton.Text = 'Evaluate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = manualSearchDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end