classdef randOpSettingsDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        OkButton                    matlab.ui.control.Button
        RandomsearchsettingsLabel   matlab.ui.control.Label
        RotationbyEuleranglesZYZconventionPanel  matlab.ui.container.Panel
        MaximumdegreesSpinnerLabel  matlab.ui.control.Label
        MaximumdegreesSpinner       matlab.ui.control.Spinner
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            screen_res = get(0,'screensize');
            
            app_position = app.UIFigure.Position;
            
            app_xpos = screen_res(3)/2-app_position(3)/2;
            app_ypos = screen_res(4)/2-app_position(4)/2;
            
            app.UIFigure.Position = [app_xpos app_ypos app_position(3) app_position(4)];
            
            app.CallingApp = mainapp;
            
            app.MaximumdegreesSpinner.Value = rad2deg(app.CallingApp.alignment.randrotangle);
            
        end

        % Value changed function: MaximumdegreesSpinner
        function MaximumdegreesSpinnerValueChanged(app, event)
           
            app.CallingApp.alignment.randrotangle = deg2rad(app.MaximumdegreesSpinner.Value);
            
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.OkButton = uibutton(app.UIFigure, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.RandomsearchsettingsLabel = uilabel(app.UIFigure);
            app.RandomsearchsettingsLabel.Text = 'Random search settings';
            app.RotationbyEuleranglesZYZconventionPanel = uipanel(app.UIFigure);
            app.RotationbyEuleranglesZYZconventionPanel.Title = 'Rotation by Euler angles (ZYZ convention)';
            app.MaximumdegreesSpinnerLabel = uilabel(app.RotationbyEuleranglesZYZconventionPanel);
            app.MaximumdegreesSpinnerLabel.Text = 'Maximum (degrees)';
            app.MaximumdegreesSpinner = uispinner(app.RotationbyEuleranglesZYZconventionPanel);
            app.MaximumdegreesSpinner.Limits = [0 Inf];
            app.MaximumdegreesSpinner.ValueChangedFcn = createCallbackFcn(app, @MaximumdegreesSpinnerValueChanged, true);
            app.MaximumdegreesSpinner.Value = 90;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = randOpSettingsDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end