classdef surrOptimPlotDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ShapealignmentoptimizationUIFigure  matlab.ui.Figure
        UIAxes            matlab.ui.control.UIAxes
        StopsearchButton  matlab.ui.control.Button
    end


    properties (Access = private)
        optimPlot % Description
        
        
    end
    
    properties (Access = public)
        callingApp % Description
        ph
    end
    
    methods (Access = private)
        
    end
    
    methods (Access = public)
        
        function updatePlot(app, iteration, fval)
            app.ph.XData = iteration;            
            app.ph.YData = fval;
            refreshdata(app.ph);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, optimPlot)
            
            app.optimPlot = optimPlot;
            app.callingApp = mainApp;
            
            app.UIAxes.Toolbar = [];
            app.UIAxes.Interactions = [];
            app.UIAxes.XLim = [0 app.callingApp.surropt.options.MaxFunctionEvaluations];
            app.ph=plot(app.UIAxes, app.optimPlot.iteration, app.optimPlot.fval);
            app.ph.Marker = 'o';
            
            app.ph.MarkerSize = 4;
            app.ph.MarkerEdgeColor = [1 1 0];
            app.ph.MarkerFaceColor = [1 1 0];
            app.ph.LineStyle='none';
            
            % position window
            screenRes = get(0,'screensize');  
                                    
            app_position = app.ShapealignmentoptimizationUIFigure.Position;
            
            app.ShapealignmentoptimizationUIFigure.Position = [ (screenRes(3)/2-app_position(3)*1.25) (screenRes(4)/2 - app_position(4)/2) app_position(3) app_position(4)];
            
            
        end

        % Button pushed function: StopsearchButton
        function StopsearchButtonPushed(app, event)
            stopSurrOpt(app.callingApp);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ShapealignmentoptimizationUIFigure = uifigure('Visible', 'off');
            app.ShapealignmentoptimizationUIFigure.Name = 'Shape alignment optimization';
            app.UIAxes = uiaxes(app.ShapealignmentoptimizationUIFigure);
            title(app.UIAxes, 'Best correlation: ')
            xlabel(app.UIAxes, 'iteration')
            ylabel(app.UIAxes, 'ZC-moment correlation')
            app.StopsearchButton = uibutton(app.ShapealignmentoptimizationUIFigure, 'push');
            app.StopsearchButton.ButtonPushedFcn = createCallbackFcn(app, @StopsearchButtonPushed, true);
            app.StopsearchButton.Text = 'Stop search';
            % Show the figure after all components are created
            app.ShapealignmentoptimizationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = surrOptimPlotDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ShapealignmentoptimizationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ShapealignmentoptimizationUIFigure)
        end
    end
end