classdef zeal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ZEALUIFigure                    matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        ZEALlogo                        matlab.ui.control.Image
        TabGroup                        matlab.ui.container.TabGroup
        ImportTab                       matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        FIXEDSTRUCTUREPanel             matlab.ui.container.Panel
        importFixPanel                  matlab.ui.container.Panel
        importFix                       matlab.ui.control.Button
        fixPDBIDEditFieldLabel          matlab.ui.control.Label
        fixPDBIDEditField               matlab.ui.control.EditField
        fix_pdbfile                     matlab.ui.control.TextArea
        CHAINIDEditFieldLabel           matlab.ui.control.Label
        fixCHAINIDEditField             matlab.ui.control.EditField
        fixFileNameLabel                matlab.ui.control.Label
        fixSwitch                       matlab.ui.control.Switch
        ROTATINGSTRUCTUREPanel          matlab.ui.container.Panel
        importRotPanel                  matlab.ui.container.Panel
        rotPDBIDEditFieldLabel          matlab.ui.control.Label
        rotPDBIDEditField               matlab.ui.control.EditField
        importRot                       matlab.ui.control.Button
        rotCHAINIDEditFieldLabel        matlab.ui.control.Label
        rotCHAINIDEditField             matlab.ui.control.EditField
        rot_pdbfile                     matlab.ui.control.TextArea
        rotFileNameLabel                matlab.ui.control.Label
        rotSwitch                       matlab.ui.control.Switch
        AlignTab                        matlab.ui.container.Tab
        HistoryGroup                    matlab.ui.container.ButtonGroup
        history_original                matlab.ui.control.ToggleButton
        history_manual                  matlab.ui.control.ToggleButton
        history_surropt                 matlab.ui.control.ToggleButton
        MODEPanel                       matlab.ui.container.Panel
        RunSearchButton                 matlab.ui.control.Button
        manualSearchOp                  matlab.ui.control.StateButton
        solverSearchOp                  matlab.ui.control.StateButton
        randOp                          matlab.ui.control.StateButton
        alignSettingsButton             matlab.ui.control.Button
        VisTab                          matlab.ui.container.Tab
        graphicsSettings                matlab.ui.control.Button
        FixedstructurePanel             matlab.ui.container.Panel
        FixColorButton                  matlab.ui.control.Button
        hideFix                         matlab.ui.control.StateButton
        FixTransparentButton            matlab.ui.control.StateButton
        RotatingstructurePanel          matlab.ui.container.Panel
        RotColorButton                  matlab.ui.control.Button
        hideRot                         matlab.ui.control.StateButton
        RotTransparentButton            matlab.ui.control.StateButton
        Panel_4                         matlab.ui.container.Panel
        jmolAlignment                   matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        JSmolROIswitch                  matlab.ui.control.Switch
        ExportTab                       matlab.ui.container.Tab
        STRUCTUREPanel                  matlab.ui.container.Panel
        StructureDropDown               matlab.ui.control.DropDown
        INCLUDEPanel                    matlab.ui.container.Panel
        HETATOMSCheckBox                matlab.ui.control.CheckBox
        AllrecordsCheckBox              matlab.ui.control.CheckBox
        SavePdbButton                   matlab.ui.control.Button
        SetupTab                        matlab.ui.container.Tab
        ZERNIKEPARAMETERSPanel          matlab.ui.container.Panel
        GridresolutionLabel             matlab.ui.control.Label
        GridresolutionEditField         matlab.ui.control.NumericEditField
        ZCmomentorderDropDownLabel      matlab.ui.control.Label
        ZCmomentorderDropDown           matlab.ui.control.DropDown
        SHAPEREPRESENTATIONPanel        matlab.ui.container.Panel
        molSurfDropDown                 matlab.ui.control.DropDown
        RelativeisovalueEditFieldLabel  matlab.ui.control.Label
        probeRadiusSpinner              matlab.ui.control.Spinner
        SmearfactorEditFieldLabel       matlab.ui.control.Label
        SmearfactorEditField            matlab.ui.control.NumericEditField
        RelativeisovalueEditField       matlab.ui.control.NumericEditField
        ShapeRepDropDown                matlab.ui.control.DropDown
        shellThicknessSpinner           matlab.ui.control.Spinner
        shellThicknessLabel             matlab.ui.control.Label
        ProberadiusSpinnerLabel         matlab.ui.control.Label
        USEREGIONOFINTERESTPanel        matlab.ui.container.Panel
        fixROICheckBox                  matlab.ui.control.CheckBox
        rotROICheckBox                  matlab.ui.control.CheckBox
        RightPanel                      matlab.ui.container.Panel
        GridLayoutRight                 matlab.ui.container.GridLayout
        TabGroup3                       matlab.ui.container.TabGroup
        ViewshapesTab                   matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        UIAxes                          matlab.ui.control.UIAxes
        Label_momCCResults              matlab.ui.control.Label
        ZEALscoreLabel                  matlab.ui.control.Label
        RegionOfInterestTab             matlab.ui.container.Tab
        Panel_3                         matlab.ui.container.Panel
        DefineButton                    matlab.ui.control.Button
        deleteROI                       matlab.ui.control.Button
        ROIselButton                    matlab.ui.container.ButtonGroup
        rotROISelect                    matlab.ui.control.RadioButton
        fixROISelect                    matlab.ui.control.RadioButton
        viewROIdefinedPanel             matlab.ui.container.Panel
        UIAxes3                         matlab.ui.control.UIAxes
        ZEALversionLabel                matlab.ui.control.Label
        GitHubLogo                      matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        %fix % various data and settings for fixed structure
        %rot  % various data and settings for rotating structure
                      
        ZC_settings % settings relating to moment computations
               
        brush % data relating to region of interest definitions and data
        
                
%         function_lib = addpath(genpath('./functions/'))
%         lib = addpath(genpath('./lib/'));
%         dialog_lib = addpath('./dialogs/');
%         samples_lib = addpath('./samples/')
            
    end
    
    properties (Access = public)
                 
        fix % various data and settings for fixed structure
        rot  % various data and settings for rotating structure
        
        alignment % data container for alignment results
        
        plot_settings % settings for the figure in "view structures" tab
        
        shape_settings % settings dealing with shape functions
        
        surropt % settings relevent for surrogate optimization 
        manual % data from manual search
        original % data from original orientation
        
        trackball % data used by the virtual trackball mode
        
        JSmol % settings to JSmol
        
        optimPlot % results and status for handling the plot during surrogate optimization 
        
        paths % store various libary paths here 
        
        dialogs % handles to dialog windows
        
    end
    
    methods (Access = private)
        
        %% ------------- PLOTTING -------------
        function plotAlignment(app)
            
            if app.fix.loaded && app.rot.loaded

                % Set axes limits
                app.UIAxes.XLim = [0 app.ZC_settings.grid_res];
                app.UIAxes.YLim = [0 app.ZC_settings.grid_res];
                app.UIAxes.ZLim = [0 app.ZC_settings.grid_res];
                
                hold(app.UIAxes,'on');
                
                
                plotFix(app);
                plotRot(app);

            else
                app.plot_settings.hasrun = false;
                warndlg('No structures to process. Make sure two pdb files have been loaded.');
            end
            
        end
        
        function  [ph] = plotPatch(~, axhandle, shape_mesh, col, opacity_op)
            
            ph = patch(axhandle, shape_mesh);
            
            %reducepatch(ph,0.1)
            
            ph.FaceColor = col;
            ph.EdgeColor = 'none';
            ph.FaceLighting = 'Gouraud';
            material(ph,'dull');
            
            if opacity_op
                ph.FaceAlpha = 0.3;
            else
                ph.FaceAlpha = 1;
            end
            
        end
        
        function plotFix(app)
            
            if app.plot_settings.updatefix || app.hideFix.Value
                if isfield(app.alignment, 'ph_fix')
                    delete(app.alignment.ph_fix);
                end
            end

            hold(app.UIAxes,'on');

            if app.plot_settings.updatefix && ~app.hideFix.Value
                if app.fixROICheckBox.Value % ROI
                    
                    nonsel_color = app.plot_settings.fixcolor+0.6;
                    nonsel_color(nonsel_color>1) = 1;
                    
                    fix_MSmesh_sel = app.fix.ROI.MSmesh_sel;
                    %fix_MSmesh_notsel = app.fix.ROI.MSmesh_notsel;
                    
                    %[app.alignment.ph_fix(1), app.alignment.ph_fix(2)] = plotROI(app, app.UIAxes, fix_MSmesh_sel, fix_MSmesh_notsel, app.plot_settings.fixcolor, nonsel_color, app.FixTransparentButton.Value);
                    [app.alignment.ph_fix] = plotROI(app, app.UIAxes, fix_MSmesh_sel, app.plot_settings.fixcolor, app.FixTransparentButton.Value);
                                        
                else % default
                    [app.alignment.ph_fix] = plotPatch(app, app.UIAxes, app.fix.MSmesh, app.plot_settings.fixcolor, app.FixTransparentButton.Value);
                end
                
            end
            
            hold(app.UIAxes,'off');
            app.UIAxes.View = [45 45];
            %rotate3d(app.UIAxes,'on');
                
        end
        
        function plotRot(app)
            
            if isfield(app.alignment, 'ph_rot')
                delete(app.alignment.ph_rot);
            end

            hold(app.UIAxes,'on');
            
            if ~app.hideRot.Value
                if app.rotROICheckBox.Value % ROI
                    nonsel_color = app.plot_settings.rotcolor+0.6;
                    nonsel_color(nonsel_color>1) = 1;
                    
                    %[app.alignment.ph_rot(1), app.alignment.ph_rot(2)] = plotROI(app, app.UIAxes, app.rot.ROI.MSmesh_sel, app.rot.ROI.MSmesh_notsel, app.plot_settings.rotcolor, nonsel_color, app.RotTransparentButton.Value);
                    app.alignment.ph_rot = plotROI(app, app.UIAxes, app.rot.ROI.MSmesh_sel, app.plot_settings.rotcolor, app.RotTransparentButton.Value);
                    
                else % default
                    [app.alignment.ph_rot] = plotPatch(app, app.UIAxes, app.rot.MSmesh, app.plot_settings.rotcolor, app.RotTransparentButton.Value);
                end
            end
            
            hold(app.UIAxes,'off');
            app.UIAxes.View = [45 45];
            %rotate3d(app.UIAxes,'on');
            
        end
        
        function [ph] = plotROI(app, axhandle, MSmesh_sel, sel_color, opacity_op)
            
            ph = patch(axhandle,MSmesh_sel);
            ph.FaceColor = sel_color;
            ph.EdgeColor = 'none';
            ph.FaceLighting = 'gouraud';
            material(ph,'dull');
            
            %ph2 = patch(axhandle,MSmesh_notsel);
            %ph2.FaceColor = notsel_color;
            %ph2.EdgeColor = 'none';
            %ph2.FaceLighting = 'gouraud';
            %material(ph2,'dull');
            
            %ph2 = [];
            if opacity_op
                ph.FaceAlpha = 0.3;
             %   ph2.FaceAlpha = 0.3;
            else
                ph.FaceAlpha = 1;
              %  ph2.FaceAlpha = 1;
            end
            
            % Set axes limits
            axhandle.XLim = [0 app.ZC_settings.grid_res];
            axhandle.YLim = [0 app.ZC_settings.grid_res];
            axhandle.ZLim = [0 app.ZC_settings.grid_res];
            
        end
        
        
        %% -------------  SHAPE GENERATION -------------
        function [MS, MSmesh, solid_or_eldens, scaling_factor] = createShapeRep(app, atom_list, varargin)
        
        if nargin>2
            scaling.COM = varargin{1};
            scaling.factor = varargin{2};  
            scaling.voxel_res = varargin{3};
        else
            scaling.COM = [];
            scaling.factor = [];
            scaling.voxel_res = [];
        end
            
            switch app.ShapeRepDropDown.Value
                
                case 1
                    [MS, MSsolid, MSmesh, scaling_factor] = EDTms(atom_list, app.ZC_settings.grid_res, app.shape_settings, app.plot_settings.smoothing, scaling);
                    solid_or_eldens = MSsolid;
                    
                case 2
                    [MS, ~, ~] = create_elecDensity(atom_list, app.ZC_settings.grid_res, app.shape_settings.smear_factor, scaling.COM, scaling.factor, scaling.voxel_res);
                    
                    elDensityVolNorm = MS/ max(max(max(MS)));
                    solid_or_eldens = elDensityVolNorm;
                    
                    MSmesh = create_elDensitySurface(app, elDensityVolNorm, app.shape_settings.rel_iso);
            end
            
        end
        
        function elec_density_mesh = create_elDensitySurface(app, elecDensityVolume, iso_val)
            
            gv = 1:app.ZC_settings.grid_res;
            
            [xdata, ydata, zdata] = meshgrid(gv, gv, gv);
            elec_density_mesh = isosurface(ydata,xdata,zdata, elecDensityVolume,iso_val);
        end
        
        
        function [scaling_factor, voxel_res] = compute_scaling(app, COM, atom_list)
            
            [~, voxel_res, scaling_factor ] = createSASolid(atom_list, app.ZC_settings.grid_res, app.shape_settings.probe_radius, COM, [], []) ;
            
        end

        function updateRotShape(app, Rmat)
                 
            app.rot.atom_list(:,1:3) = app.rot.atom_list(:,1:3) * Rmat';      
            
            [app.rot.MS,  app.rot.MSmesh, ~] = createShapeRep(app, app.rot.atom_list);
               
            app.rot.MSmesh_orig = app.rot.MSmesh;
            
            app.alignment.ph_rot.Vertices = app.rot.MSmesh.vertices;
            app.alignment.ph_rot.Faces = app.rot.MSmesh.faces;
            
            refreshdata(app.alignment.ph_rot);
            
            % reset rot mat since a new surface is generated from
            % rotated atom coordinates
            app.alignment.R = eye(3);
            
        end
        
        function updateFixShape(app)
            
            [app.fix.MS,  app.fix.MSmesh, ~] = createShapeRep(app, app.fix.atom_list);
            
            app.fix.MSmesh_orig = app.fix.MSmesh;
            
            app.alignment.ph_fix.Vertices = app.fix.MSmesh.vertices;
            app.alignment.ph_fix.Faces = app.fix.MSmesh.faces;
            
            refreshdata(app.alignment.ph_fix);
            
        end
        
        function updateRotShape_ROI(app, Rmat)
            
            % ROTATE ALL ATOMS 
            app.rot.atom_list(:,1:3) = app.rot.atom_list(:,1:3) * Rmat';
            
            
            % ROTATE ROI ATOMS                   
            app.rot.ROI.atom_list_sel(:,1:3) = app.rot.ROI.atom_list_sel(:,1:3) * Rmat';
            
            % Test if we have expected Rmat
            % R = findRotation(atom_list_sel_orig, app.rot.ROI.atom_list_sel(:,1:3));
                        
            
            % reset rot mat since a new surface is generated from
            % rotated atom coordinates
            app.alignment.R = eye(3);
            
           
            %selCOM = mean(app.rot.ROI.atom_list_sel(:,1:3));
            
            % non ROI
            %notsel_IDs = app.rot.ROI.atom_list_notsel(:,7);
            %app.rot.ROI.atom_list_notsel(:,1:3) = app.rot.atom_list(notsel_IDs,1:3);                    
            
            % CREATE NEW SHAPES
            %[scaling_factor, voxel_res] = compute_scaling(app, selCOM, app.rot.atom_list);
            
            %[app.rot.ROI.MS_sel, app.rot.ROI.MSmesh_sel, ~] = createShapeRep(app, app.rot.ROI.atom_list_sel,selCOM, scaling_factor, voxel_res);
            %[app.rot.ROI.MS_notsel, app.rot.ROI.MSmesh_notsel, ~] = createShapeRep(app, app.rot.ROI.atom_list_notsel,selCOM, scaling_factor, voxel_res);
            
            [app.rot.ROI.MS_sel, app.rot.ROI.MSmesh_sel, ~] = createShapeRep(app, app.rot.ROI.atom_list_sel);
            [app.rot.ROI.MS_notsel, app.rot.ROI.MSmesh_notsel, ~] = createShapeRep(app, app.rot.ROI.atom_list_notsel);
            
            
            % UPDATE DATA CONTAINERS
            app.rot.ROI.MSmesh_sel_orig = app.rot.ROI.MSmesh_sel;
            app.rot.ROI.MSmesh_notsel_orig = app.rot.ROI.MSmesh_notsel;
            
            
            % UPDATE FIGURE
            % ROI            
            %app.alignment.ph_rot(1).Vertices = app.rot.ROI.MSmesh_sel.vertices;
            %app.alignment.ph_rot(1).Faces = app.rot.ROI.MSmesh_sel.faces;
            
            app.alignment.ph_rot.Vertices = app.rot.ROI.MSmesh_sel.vertices;
            app.alignment.ph_rot.Faces = app.rot.ROI.MSmesh_sel.faces;
            
            % NON ROI
            %app.alignment.ph_rot(2).Vertices = app.rot.ROI.MSmesh_notsel.vertices;
            %app.alignment.ph_rot(2).Faces = app.rot.ROI.MSmesh_notsel.faces;
            
            refreshdata(app.alignment.ph_rot);
            
        end
        
        function updateFixShape_ROI(app)
            
            % ROI
            %sel_IDs = app.fix.ROI.atom_list_sel(:,7);
            %app.fix.ROI.atom_list_sel(:,1:3) = app.fix.atom_list(sel_IDs,1:3);
            selCOM = mean(app.fix.ROI.atom_list_sel(:,1:3));
            
            % non ROI
            %notsel_IDs = app.fix.ROI.atom_list_notsel(:,7);
            %app.fix.ROI.atom_list_notsel(:,1:3) = app.fix.atom_list(notsel_IDs,1:3);
            
            
            % CREATE NEW SHAPES
            %[scaling_factor, voxel_res] = compute_scaling(app, selCOM, app.rot.atom_list);
            
            %[app.fix.ROI.MS_sel, app.fix.ROI.MSmesh_sel, ~] = createShapeRep(app, app.fix.ROI.atom_list_sel,selCOM, scaling_factor, voxel_res);
            %[app.fix.ROI.MS_notsel, app.fix.ROI.MSmesh_notsel, ~] = createShapeRep(app, app.fix.ROI.atom_list_notsel,selCOM, scaling_factor, voxel_res);
            
            [app.fix.ROI.MS_sel, app.fix.ROI.MSmesh_sel, ~] = createShapeRep(app, app.fix.ROI.atom_list_sel);
            [app.fix.ROI.MS_notsel, app.fix.ROI.MSmesh_notsel, ~] = createShapeRep(app, app.fix.ROI.atom_list_notsel);
            
            
            % UPDATE DATA CONTAINERS
            app.fix.ROI.MSmesh_sel_orig = app.fix.ROI.MSmesh_sel;
            app.fix.ROI.MSmesh_notsel_orig = app.fix.ROI.MSmesh_notsel;


            % UPDATE FIGURE
            % ROI
            %app.alignment.ph_fix(1).Vertices = app.fix.ROI.MSmesh_sel.vertices;
            %app.alignment.ph_fix(1).Faces = app.fix.ROI.MSmesh_sel.faces;
            
            app.alignment.ph_fix.Vertices = app.fix.ROI.MSmesh_sel.vertices;
            app.alignment.ph_fix.Faces = app.fix.ROI.MSmesh_sel.faces;
            
            % NON ROI
            %app.alignment.ph_fix(2).Vertices = app.fix.ROI.MSmesh_notsel.vertices;
            %app.alignment.ph_fix(2).Faces = app.fix.ROI.MSmesh_notsel.faces;
            
            refreshdata(app.alignment.ph_fix);
            
        end
        
        function rotateAndUpdateRotMesh(app, Rmat)
            
            COM = mean(app.rot.MSmesh_orig.vertices);
            app.rot.MSmesh = app.rot.MSmesh_orig;
            
            app.rot.MSmesh.vertices = (((app.rot.MSmesh.vertices-COM) * Rmat')+COM);
            
            app.alignment.ph_rot.Vertices = app.rot.MSmesh.vertices;
            refreshdata(app.alignment.ph_rot);
            
        end
        
        function rotateAndUpdateRotMesh_ROI(app, Rmat)
            
            % ROI
            COM_sel = mean(app.rot.ROI.MSmesh_sel_orig.vertices);
            app.rot.ROI.MSmesh_sel = app.rot.ROI.MSmesh_sel_orig;
            
            app.rot.ROI.MSmesh_sel.vertices = (((app.rot.ROI.MSmesh_sel.vertices-COM_sel) * Rmat')+COM_sel);
            
            app.alignment.ph_rot(1).Vertices = app.rot.ROI.MSmesh_sel.vertices;
            refreshdata(app.alignment.ph_rot(1));
            
            % NON ROI
            %COM = mean(app.brush.MSmesh_notsel_orig.vertices);
            %app.rot.ROI.MSmesh_notsel = app.rot.ROI.MSmesh_notsel_orig;
            
            %app.rot.ROI.MSmesh_notsel.vertices = (((app.rot.ROI.MSmesh_notsel.vertices-COM_sel) * Rmat')+COM_sel);
            
            %app.alignment.ph_rot(2).Vertices = app.rot.ROI.MSmesh_notsel.vertices;
            %refreshdata(app.alignment.ph_rot(2));
            
        end
        
        %% ------------- GUI -------------
      
        function enableSearch(app)
            
            enableSetupTab(app);
                   
            % viewer tab
            app.jmolAlignment.Enable = true;
            app.graphicsSettings.Enable = true;
            
            app.FixColorButton.Enable = true;
            app.RotColorButton.Enable = true;
            
            app.FixTransparentButton.Enable = true;
            app.RotTransparentButton.Enable = true;
            
            app.hideFix.Enable = true;
            app.hideRot.Enable = true;
            
            % results area
            app.ZEALscoreLabel.Visible = true;
            app.Label_momCCResults.Visible = true;
            
            % alignment            
            app.solverSearchOp.Enable = true;
            app.manualSearchOp.Enable = true;
            app.RunSearchButton.Enable = true;
            app.randOp.Enable = true;
            
            app.alignSettingsButton.Enable = true;
            
            % ROI  
            app.DefineButton.Enable = true;
            
            app.TabGroup.SelectedTab = app.AlignTab;
            
            app.SavePdbButton.Enable = true;
            
        end
        
        function disableSearch(app)
            
            disableSetupTab(app);
                   
            % viewer tab
            app.jmolAlignment.Enable = false;
            app.graphicsSettings.Enable = false;
            
            app.FixColorButton.Enable = false;
            app.RotColorButton.Enable = false;
                        
            app.FixTransparentButton.Enable = false;
            app.RotTransparentButton.Enable = false;
            
            app.hideFix.Enable = false;
            app.hideRot.Enable = false;
                        
            % results area
            app.ZEALscoreLabel.Visible = false;
            app.Label_momCCResults.Visible = false;
            
            % alignment            
            app.solverSearchOp.Enable = false;
            app.manualSearchOp.Enable = false;
            app.RunSearchButton.Enable = false;
            app.randOp.Enable = false;
            
            app.alignSettingsButton.Enable = false;
            
            % JSmol
            app.JSmolROIswitch.Enable = false;
            app.JSmolROIswitch.Value = 'ALL';
            
            
            % ROI      
            
            app.deleteROI.Enable = false;
            
            app.SavePdbButton.Enable = false;
            
            nROIgraphicsObj = length(app.UIAxes3.Children);
            
            if nROIgraphicsObj>1
                delete(app.brush.ph);
                %delete(app.brush.ph2);
            end
                        
            % history
            app.history_original.Value = true;
            app.history_original.Enable = false;
            app.history_manual.Enable = false;
            app.history_surropt.Enable = false;
            
        end
        
        %% ------------- ZERNIKE RELATED -------------
        
        function [chi_coeff_cell, chi_nlm_rst_cell] = loadChiCoeffs(app)
            
            chi_matfile_name = sprintf('chiCoeffs_order_%d.mat', app.ZC_settings.order);
                       
            
            %chi_mat_path = fullfile(chi_folder_in_archive, chi_matfile_name);
            
            chi_mat_path=which(fullfile(chi_matfile_name));
            
            if ~exist(chi_mat_path, 'file')
                [~, chi_coeff_cell, chi_nlm_rst_cell] = computeChiCoeffs(app.ZC_settings.order, app.ZEALUIFigure);
                fprintf('\n\t Saving chi coefficients\n');
                save(chi_mat_path, 'chi_coeff_cell', 'chi_nlm_rst_cell');
            else
                fprintf('\n\t Loading chi coefficients for order %d', app.ZC_settings.order);
                fprintf(' ... ');
                load(chi_mat_path,'chi_coeff_cell','chi_nlm_rst_cell');
                fprintf('done.\n');
            end
        end
        
        function [zcCC] = computeCC(app)
            %zcCC = real(corr(app.fix.ZC.mom_list, app.rot.ZC.mom_list));
            zcCC = real(dot(app.fix.ZC.mom_list, app.rot.ZC.mom_list)) / (norm(app.fix.ZC.mom_list)*norm(app.rot.ZC.mom_list));
        end


        %% ------------- REGION OF INTEREST -------------
        function expandSelection(app)
            sel_dists = pdist2(app.brush.xyz_sel_origin, app.brush.xyz_voxs,'euclidean');
            
            within_radius_tf = sel_dists <= app.ROIradiusSpinner.Value;
            outside_radius_tf = within_radius_tf == 0;
            
            vert_inds = 1:size(app.brush.xyz_voxs,1);
            
            app.brush.sel_ind = vert_inds(within_radius_tf);
            app.brush.notsel_ind = vert_inds(outside_radius_tf);
            
            if app.inverseButton.Value
                sel_ind_tmp =  app.brush.sel_ind;
                app.brush.sel_ind = app.brush.notsel_ind;
                app.brush.notsel_ind = sel_ind_tmp;
            end
            
            app.brush.xyz_sel = app.brush.xyz_voxs(app.brush.sel_ind,:);
            app.brush.xyz_notsel = app.brush.xyz_voxs(app.brush.notsel_ind,:);
            
            xyz_sel_ind = app.brush.xyz_sel+0.5;
            
            gs = app.ZC_settings.grid_res;
            
            app.brush.MS_sel = zeros(gs, gs, gs);
            for i = 1:length(xyz_sel_ind)
                app.brush.MS_sel(xyz_sel_ind(i,1), xyz_sel_ind(i,2), xyz_sel_ind(i,3)) = app.ROIweightEditField.Value;
            end
            
            xyz_notsel_ind = app.brush.xyz_notsel+0.5;
            app.brush.MS_notsel = zeros(gs, gs, gs);
            for i = 1:length(xyz_notsel_ind)
                app.brush.MS_notsel(xyz_notsel_ind(i,1), xyz_notsel_ind(i,2), xyz_notsel_ind(i,3)) = app.nonROIweightEditField.Value;
            end
            
            if app.fixedROIButton.Value
                
                app.fix.ROI.MS_sel = app.brush.MS_sel;
                app.fix.ROI.MS_notsel = app.brush.MS_notsel;
                app.fix.ROI.xyz_sel_origin = app.brush.xyz_sel_origin;
                app.fix.ROI.xyz_vox = app.brush.xyz_voxs ;
                app.fix.ROI.sel_radius = app.ROIradiusSpinner.Value;
                app.fix.ROI.xyz_sel = app.brush.xyz_sel;
                app.fix.ROI.xyz_notsel = app.brush.xyz_notsel;
                
            elseif app.rotatingROIButton.Value
                
                app.rot.ROI.MS_sel = app.brush.MS_sel;
                app.rot.ROI.MS_notsel = app.brush.MS_notsel;
                app.rot.ROI.xyz_sel_origin = app.brush.xyz_sel_origin;
                app.rot.ROI.xyz_vox = app.brush.xyz_voxs ;
                app.rot.ROI.sel_radius = app.ROIradiusSpinner.Value;
                app.rot.ROI.xyz_sel = app.brush.xyz_sel;
                app.rot.ROI.xyz_notsel = app.brush.xyz_notsel;
                
            end
            
        end
        
        
        %% ------------- ALIGNMENT SEARCH -------------
        
        function globalSearch(app)

            % ### SETTINGS ###
            if app.rotROICheckBox.Value
                rot_atom_list = app.rot.ROI.atom_list_sel;               
            else       
                rot_atom_list = app.rot.atom_list;
            end
            
            %COM = mean(rot_atom_list(:,1:3));
            %rot_atom_list(:,1:3) = rot_atom_list(:,1:3) - COM;
            
            % Parameters for shape generation
            settings.shape_op = app.ShapeRepDropDown.Value;
            settings.grid_res = app.ZC_settings.grid_res;
            settings.shape = app.shape_settings;
                                   
            % Parameters and data for ZC-moment computation
            ZCref = app.fix.ZC;
            ZCref.order = app.ZC_settings.order;
            ZCref.grid_res = app.ZC_settings.grid_res;
            ZCref.scale_option = app.ZC_settings.scale_option;
            ZCref.chi_coeff_cell = app.alignment.chi_coeff_cell;
            ZCref.chi_nlm_rst_cell = app.alignment.chi_nlm_rst_cell;
            
            
            % display progress window, otherwise we update the alignment
            % during optimization
            if ~app.surropt.viewprogress
                d = uiprogressdlg(app.ZEALUIFigure,'Title','Global search', 'Message', 'Searching for shape optimal alignment','Indeterminate','on');
            end
            
            % ### PERFORM SURROGATE OPTIMIZATION ###            
            
            [x,~,~,~,~] = alignmentOptimization(ZCref, settings, rot_atom_list, app.surropt.options, app.surropt.lb, app.surropt.ub);
            %[x,fval,exitflag,output,trials]
            
            % ### HANDLE OUTPUT AFTER OPTIMIZATION ###            
            % Update rotation matrix
            app.alignment.R = euler2rotationMatrix(x(1), x(2), x(3), 'zyz');
            
            updateAfterSearch(app,'surropt');
           
            if ~app.surropt.viewprogress
                close(d);
            end
        
            app.SavePdbButton.Enable = true;
            
            if ~app.surropt.viewprogress
                close(d);
                rotateAndUpdateRotMesh(app, real(app.alignment.R));               
            end
        end
        
        function  stop = surrOptiOutputFcn(app,x,optimvalues,state)

            if strcmp(state,'init')
                fprintf('\n\n -------------------------------------\n');
                fprintf('\n\tPerforming shape alignment search.\n');
               
            end
            
            stop = app.optimPlot.stop;
            
            if optimvalues.iteration == 0
                app.surropt.prev_val = 0;
                app.surropt.startTime = tic;                
            end
           
            if ~isempty(optimvalues.fval)
                
                app.optimPlot.iteration = [app.optimPlot.iteration optimvalues.iteration] ;
                app.optimPlot.fval = [app.optimPlot.fval optimvalues.fval];
                
                app.dialogs.surrOptimPlot.ph.XData = app.optimPlot.iteration;
                app.dialogs.surrOptimPlot.ph.YData = -1*app.optimPlot.fval;
                refreshdata(app.dialogs.surrOptimPlot.ph);
              
                searchTime = toc(app.surropt.startTime);
                
                app.dialogs.surrOptimPlot.UIAxes.Title.String = sprintf('Best score: %3.3f \t\t\t Elapsed time: %3.0f s', app.alignment.zcCC, searchTime);
                
                if optimvalues.fval < (app.surropt.prev_val*1.01)
                    
                    app.surropt.prev_val = optimvalues.fval;
                    
                    app.alignment.zcCC = -1*optimvalues.fval;
                    app.alignment.searchTime = searchTime;
                    
                    updatePlot = true;
                    
                else
                    updatePlot = false;
                end
                
                if updatePlot || (strcmp(state,'done'))                    
                                        
                    if app.surropt.viewprogress
                        
                        updateResultLabel(app);
                        
                        Rmat = euler2rotationMatrix(x(1), x(2), x(3),'zyz');
                        
                        if app.rotROICheckBox.Value % ROI
                            
                            rotateAndUpdateRotMesh_ROI(app, Rmat);
                            
                        else
                            
                            rotateAndUpdateRotMesh(app, Rmat)
                            
                        end
                    end
                end
                
                if mod(optimvalues.iteration,app.plot_settings.drawnowFreq)==1
                    drawnow limitrate;
                end
                
            end
            
            if strcmp(state,'done')
                fprintf('\n\tSearch completed after %3.1f s.\n\tBest correlation found was %2.2f after %3.1f s.\n', searchTime, app.alignment.zcCC, app.alignment.searchTime);
                fprintf('\n -------------------------------------\n\n');
                delete(app.dialogs.surrOptimPlot);
                
            end
            
        end
        
        %% ------------- MANUAL ALIGNMENT/VIRTUAL TRACKBALL -------------
        
        function xyz_sphere = getPointOnTrackball(~, x,y,r)
            % Returns xyz coordinates on a virtual sphere with radius r
            
            % normalize viewport to fit inside unit circle
            x = x/r;
            y = y/r;
            
            if x^2 + y^2 < 1
                z = sqrt( 1 - (x^2 + y^2) );
                xyz_sphere = [x y z];
                
            else % point outside the circle
                
                % project point to the nearest point on the circle,
                % set z to 0 and renormalize
                xyz_sphere = normr([x y 0]);
            end
        end
        
        function R = getTrackballRotmat(app, xyz)
            
            % Returns the rotation matrix from the new and old position
            % on the virtual trackball
            
            % Rotation axis in eye coordinates
            v = cross(xyz, app.trackball.xyz0);
            v = normc(v(:));
            
            % Transform rotation axis to world coordinates
            v = app.trackball.R0 * v;
            
            % Rotation angle
            a = acos(app.trackball.xyz0 * xyz');
            
            % get rotation matrix
            R = makehgtform('axisrotate', v, a);
            
        end
        
        
        function initializeSession(app)
        
            % Compute ZC moments
            [app.fix.ZC.mom_nlm_list ,app.fix.ZC.mom_list, app.fix.ZC.mom] = getZCmomFromShape(app.fix.MS, app.ZC_settings.order, app.ZC_settings.grid_res, app.ZC_settings.scale_option, app.alignment.chi_coeff_cell, app.alignment.chi_nlm_rst_cell);
            [app.rot.ZC.mom_nlm_list ,app.rot.ZC.mom_list, app.rot.ZC.mom] = getZCmomFromShape(app.rot.MS, app.ZC_settings.order, app.ZC_settings.grid_res, app.ZC_settings.scale_option, app.alignment.chi_coeff_cell, app.alignment.chi_nlm_rst_cell);
            
            app.alignment.zcCC = computeCC(app);
           
            app.alignment.updaterot = true;
            app.alignment.updatefix = true;
            
            app.RotatingstructurePanel.ForegroundColor = app.plot_settings.rotcolor;
            app.FixedstructurePanel.ForegroundColor = app.plot_settings.fixcolor;
                         
            updateResultLabel(app);

        end
        
        function pdbData = reIndexAtomNum(~,pdbData)            
            
            nAtoms = size(pdbData.atomNum,2);
            pdbData.atomNum = 1:nAtoms;

        end        
        
        
        function resetFix(app)
            
            disableSetupTab(app);
            disableSearch(app);
                        
            app.fix.loaded = false;
            app.JSmol.fixSaved = false;
            app.fix_pdbfile.Enable = false;
            
            if isfield(app.alignment, 'ph_fix')
                delete(app.alignment.ph_fix);
            end
            
            app.fix.pdb_data = [];
            app.fix.atom_list = [];
            app.fix.MS = [];
            app.fix.MSmesh = [];
            app.fix.ZC.mom_nlm_list = [];
            app.fix.ZC.mom_list = [];
            app.fix.ZC.mom = [];
            
            app.alignment.zcCC = [];
            app.plot_settings.updatefix = true;
            
            updateResultLabel(app);
            
            loadMode = app.fixSwitch.Value;
            
            if app.fix.ROI.available
                app.fix.ROI.MS_sel = [];
                app.fix.ROI.MS_notsel = [];
                app.fix.ROI.MSmesh_sel = [];
                app.fix.ROI.MSmesh_notsel = [];
                
                app.fix.ROI.available = false;
                app.fixROICheckBox.Value = false;
                app.deleteROI.Enable = false;
            end
            
            if isdeployed
                app.importFix.Icon = 'import2_100px.png';
            else
                app.importFix.Icon = fullfile(app.paths.icons, 'import2_100px.png');
            end
            
            app.importFix.Text = 'Import';
            
            bgcol = [0.87 0.93 0.96];
            app.importFix.BackgroundColor = bgcol;
            
            app.fixCHAINIDEditField.BackgroundColor = bgcol;
            app.fixCHAINIDEditField.FontColor = [0 0 0];
            app.fixCHAINIDEditField.FontWeight = 'normal';
            
            if strcmp(loadMode, 'LOAD')
                app.fix_pdbfile.BackgroundColor = bgcol;
                app.fix_pdbfile.FontColor = [0 0 0];
                app.fix_pdbfile.FontWeight = 'normal';
                app.fix_pdbfile.FontSize = 10;
                app.fix_pdbfile.Value = 'no structure loaded';
            else
                app.fixPDBIDEditField.BackgroundColor = bgcol;
                app.fixPDBIDEditField.FontColor = [0 0 0];
                app.fixPDBIDEditField.FontWeight = 'normal';
                app.fixPDBIDEditField.Value = '';
                
            end
            
            app.fixCHAINIDEditField.Value = '';
            app.FixedstructurePanel.ForegroundColor = [0.5 0.5 0.5];
            
            % update export to pdb drop down menu
            items = app.StructureDropDown.Items;
            
            itemFun=@(x) (strcmp(x,'ROI Fixed'));
            
            itemDeleteTF = cellfun(itemFun,items,'UniformOutput',false);
            
            items(cell2mat(itemDeleteTF)) = [];
            
            app.StructureDropDown.Items = items;
            
        end
        
        function resetRot(app)
            
            disableSetupTab(app);
            disableSearch(app);
            
            app.rot.loaded = false;
            app.JSmol.rotSaved = false;
            app.rot_pdbfile.Enable = false;
            
            if isfield(app.alignment, 'ph_rot')
                delete(app.alignment.ph_rot);
            end
            
            app.rot.pdb_data = [];
            app.rot.atom_list = [];
            app.rot.MS = [];
            app.rot.MSmesh = [];
            app.rot.ZC.mom_nlm_list = [];
            app.rot.ZC.mom_list = [];
            app.rot.ZC.mom = [];
            
            app.alignment.zcCC = [];
            
            if app.rot.ROI.available
                app.rot.ROI.MS_sel = [];
                app.rot.ROI.MS_notsel = [];
                app.rot.ROI.MSmesh_sel = [];
                app.rot.ROI.MSmesh_notsel = [];
                
                app.rot.ROI.available = false;
                app.rotROICheckBox.Value = false;
                app.deleteROI.Enable = false;
            end
            
            updateResultLabel(app);
            if isdeployed
                app.importRot.Icon = 'import2_100px.png';
            else
                app.importRot.Icon = fullfile(app.paths.icons, 'import2_100px.png');
            end
            
            app.importRot.Text = 'Import';
            
            bgcol = [0.87 0.93 0.96];
            app.importRot.BackgroundColor = bgcol;
            
            app.rotCHAINIDEditField.BackgroundColor = bgcol;
            app.rotCHAINIDEditField.FontColor = [0 0 0];
            app.rotCHAINIDEditField.FontWeight = 'normal';
            
            loadMode = app.rotSwitch.Value;
            
            if strcmp(loadMode, 'LOAD')
                app.rot_pdbfile.BackgroundColor = bgcol;
                app.rot_pdbfile.FontColor = [0 0 0];
                app.rot_pdbfile.FontWeight = 'normal';
                app.rot_pdbfile.FontSize = 10;
                app.rot_pdbfile.Value = 'no structure loaded';
            else
                app.rotPDBIDEditField.BackgroundColor = bgcol;
                app.rotPDBIDEditField.FontColor = [0 0 0];
                app.rotPDBIDEditField.FontWeight = 'normal';
                app.rotPDBIDEditField.Value = '';
                
            end
            
            app.rotCHAINIDEditField.Value = '';
            
            app.RotatingstructurePanel.ForegroundColor = [0.5 0.5 0.5];
            
            % update export to pdb drop down menu
            items = app.StructureDropDown.Items;
            
            itemFun=@(x) (strcmp(x,'ROI Rotating'));
            
            itemDeleteTF = cellfun(itemFun,items,'UniformOutput',false);
            
            items(cell2mat(itemDeleteTF)) = [];
            
            app.StructureDropDown.Items = items;
            
            
        end
        
        
        function [outfilePath] = structure2JSmol(app, structureOption)
            
            if strcmp(structureOption, 'fixed')    
                
                model = app.fix.pdb_data;                
                atomList = app.fix.atom_list;
                
            elseif strcmp(structureOption,'rotating')
                
                model = app.rot.pdb_data;
                atomList = app.rot.atom_list;
                
            else
                error('Argument can only be be ''fixed'' or ''rotating'' ' );
                
            end
            
            for i = 1:size(atomList,1)
                model.X(i) = atomList(i,1);
                model.Y(i) = atomList(i,2);
                model.Z(i) = atomList(i,3);
            end
                   
            fileName = [structureOption '.xml'];
            outfilePath = fullfile(app.paths.JSmoltmp,fileName);
          
            model.outfile = outfilePath;
            mat2pdb(model);
            
        end


        function [pdbData] = updatePDBdata(app, structureOption)
            
            if strcmp(structureOption, 'fixed')    
                
                pdbData = app.fix.pdb_data;                
                atomList = app.fix.atom_list;
                
            elseif strcmp(structureOption,'rotating')
                
                pdbData = app.rot.pdb_data;
                atomList = app.rot.atom_list;
            else
                
                error('Argument can only be be ''fixed'' or ''rotating'' ' );
                
            end
                       
            for i = 1:size(atomList,1)
                pdbData.X(i) = atomList(i,1);
                pdbData.Y(i) = atomList(i,2);
                pdbData.Z(i) = atomList(i,3);
            end
            
        end
        
        function [pdbData] = updatePDBdata_ROI(app, structureOption)
           
            if strcmp(structureOption, 'fixed')    
                
                pdbData = app.fix.pdb_data; 
                                
                atomSelIDs = app.fix.ROI.selectedIDs;
                atomNonSelIDs = app.fix.ROI.notSelectedIDs;
                 
                atomListSel = app.fix.atom_list(atomSelIDs,:);
                atomList = app.fix.atom_list;
                
            elseif strcmp(structureOption,'rotating')
                
                pdbData = app.rot.pdb_data;
                
                atomSelIDs = app.rot.ROI.selectedIDs;
                atomNonSelIDs = app.rot.ROI.notSelectedIDs;
                
                atomListSel = app.rot.atom_list(atomSelIDs,:);
                atomList = app.rot.atom_list;            
                
            else
                
                error('Argument can only be be ''fixed'' or ''rotating'' ' );
                
            end
            
            % Assert that atom indices go from 1:#atoms
            pdbData = reIndexAtomNum(app,pdbData);
            
            COM = mean(atomListSel(:,1:3));
            
            if strcmp(app.JSmolROIswitch.Value, 'ROI')
                
                fn = fieldnames(pdbData);
                for k=1:numel(fn)
                    pdbData.(fn{k})(atomNonSelIDs) = [];
                end
                
                atomListSel(:,1:3) = atomListSel(:,1:3) - COM;
                
                for i = 1:size(atomListSel,1)
                    pdbData.X(i) = atomListSel(i,1);
                    pdbData.Y(i) = atomListSel(i,2);
                    pdbData.Z(i) = atomListSel(i,3);
                end
                
            else
                
                atomList(:,1:3) = atomList(:,1:3) - COM;
                
                for i = 1:size(atomList,1)
                    pdbData.X(i) = atomList(i,1);
                    pdbData.Y(i) = atomList(i,2);
                    pdbData.Z(i) = atomList(i,3);
                end
                
            end
                        
        end
        
        function [R] = computeRotationMatrix(app, structureOption)
            
            if strcmp(structureOption, 'fixed')    
                              
                atomList = app.fix.atom_list;
                atomList_orig = app.original.fix_atomlist;
                
            elseif strcmp(structureOption,'rotating')                
               
                atomList = app.rot.atom_list;
                atomList_orig = app.original.rot_atomlist;
                
            elseif strcmp(structureOption,'ROIrotating')                
               
                atomList = app.rot.ROI.atom_list_sel;
                atomList_orig = app.rot.ROI.atom_list_sel_original;
                
            elseif strcmp(structureOption,'ROIfixed')                
               
                atomList = app.fix.ROI.atom_list_sel;
                atomList_orig = app.fix.ROI.atom_list_sel_original;
                
            else
                
                error('Argument can only be be ''fixed'' or ''rotating'' ' );
                
            end
            
            %      
            c2 = atomList_orig(:,1:3);
            c1 = atomList(:,1:3);
                        
            % Find rotation matrix from old to new orientation using Horn's method
            rotMat=(findRotation(c2',c1'))';
                      
             R = [rotMat(1,:) 0
                  rotMat(2,:) 0
                  rotMat(3,:) 0
                  0 0 0 0];
                    
        end
        
        function [T] = computeTranslationMatrix(app, structureOption)
            
            if strcmp(structureOption, 'fixed')

                pdbData = app.fix.pdb_data;
                
            elseif strcmp(structureOption,'rotating')

               pdbData = app.rot.pdb_data;
               
            elseif strcmp(structureOption,'ROIrotating')

               pdbData = app.rot.ROI.pdb_data_sel;
               
            elseif strcmp(structureOption,'ROIfixed')

               pdbData = app.fix.ROI.pdb_data_sel;
               
            else
                
                error('Argument can only be be ''fixed'' or ''rotating'' ' );
                
            end
            
            xyz = [pdbData.X' pdbData.Y' pdbData.Z'];
            COM = mean(xyz);
            
            T = [1 0 0 0
                 0 1 0 0
                 0 0 1 0
                 -COM 1];
            
        end
        
        
        function ZEALsettings = getZEALsettings(app)
            fixName = split(app.fix.name,'.');
            fixName = fixName{1};
            rotName = split(app.rot.name,'.');
            rotName = rotName{1};
            
            ZEALsettings.fix.name = fixName;
            ZEALsettings.fix.chain = app.fixCHAINIDEditField.Value;
            
            if isempty(ZEALsettings.fix.chain)
                ZEALsettings.fix.chain = 'all';
            end
            
            ZEALsettings.rot.name = rotName;
            ZEALsettings.rot.chain = app.rotCHAINIDEditField.Value;
            
            if isempty(ZEALsettings.rot.chain)
                ZEALsettings.rot.chain = 'all';
            end
            
            ZEALsettings.order = app.ZC_settings.order;
            ZEALsettings.gridRes = app.ZC_settings.grid_res;            
            ZEALsettings.probeRadius = app.shape_settings.probe_radius;
            ZEALsettings.shellThickness = app.shape_settings.shell_thickness;
            ZEALsettings.smearFactor = app.shape_settings.smear_factor;     
            ZEALsettings.globalSearchOp = app.history_surropt.Value;
            ZEALsettings.zcCC = app.alignment.zcCC;
            
            switch app.shape_settings.shape_rep_type                
                case 1 % MS or SA
                    if strcmp(app.shape_settings.surf_type, 'MS')
                        ZEALsettings.shapeFcn = 'Molecular surface';
                    else
                        ZEALsettings.shapeFcn = 'Solvent-accessible surface';
                    end
                    
                case 2 % density
                    ZEALsettings.shapeFcn = 'Density';                    
            end
        end
                
        
        function pdb_data_sel = parseJSsel(~, pdb_data, selAtomIndex)
            
            pdb_data_sel = struct();
            selSize = size(selAtomIndex);
            
            try
                pdb_data_sel.recordName = pdb_data.recordName(selAtomIndex);
                pdb_data_sel.atomNum = pdb_data.atomNum(selAtomIndex);
                pdb_data_sel.atomName = pdb_data.atomName(selAtomIndex);
                
                pdb_data_sel.altLoc = pdb_data.altLoc(selAtomIndex);
                pdb_data_sel.resName = pdb_data.resName(selAtomIndex);
                pdb_data_sel.chainID = pdb_data.chainID(selAtomIndex);
                pdb_data_sel.resNum = pdb_data.resNum(selAtomIndex);
                
                pdb_data_sel.X = pdb_data.X(selAtomIndex);
                pdb_data_sel.Y = pdb_data.Y(selAtomIndex);
                pdb_data_sel.Z = pdb_data.Z(selAtomIndex);
                
                pdb_data_sel.occupancy = pdb_data.occupancy(selAtomIndex);
                pdb_data_sel.betaFactor = pdb_data.betaFactor(selAtomIndex);
                pdb_data_sel.element = pdb_data.element(selAtomIndex);
                pdb_data_sel.charge = pdb_data.charge(selAtomIndex);
                
            catch
                error('\nCould not parse JSmol selection in ZEAL.\n');
            end
            
        end
        
    end
    
    methods (Access = public)
        
        function updateAllShapesAndZCcc(app)
                        
            if ~app.rotROICheckBox.Value
                
                updateRotShape(app,eye(3));
                [app.rot.ZC.mom_nlm_list ,app.rot.ZC.mom_list, app.rot.ZC.mom] = getZCmomFromShape(app.rot.MS, app.ZC_settings.order, app.ZC_settings.grid_res, app.ZC_settings.scale_option, app.alignment.chi_coeff_cell, app.alignment.chi_nlm_rst_cell);
                
            else
                updateRotShape_ROI(app,eye(3));
                [app.rot.ZC.mom_nlm_list ,app.rot.ZC.mom_list, app.rot.ZC.mom] = getZCmomFromShape(app.rot.ROI.MS_sel, app.ZC_settings.order, app.ZC_settings.grid_res, app.ZC_settings.scale_option, app.alignment.chi_coeff_cell, app.alignment.chi_nlm_rst_cell);
                
            end
            
            if ~app.fixROICheckBox.Value
                
                updateFixShape(app);
                [app.fix.ZC.mom_nlm_list ,app.fix.ZC.mom_list, app.fix.ZC.mom] = getZCmomFromShape(app.fix.MS, app.ZC_settings.order, app.ZC_settings.grid_res, app.ZC_settings.scale_option, app.alignment.chi_coeff_cell, app.alignment.chi_nlm_rst_cell);
                
            else
                updateFixShape_ROI(app);
                [app.fix.ZC.mom_nlm_list ,app.fix.ZC.mom_list, app.fix.ZC.mom] = getZCmomFromShape(app.fix.ROI.MS_sel, app.ZC_settings.order, app.ZC_settings.grid_res, app.ZC_settings.scale_option, app.alignment.chi_coeff_cell, app.alignment.chi_nlm_rst_cell);
                
            end
            
            app.alignment.zcCC = computeCC(app);
            updateResultLabel(app);
            
        end
        
        function updateResultLabel(app)
            
            zcCC_str = sprintf('%8.6f',  app.alignment.zcCC);
            
            app.Label_momCCResults.Text = zcCC_str;
                        
        end

        
        function rotateScene(app, op)
                        
            if op                
                app.trackball.scene = true;                
            else               
                app.trackball.scene = false;
            end
        end
        
        function plotLocalAx(app, op)
            
            if op
                hold(app.UIAxes, 'on');
                app.plot_settings.phx = plot3(app.UIAxes, app.plot_settings.xaxis(1,:), app.plot_settings.xaxis(2,:), app.plot_settings.xaxis(3,:));
                app.plot_settings.phx.Color = [11 148 68]/255;
                app.plot_settings.phx.LineWidth = 3;
                
                app.plot_settings.phy = plot3(app.UIAxes, app.plot_settings.yaxis(1,:), app.plot_settings.yaxis(2,:), app.plot_settings.yaxis(3,:));
                app.plot_settings.phy.Color = [191 30 45]/255;
                app.plot_settings.phy.LineWidth = 3;
                
                app.plot_settings.phz = plot3(app.UIAxes, app.plot_settings.zaxis(1,:), app.plot_settings.zaxis(2,:), app.plot_settings.zaxis(3,:));
                app.plot_settings.phz.Color = [15 117 188]/255;
                app.plot_settings.phz.LineWidth = 3;
                
                hold(app.UIAxes, 'off');
            else
                delete(app.plot_settings.phx);
                delete(app.plot_settings.phy);
                delete(app.plot_settings.phz);
            end
        end
        
        function updateAfterSearch(app, mode)
            
             Rmat = real(app.alignment.R);
              
              
            if ~app.rotROICheckBox.Value 
                updateRotShape(app, Rmat);
                [app.rot.ZC.mom_nlm_list ,app.rot.ZC.mom_list, app.rot.ZC.mom] = getZCmomFromShape(app.rot.MS, app.ZC_settings.order, app.ZC_settings.grid_res, app.ZC_settings.scale_option, app.alignment.chi_coeff_cell, app.alignment.chi_nlm_rst_cell);

            else
                updateRotShape_ROI(app, Rmat);
                
                % updated shape from above is scaled to also fit non-selected
                % region on grid. But the ZC moments should be computed
                % from selected region scaled to fit fully on the grid -
                % this is what the the surrogate model is based on.
                
                % create shape from selected region and scale accordingly -
                % ignore the corresponding mesh
                [app.rot.ROI.MS_sel, ~, ~] = createShapeRep(app, app.rot.ROI.atom_list_sel);

                [app.rot.ZC.mom_nlm_list ,app.rot.ZC.mom_list, app.rot.ZC.mom] = getZCmomFromShape(app.rot.ROI.MS_sel, app.ZC_settings.order, app.ZC_settings.grid_res, app.ZC_settings.scale_option, app.alignment.chi_coeff_cell, app.alignment.chi_nlm_rst_cell);

            end
            
            app.alignment.zcCC = computeCC(app);            
            
            updateResultLabel(app);     
            
            % enable history selection
            switch mode
                case 'manual'
                    app.manual.rot_atomlist = app.rot.atom_list;
                    app.history_original.Enable = true;
                    app.history_manual.Enable = true;
                    app.history_manual.Value = true;
                case 'surropt'
                    app.surropt.rot_atomlist = app.rot.atom_list;
                    app.history_original.Enable = true;
                    app.history_surropt.Enable = true;  
                    app.history_surropt.Value = true;
                case 'other'
                    % no history option applicable
            end
 
            app.SavePdbButton.Enable = true;
            
        end
        
        function disableSetupTab(app)
            
            app.ZCmomentorderDropDown.Enable = false;
            app.ZCmomentorderDropDownLabel.Enable = false;
            
            app.ShapeRepDropDown.Enable = false;
            app.shellThicknessLabel.Enable = false;
            app.shellThicknessSpinner.Enable = false;
            
            app.ProberadiusSpinnerLabel.Enable = false;
            app.probeRadiusSpinner.Enable = false;
            
            app.SmearfactorEditField.Enable = false;
            app.SmearfactorEditFieldLabel.Enable = false;
            
            app.RelativeisovalueEditField.Enable = false;
            app.RelativeisovalueEditFieldLabel.Enable = false;
            
            app.GridresolutionEditField.Enable = false;
            app.GridresolutionLabel.Enable = false;
            
            if app.rot.ROI.available
                app.rotROICheckBox.Enable = false;
            end
            if app.fix.ROI.available
                app.fixROICheckBox.Enable = false;
            end
            
            app.molSurfDropDown.Enable = false;  
            
        end
        
        function enableSetupTab(app)
        
            app.ZCmomentorderDropDown.Enable = true;
            app.ZCmomentorderDropDownLabel.Enable = true;
            
            app.ShapeRepDropDown.Enable = true;
            app.shellThicknessLabel.Enable = true;
            app.shellThicknessSpinner.Enable = true;
            
            app.ProberadiusSpinnerLabel.Enable = true;
            app.probeRadiusSpinner.Enable = true;
            
            app.SmearfactorEditField.Enable = true;
            app.SmearfactorEditFieldLabel.Enable = true;
            
            app.RelativeisovalueEditField.Enable = true;
            app.RelativeisovalueEditFieldLabel.Enable = true;
            
            app.GridresolutionEditField.Enable = true;
            app.GridresolutionLabel.Enable = true;
            
            if app.rot.ROI.available
                app.rotROICheckBox.Enable = true;
            end
            if app.fix.ROI.available
                app.fixROICheckBox.Enable = true;
            end
            
            app.molSurfDropDown.Enable = true;    
            
            app.RunSearchButton.Enable = true;
           
        end
        
        
        function stopSurrOpt(app)
            app.optimPlot.stop = true;
            
        end
        
        function importSelection(app, selAtomIDs)
            
            fprintf('\n Importing atom selection from JSmol...');
            
            selectedButton = app.ROIselButton.SelectedObject;
            
            if strcmp(selectedButton.Text, 'ROTATING STRUCTURE')
                
                app.brush.col = app.plot_settings.rotcolor;
                
                % get pdb data from selection
                app.brush.pdb_data_sel = parseJSsel(app, app.rot.pdb_data, selAtomIDs);
                
                all_IDs = 1:size(app.rot.atom_list(:,7));
                
                [~, notselectedIDs] = setdiff(all_IDs, selAtomIDs);
                
                app.rot.ROI.selectedIDs = selAtomIDs;
                app.rot.ROI.notSelectedIDs = notselectedIDs;
                
                % shift COM to COM for selected atoms
                selCOM = mean(app.rot.atom_list(selAtomIDs,1:3));
                
                app.brush.atom_list = app.rot.atom_list;
                app.brush.atom_list(:,1:3) = app.brush.atom_list(:,1:3) - selCOM;
                
                app.brush.atom_list_sel = app.brush.atom_list(selAtomIDs,:);
                app.brush.atom_list_notsel = app.brush.atom_list(notselectedIDs,:);                
                
                % store to ROI fields
                app.rot.ROI.atom_list_notsel = app.brush.atom_list_notsel;
                app.rot.ROI.atom_list_sel = app.brush.atom_list_sel;
                app.rot.ROI.atom_list = app.brush.atom_list;
                app.rot.ROI.atom_list_sel_original = app.brush.atom_list_sel;
                app.rot.ROI.pdb_data_sel = app.brush.pdb_data_sel;
                
                app.rot.ROI.ShapeType = app.ShapeRepDropDown.Value;
                
            elseif strcmp(selectedButton.Text, 'FIXED STRUCTURE')
                
                app.brush.col = app.plot_settings.fixcolor;
                
                app.brush.pdb_data_sel = parseJSsel(app, app.fix.pdb_data, selAtomIDs);
                
                all_IDs = 1:size(app.fix.atom_list(:,7));
                
                [~, notselectedIDs] = setdiff(all_IDs, selAtomIDs);
                
                app.fix.ROI.selectedIDs = selAtomIDs;
                app.fix.ROI.notSelectedIDs = notselectedIDs;
                
                % shift COM to COM for selected atoms
                selCOM = mean(app.fix.atom_list(selAtomIDs,1:3));
                
                app.brush.atom_list = app.fix.atom_list;
                app.brush.atom_list(:,1:3) = app.brush.atom_list(:,1:3) - selCOM;
                
                app.brush.atom_list_sel = app.brush.atom_list(selAtomIDs,:);
                app.brush.atom_list_notsel = app.brush.atom_list(notselectedIDs,:);
                
                % store to ROI fields
                app.fix.ROI.atom_list_notsel = app.brush.atom_list_notsel;
                app.fix.ROI.atom_list_sel = app.brush.atom_list_sel;
                app.fix.ROI.atom_list = app.brush.atom_list;
                app.fix.ROI.atom_list_sel_original = app.brush.atom_list_sel;
                app.fix.ROI.pdb_data_sel = app.brush.pdb_data_sel;
                
                app.fix.ROI.ShapeType = app.ShapeRepDropDown.Value;
                
            end
            
            fprintf('\n done.\n');
            
            fprintf('\n Creating shape representations for ROI and non-ROI... ');
            
            app.deleteROI.Enable = true;
            app.viewROIdefinedPanel.Title = '';
            
            app.DefineButton.Enable = true;
            
            %[scaling_factor, voxel_res] = compute_scaling(app, selCOM, app.brush.atom_list);
            %[scaling_factor, voxel_res] = compute_scaling(app, selCOM, app.brush.atom_list_sel);
                        
            %[app.brush.MS_sel, app.brush.MSmesh_sel, ~] = createShapeRep(app, app.brush.atom_list_sel, selCOM, scaling_factor, voxel_res);
            %[app.brush.MS_notsel, app.brush.MSmesh_notsel, ~] = createShapeRep(app, app.brush.atom_list_notsel, selCOM, scaling_factor, voxel_res);
            
            [app.brush.MS_sel, app.brush.MSmesh_sel, ~] = createShapeRep(app, app.brush.atom_list_sel);
            %[app.brush.MS_notsel, app.brush.MSmesh_notsel, ~] = createShapeRep(app, app.brush.atom_list_notsel);
            
            
            app.brush.MSmesh_sel_orig = app.brush.MSmesh_sel;
            %app.brush.MSmesh_notsel_orig = app.brush.MSmesh_notsel;
            
            % put ROI data to respective structure            
            if strcmp(selectedButton.Text, 'ROTATING STRUCTURE')
                
                app.rot.ROI.MS_sel = app.brush.MS_sel;
                app.rot.ROI.MSmesh_sel = app.brush.MSmesh_sel;
                %app.rot.ROI.MS_notsel = app.brush.MS_notsel;
                %app.rot.ROI.MSmesh_notsel = app.brush.MSmesh_notsel;
                
                app.rot.ROI.available = true;
                
                app.rotROICheckBox.Enable = true;
                app.rotROICheckBox.Value = true;
                
                opacity_op = app.RotTransparentButton.Value;
                
                rotROICheckBoxValueChanged(app);
                
            elseif strcmp(selectedButton.Text, 'FIXED STRUCTURE')
                
                app.fix.ROI.MS_sel = app.brush.MS_sel;
                app.fix.ROI.MSmesh_sel = app.brush.MSmesh_sel;
                %app.fix.ROI.MS_notsel = app.brush.MS_notsel;
                %app.fix.ROI.MSmesh_notsel = app.brush.MSmesh_notsel;
                
                app.fix.ROI.available = true;
                
                app.fixROICheckBox.Enable = true;
                app.fixROICheckBox.Value = true;
                
                opacity_op = app.FixTransparentButton.Value;
                
                fixROICheckBoxEnabled(app);
            end
            
            if app.brush.update
                %delete(app.brush.ph); delete(app.brush.ph2);
                delete(app.brush.ph);
            end
            
            %[app.brush.ph, app.brush.ph2] = plotROI(app, app.UIAxes3, app.brush.MSmesh_sel, app.brush.MSmesh_notsel, app.brush.col, [1 1 1], opacity_op);
            app.brush.ph = plotROI(app, app.UIAxes3, app.brush.MSmesh_sel, app.brush.col, opacity_op);
            
            app.brush.update = true;
            
            message = sprintf('ROI-based alignment enabled. You can disable this in the setup tab.');
            uialert(app.ZEALUIFigure,message,'Info', ...
                'Icon','Info');
            
            app.TabGroup.SelectedTab = app.SetupTab;
            
            delete(app.dialogs.JSmolSelection);
            
            fprintf('done.\n');
            
        end
        
       
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                     
            warning off Matlab:structOnObject 
            
            %% Setup paths
            if ~isdeployed
                
                app.paths.libPath = fullfile(pwd,'lib');
                app.paths.JSmol = fullfile(app.paths.libPath, 'jsmol');
                app.paths.JSmoltmp = fullfile(app.paths.JSmol,'tmp');
                
                app.paths.tmp =  fullfile(pwd,'tmp');
                app.paths.chiCoeffs = fullfile(app.paths.libPath, 'chi_coefficients');
                app.paths.images = fullfile(app.paths.libPath,'images');
                app.paths.icons = fullfile(app.paths.images,'icons');
                app.paths.ROIpdbpath = app.paths.tmp;
                app.paths.samples = fullfile(pwd,'samples');
                
            else
                
                app.paths.libPath = fullfile('/lib/');
                app.paths.JSmol = fullfile(app.paths.libPath, 'jsmol');
                app.paths.JSmoltmp = fullfile(app.paths.JSmol,'tmp');
                
                app.paths.tmp =  fullfile('tmp');
                app.paths.chiCoeffs = fullfile(app.paths.libPath, 'chi_coefficients');
                app.paths.images = fullfile(app.paths.libPath,'images');
                app.paths.icons = fullfile(app.paths.images,'icons');
                app.paths.ROIpdbpath = app.paths.tmp;
                app.paths.samples = fullfile('samples');
                
            end
            
            app.fix.path = app.paths.samples;
            app.rot.path = app.paths.samples;
            
            if ispc
                app.paths.userDir = winqueryreg('HKEY_CURRENT_USER',...
                    ['Software\Microsoft\Windows\CurrentVersion\' ...
                    'Explorer\Shell Folders'],'Personal');
            else
                app.paths.userDir = char(java.lang.System.getProperty('user.home'));
            end
            
            
            %Position main app            
            screen_res = get(0,'screensize');
            
            app_position = app.ZEALUIFigure.Position;
            
            app_xpos = screen_res(3)/2-app_position(3)/2;
            app_ypos = screen_res(4)/2-app_position(4)/2;
            
            app.ZEALUIFigure.Position = [app_xpos app_ypos app_position(3) app_position(4)];
                        
            % ZC parameters
            app.ZC_settings.order = str2double(app.ZCmomentorderDropDown.Value);        
            app.ZC_settings.grid_res = app.GridresolutionEditField.Value;
            app.ZC_settings.scale_option = 1;
        
            % shape parameters
            app.shape_settings.probe_radius = app.probeRadiusSpinner.Value;
            app.shape_settings.shell_thickness = app.shellThicknessSpinner.Value;
            app.shape_settings.surf_type = 'MS';
            app.shape_settings.do_mesh = true;
            app.shape_settings.smear_factor = app.SmearfactorEditField.Value;
            app.shape_settings.rel_iso = app.RelativeisovalueEditField.Value;
            
            % these are strings at start, change to integers
            app.ShapeRepDropDown.ItemsData = [1 2];
            app.ShapeRepDropDown.Value = 1;
            
            app.shape_settings.shape_rep_type = app.ShapeRepDropDown.Value;
            
            % Load/compute chi coeffs for the ZC order
            [app.alignment.chi_coeff_cell, app.alignment.chi_nlm_rst_cell] = loadChiCoeffs(app);
            
            
            % axes setup
            app.plot_settings.tb = axtoolbar(app.UIAxes,{'zoomin','zoomout'});
            app.UIAxes.Interactions = [rotateInteraction zoomInteraction];
                      
            
            app.UIAxes.View = [20 30];
            app.UIAxes.DataAspectRatio = [1 1 1];
            camproj(app.UIAxes, 'perspective');
            app.UIAxes.CameraViewAngleMode = 'manual';
            
            app.UIAxes.XLim = [0 app.ZC_settings.grid_res];
            app.UIAxes.YLim = [0 app.ZC_settings.grid_res];
            app.UIAxes.ZLim = [0 app.ZC_settings.grid_res];
            
            app.UIAxes3.XLim = [0 app.ZC_settings.grid_res];
            app.UIAxes3.YLim = [0 app.ZC_settings.grid_res];
            app.UIAxes3.ZLim = [0 app.ZC_settings.grid_res];
            
            % remove toolbar for figures - we only have rotation of objects
            % using our own virtual trackball implementation
            axtoolbar(app.UIAxes,{});
            axtoolbar(app.UIAxes3,{});
            
            
            % Virtual trackball parameters  
            app.alignment.R = eye(3);  
            
            axPos = app.UIAxes.Position;            
            app.trackball.center = [axPos(1)+axPos(3)/2 axPos(2)+axPos(4)/2];
            
            app.trackball.radius = 0.5*(sqrt(axPos(3)^2 + axPos(4)^2));
            app.trackball.move = false;
            app.trackball.R0 = eye(3);            
          
            app.trackball.camva0 = camva(app.UIAxes);  
            
            app.trackball.scene = true;
            app.trackball.active = true;
            
            app.trackball.manualAlignment = false;
    
            % misc           
            app.alignment.updaterot = false;
            app.alignment.updatefix = false;                        
            app.alignment.zcCC = NaN;
            
            app.fix.loaded = false;
            app.rot.loaded = false;
            
                        
            % set plotting parameters
            app.plot_settings.update_rotaxis = false;
            app.plot_settings.updatefix = true;
            app.plot_settings.drawnowFreq = 5; % controls how often UIAxes updates during surropt search
            
            % surface smoothing
            smoothing.active = false;  
            smoothing.size = 5;
            smoothing.var = 3;
            smoothing.filter_type = 1; % Gaussian
            
            app.plot_settings.smoothing = smoothing;
            
            % lighting
            app.plot_settings.ch = camlight(app.UIAxes,'left');
            app.plot_settings.update_camlight_op = true;
            
            % axes
            app.plot_settings.crd_box = false;
            app.plot_settings.delaxes = false;            
            app.plot_settings.fixcolor = [0.8500    0.3250    0.0980];
            app.plot_settings.rotcolor = [0    0.4470    0.7410];
            
            % create colormap
            app.plot_settings.colors = lines(10);
            app.plot_settings.color_map=parula(10);
            
            % local crd system (used in manual search)
            origo = round(app.ZC_settings.grid_res/2);
            ax_length = round(1.2 * origo);
            
            ax_loc_vals = (origo-ax_length):(origo+ax_length);
            ax_loc_dummy = ones(1,length(ax_loc_vals))*origo;
            
            app.plot_settings.xaxis = [ax_loc_vals; ax_loc_dummy; ax_loc_dummy];
            app.plot_settings.yaxis = [ax_loc_dummy; ax_loc_vals; ax_loc_dummy];
            app.plot_settings.zaxis = [ax_loc_dummy; ax_loc_dummy; ax_loc_vals];
          
            %% Region of interest selection 
         
            app.DefineButton.Enable = false;
            app.brush.ch = camlight(app.UIAxes3,'left');
            
            app.UIAxes3.DataAspectRatio = [1 1 1];
            camproj(app.UIAxes3,'perspective');
            app.fix.ROI.available = false;
            app.rot.ROI.available = false;
            app.brush.update = false;
            
            %% JSmol      
            app.JSmol.webGL = true;
            app.JSmol.trueTrans = 'false';
            
            app.JSmol.rotSaved = false;
            app.JSmol.fixSaved = false;
            
            % Surface representation 
            switch app.ShapeRepDropDown.Value
                case 1
                    app.molSurfDropDown.Visible = true;
                    app.probeRadiusSpinner.Visible = true;
                    app.ProberadiusSpinnerLabel.Visible = true;
                    app.shellThicknessLabel.Visible = true;
                    app.shellThicknessSpinner.Visible = true;
                    
                case 2
                    app.SmearfactorEditField.Visible = true;
                    app.SmearfactorEditFieldLabel.Visible = true;
                    app.RelativeisovalueEditField.Visible = true;
                    app.RelativeisovalueEditFieldLabel.Visible = true;
            end
            
            % Random rotation options
            app.alignment.randrotangle = deg2rad(90);
                        
            % Surrogate optimization options            
            app.surropt.viewprogress = true;           
            app.surropt.options = optimoptions('surrogateopt');
            app.surropt.options.MaxFunctionEvaluations = 500;
            app.surropt.options.MaxTime = Inf;
            app.surropt.options.ObjectiveLimit = -0.99;
            app.surropt.options.OutputFcn = @app.surrOptiOutputFcn;
            app.surropt.options.PlotFcn = [];
            
            app.surropt.lb = [0 0 0 ];
            app.surropt.ub = [2*pi pi 2*pi];
            
            app.surropt.objfunstr = 'Spatial correlation coefficient';
            
            if app.solverSearchOp.Value
                app.RunSearchButton.Text = 'Global search';
            end
           
                                    
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ZEALUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {598, 598};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {250, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: importFix
        function LoadpdbButton_fixPushed(app, event)
            
            if app.fix.loaded                
                resetFix(app);                
            else
                
                loadMode = app.fixSwitch.Value;
                
                chainIDsel = app.fixCHAINIDEditField.Value;
                
                if isempty(chainIDsel)
                    chainStr = '';
                else
                    chainStr = sprintf(' (chain %s)', chainIDsel);
                end
                
                if strcmp(loadMode,'LOAD')
                    
                    app.ZEALUIFigure.Visible = 'off';
                    [app.fix.name, app.fix.path] = uigetfile(fullfile(app.fix.path, '*.*'));
                   
                    app.rot.path = app.fix.path;
                    app.ZEALUIFigure.Visible = 'on';
                  
                    %figure(app.ZEALUIFigure);
                    
                    if app.fix.name == 0
                        return
                    else       
                        MeStr = sprintf('Loading %s%s', app.fix.name, chainStr);
                        d = uiprogressdlg(app.ZEALUIFigure,'Title','Please Wait', 'Indeterminate','on','Message',MeStr);
    
                        [app.fix.pdb_data, app.fix.pdb_data_incHetAtoms, app.fix.pdb_data_all, app.fix.pdb_data_all_nohet, foundFileTF, downloadedFileTF] = pdb2mat(fullfile(app.fix.path, app.fix.name), chainIDsel, true);
                        
                        close(d);
                        
                        if ~foundFileTF && downloadedFileTF
                            warningMsg = sprintf('Could not find file. Downloaded structure %s from RCSB PDB instead', app.fix.name(1:4));
                            uialert(app.ZEALUIFigure, warningMsg, 'Warning', 'icon', 'warning');
                        end
                        
                        if isempty(app.fix.pdb_data.X)
                            errorStr = sprintf('No atom records for chain ID "%s" found in %s', chainIDsel, app.fix.name);
                            uialert(app.ZEALUIFigure, errorStr, 'Loading error','icon', 'error');
                            return;
                        end
                        
                        app.fix_pdbfile.Enable = true;
                        app.fix_pdbfile.Value = sprintf('%s',app.fix.name);
                    end
                else
                    
                    pdbidSel = app.fixPDBIDEditField.Value;
                    app.fix.name = [pdbidSel chainIDsel];
                    
                    if isempty(pdbidSel)
                        uialert(app.ZEALUIFigure, 'Please enter a PDB ID in the field before loading.','','icon', 'warning');
                        return;
                        
                    elseif length(pdbidSel)~=4
                        uialert(app.ZEALUIFigure, 'The PDB ID should be 4 characters long (''1ubq'' for instance to download Ubiquitin ).','','icon', 'warning');
                        return;
                    else
                        
                        MeStr = sprintf('Downloading %s%s from PDB', upper(pdbidSel), chainStr);
                        d = uiprogressdlg(app.ZEALUIFigure,'Title','Please Wait', 'Indeterminate','on','Message',MeStr);
    
                        [app.fix.pdb_data, app.fix.pdb_data_incHetAtoms, app.fix.pdb_data_all, app.fix.pdb_data_all_nohet, ~, downloadedFileTF] = pdb2mat(pdbidSel, chainIDsel, true);
                       
                        
                        close(d);
                        if ~downloadedFileTF
                            errorMsg = sprintf('Could not download structure %s from RCSB PDB', pdbidSel);
                            uialert(app.ZEALUIFigure, errorMsg, 'Loading error','icon', 'error');
                            return;
                        end
                        
                        if isempty(app.fix.pdb_data.X)
                            errorStr = sprintf('No atom records found for PDB ID "%s" and chain ID "%s"', pdbidSel, chainIDsel);
                            uialert(app.ZEALUIFigure, errorStr, 'Loading error','icon', 'error');
                            return;
                        end
                    end
                end

                 % Assert that atom indices go from 1:#atoms  
                app.fix.pdb_data = reIndexAtomNum(app,app.fix.pdb_data);
                
                 % Extract and add what we need from pdb struct.             
                [app.fix.atom_list, HydrogenList] = parsePdbStruct(app.fix.pdb_data);
                
                % Strip any H/D from model
                if ~isempty(HydrogenList)                    
                    app.fix.pdb_data = stripHpdbdata(app.fix.pdb_data, HydrogenList);                
                end
                
                app.original.fix_atomlist = app.fix.atom_list;
                
                app.fix.loaded = true;
                
                [app.fix.MS, app.fix.MSmesh, app.fix.MSsolid] = createShapeRep(app, app.fix.atom_list);
                 
                % Update GUI
                app.importFix.BackgroundColor = [0.94 0.94 0.94];
                
                if isdeployed
                    app.importFix.Icon = 'cancel_100px.png';                    
                else
                    app.importFix.Icon = fullfile(app.paths.icons, 'cancel_100px.png');
                end
                
                app.importFix.Text = 'Delete';
                
                app.importFix.Tooltip = 'Reset and delete fixed structure.';
                
                app.fixCHAINIDEditField.BackgroundColor = app.plot_settings.fixcolor;
                app.fixCHAINIDEditField.FontColor = [1 1 1];
                app.fixCHAINIDEditField.FontWeight = 'bold';
                
                if strcmp(loadMode,'LOAD')
                    app.fix_pdbfile.BackgroundColor = app.plot_settings.fixcolor;
                    app.fix_pdbfile.FontColor = [1 1 1];
                    app.fix_pdbfile.FontWeight = 'bold';
                    app.fix_pdbfile.FontSize = 12;
                else
                    app.fixPDBIDEditField.BackgroundColor = app.plot_settings.fixcolor;
                    app.fixPDBIDEditField.FontColor = [1 1 1];
                    app.fixPDBIDEditField.FontWeight = 'bold';
                end
                
                % Show structure, compute alignment results and enable settings  
                plotFix(app);
                
                if app.rot.loaded
                    initializeSession(app);
                    enableSearch(app);
                    enableSetupTab(app);
                end
            end
           
        end

        % Button pushed function: importRot
        function LoadpdbButton_rotPushed(app, event)
            if app.rot.loaded
                resetRot(app);
            else
                
                loadMode = app.rotSwitch.Value;
                
                chainIDsel = app.rotCHAINIDEditField.Value;
                
                if isempty(chainIDsel)
                    chainStr = '';
                else
                    chainStr = sprintf(' (chain %s)', chainIDsel);
                end
                
                if strcmp(loadMode,'LOAD')
                    
                    app.ZEALUIFigure.Visible = 'off';
                    [app.rot.name, app.rot.path] = uigetfile(fullfile(app.rot.path, '*.*'));
                    app.ZEALUIFigure.Visible = 'oN';
                    
                    app.fix.path = app.rot.path;
                    
                    if app.rot.name == 0
                        return
                    else

                        MeStr = sprintf('Loading %s%s', app.rot.name, chainStr);
                        d = uiprogressdlg(app.ZEALUIFigure,'Title','Please Wait', 'Indeterminate','on','Message',MeStr);
    
                        [app.rot.pdb_data, app.rot.pdb_data_incHetAtoms, app.rot.pdb_data_all, app.rot.pdb_data_all_nohet, foundFileTF, downloadedFileTF] = pdb2mat(fullfile(app.rot.path, app.rot.name), chainIDsel, true);
                        
                        close(d);
                        
                        if ~foundFileTF && downloadedFileTF
                            warningMsg = sprintf('Could not find file. Downloaded structure %s from RCSB PDB instead', app.rot.name(1:4));
                            uialert(app.ZEALUIFigure, warningMsg, 'Warning', 'icon', 'warning');
                        end
                        
                        if isempty(app.rot.pdb_data.X)
                            errorStr = sprintf('No atom records for chain ID "%s" found in %s', chainIDsel, app.rot.name);
                            uialert(app.ZEALUIFigure, errorStr, 'Loading error','icon', 'error');
                            return;
                        end
                        
                        app.rot_pdbfile.Enable = true;
                        app.rot_pdbfile.Value = sprintf('%s',app.rot.name);
                    end
                    
                else
                    
                    pdbidSel = app.rotPDBIDEditField.Value;
                    app.rot.name = [pdbidSel chainIDsel];
                    
                    if isempty(pdbidSel)
                        uialert(app.ZEALUIFigure, 'Please enter a PDB ID in the field before loading.','','icon', 'warning');
                        return;
                        
                    elseif length(pdbidSel)~=4
                        uialert(app.ZEALUIFigure, 'The PDB ID should be 4 characters long (''1ubq'' for instance to download Ubiquitin ).','','icon', 'warning');
                        return;
                    else

                        MeStr = sprintf('Downloading %s%s from PDB', upper(pdbidSel), chainStr);
                        d = uiprogressdlg(app.ZEALUIFigure,'Title','Please Wait', 'Indeterminate','on','Message',MeStr);
    
                        [app.rot.pdb_data, app.rot.pdb_data_incHetAtoms, app.rot.pdb_data_all, app.rot.pdb_data_all_nohet, ~, downloadedFileTF] = pdb2mat(pdbidSel, chainIDsel, true);
                        
                        close(d);
                        
                        if ~downloadedFileTF
                            errorMsg = sprintf('Could not download structure %s from RCSB PDB', pdbidSel);
                            uialert(app.ZEALUIFigure, errorMsg, 'Loading error','icon', 'error');
                            return;
                        end
                        
                        if isempty(app.rot.pdb_data.X)
                            errorStr = sprintf('No atom records found for PDB ID "%s" and chain ID "%s"', pdbidSel, chainIDsel);
                            uialert(app.ZEALUIFigure, errorStr, 'Loading error','icon', 'error');
                            return;
                        end
                    end
                end
                
                % Assert that atom indices go from 1:#atoms
                app.rot.pdb_data = reIndexAtomNum(app,app.rot.pdb_data);
                
                % Extract and add what we need from pdb struct.
                [app.rot.atom_list, HydrogenList] = parsePdbStruct(app.rot.pdb_data);
                
                % Strip any H/D from model
                if ~isempty(HydrogenList)
                    app.rot.pdb_data = stripHpdbdata(app.rot.pdb_data, HydrogenList);
                end
                
                app.original.rot_atomlist = app.rot.atom_list;
                
                app.rot.loaded = true;
                
                [app.rot.MS, app.rot.MSmesh, app.rot.MSsolid] = createShapeRep(app, app.rot.atom_list);
                
                app.rot.MSmesh_orig = app.rot.MSmesh;
                
                % Update GUI
                app.importRot.BackgroundColor = [0.94 0.94 0.94];
                if isdeployed
                    app.importRot.Icon = 'cancel_100px.png';
                else
                    app.importRot.Icon = fullfile(app.paths.icons, 'cancel_100px.png');
                end
                
                app.importRot.Text = 'Delete';
                
                app.importRot.Tooltip = 'Reset and delete rotating structure.';
                
                app.rotCHAINIDEditField.BackgroundColor = app.plot_settings.rotcolor;
                app.rotCHAINIDEditField.FontColor = [1 1 1];
                app.rotCHAINIDEditField.FontWeight = 'bold';
                
                if strcmp(loadMode,'LOAD')
                    app.rot_pdbfile.BackgroundColor = app.plot_settings.rotcolor;
                    app.rot_pdbfile.FontColor = [1 1 1];
                    app.rot_pdbfile.FontWeight = 'bold';
                    app.rot_pdbfile.FontSize = 12;
                else
                    app.rotPDBIDEditField.BackgroundColor = app.plot_settings.rotcolor;
                    app.rotPDBIDEditField.FontColor = [1 1 1];
                    app.rotPDBIDEditField.FontWeight = 'bold';
                end
                
                % Show structure, compute alignment results and enable settings  
                plotRot(app);
                
                if app.fix.loaded
                    initializeSession(app);
                    enableSearch(app);
                    enableSetupTab(app);
                end
                
            end
            
        end

        % Callback function
        function ZCmomentorderEditFieldValueChanged(app, event)
            value = app.ZCmomentorderEditField.Value;
            
            
            app.ZC_settings.order = value;
            [app.alignment.chi_coeff_cell, app.alignment.chi_nlm_rst_cell] = loadChiCoeffs(app);
           
            % Compute new ZC moments
            [app.fix.ZC.mom_nlm_list, app.fix.ZC.mom_list, app.fix.ZC.mom] = getZCmomFromShape(app.fix.MS, app.ZC_settings.order, app.ZC_settings.grid_res, app.ZC_settings.scale_option, app.alignment.chi_coeff_cell, app.alignment.chi_nlm_rst_cell);
            [app.rot.ZC.mom_nlm_list, app.rot.ZC.mom_list, app.rot.ZC.mom] = getZCmomFromShape(app.rot.MS, app.ZC_settings.order, app.ZC_settings.grid_res, app.ZC_settings.scale_option, app.alignment.chi_coeff_cell, app.alignment.chi_nlm_rst_cell);
            
            [app.alignment.zcCC] = computeCC(app);
            
            updateResultLabel(app);
        end

        % Value changed function: ZCmomentorderDropDown
        function ZCmomentorderDropDownValueChanged(app, event)
            value = app.ZCmomentorderDropDown.Value;
            
            app.ZC_settings.order = str2double(value);
            
            [app.alignment.chi_coeff_cell, app.alignment.chi_nlm_rst_cell] = loadChiCoeffs(app);
            
            % Compute new ZC moments
            [app.fix.ZC.mom_nlm_list, app.fix.ZC.mom_list, app.fix.ZC.mom] = getZCmomFromShape(app.fix.MS, app.ZC_settings.order, app.ZC_settings.grid_res, app.ZC_settings.scale_option, app.alignment.chi_coeff_cell, app.alignment.chi_nlm_rst_cell);
            [app.rot.ZC.mom_nlm_list, app.rot.ZC.mom_list, app.rot.ZC.mom] = getZCmomFromShape(app.rot.MS, app.ZC_settings.order, app.ZC_settings.grid_res, app.ZC_settings.scale_option, app.alignment.chi_coeff_cell, app.alignment.chi_nlm_rst_cell);
            
            [app.alignment.zcCC] = computeCC(app);
            
            updateResultLabel(app);
            
        end

        % Value changed function: GridresolutionEditField
        function GridresolutionEditFieldValueChanged2(app, event)
            
            app.ZC_settings.grid_res = app.GridresolutionEditField.Value;
                        
            % fixed
            switch app.ShapeRepDropDown.Value
                case 1
                    [app.fix.MS, app.fix.MSmesh, app.fix.MSsolid, app.fix.scaling_factor] = createShapeRep(app, app.fix.atom_list);
                case 2
                    [app.fix.MS, app.fix.MSmesh, app.fix.elDensityVolNorm, app.fix.scaling_factor] = createShapeRep(app, app.fix.atom_list);
            end
            
            % rotating
            switch app.ShapeRepDropDown.Value
                case 1
                    [app.rot.MS, app.rot.MSmesh, app.rot.MSsolid, app.rot.scaling_factor] = createShapeRep(app, app.rot.atom_list);
                case 2
                    [app.rot.MS, app.rot.MSmesh, app.rot.elDensityVolNorm, app.rot.scaling_factor] = createShapeRep(app, app.rot.atom_list);
            end
            
            app.rot.MSmesh_orig = app.rot.MSmesh;
                        
            app.plot_settings.updatefix = true;
             
            plotAlignment(app);
            
        end

        % Button pushed function: RunSearchButton
        function RunSearchButtonPushed(app, event)
            
            if app.manualSearchOp.Value   
                
                app.dialogs.manual = manualSearchDialog(app);  
                
            elseif app.solverSearchOp.Value   
                
                 app.optimPlot.stop = false;
                 app.optimPlot.iteration = 0;
                 app.optimPlot.fval = -app.alignment.zcCC;
                
                app.dialogs.surrOptimPlot = surrOptimPlotDialog(app, app.optimPlot);
               
%                  app.dialogs.surrOptimPlot.ph.XDataSource = 'app.optimPlot.iterationTest';
%                  app.dialogs.surrOptimPlot.ph.YDataSource = 'app.optimPlot.fvalTest';
                
                pause(1);
                
                globalSearch(app);                               
                                
            elseif app.randOp.Value
                
                alpha = (rand(1)-0.5)* app.alignment.randrotangle*2;
                beta = (rand(1)-0.5)* app.alignment.randrotangle;
                gamma = (rand(1)-0.5)* app.alignment.randrotangle*2;
                
                app.alignment.R = euler2rotationMatrix(alpha, beta, gamma, 'zyz');
                
                updateAfterSearch(app,'random');              
                
            end
            
        end

        % Selection changed function: HistoryGroup
        function HistoryGroupSelectionChanged(app, event)
            
            if app.history_original.Value
              
                app.rot.atom_list = app.original.rot_atomlist;
                
            elseif app.history_manual.Value
              
                app.rot.atom_list = app.manual.rot_atomlist;
                
            elseif app.history_surropt.Value
               
                app.rot.atom_list = app.surropt.rot_atomlist;
                
            end
            
            updateAllShapesAndZCcc(app);

        end

        % Value changed function: probeRadiusSpinner
        function probeRadiusSpinnerValueChanged(app, event)
            
            app.shape_settings.probe_radius = event.Value;
            
            updateAllShapesAndZCcc(app);
        end

        % Button pushed function: FixColorButton
        function FixColorButtonPushed(app, event)
            app.plot_settings.fixcolor = uisetcolor([0.8500    0.3250    0.0980],'Select a color for the fixed structure');
                
            app.FixedstructurePanel.ForegroundColor = app.plot_settings.fixcolor;
 
            app.fix_pdbfile.BackgroundColor = app.plot_settings.fixcolor;
                        
            %app.fixROISelect.FontColor = app.plot_settings.fixcolor;
            
            app.fixCHAINIDEditField.BackgroundColor = app.plot_settings.fixcolor;
            app.fixPDBIDEditField.BackgroundColor = app.plot_settings.fixcolor;
            
            app.plot_settings.updatefix = true;
            plotAlignment(app);
        end

        % Button pushed function: RotColorButton
        function RotColorButtonPushed(app, event)
            app.plot_settings.rotcolor = uisetcolor([0    0.4470    0.7410],'Select a color for the rotating structure');
            
            app.RotatingstructurePanel.ForegroundColor = app.plot_settings.rotcolor;
            
            app.rot_pdbfile.BackgroundColor = app.plot_settings.rotcolor;
            
            %app.rotROISelect.FontColor = app.plot_settings.rotcolor;
            
            app.rotCHAINIDEditField.BackgroundColor = app.plot_settings.rotcolor;
            app.rotPDBIDEditField.BackgroundColor = app.plot_settings.rotcolor;
             
            plotAlignment(app);
        end

        % Value changed function: FixTransparentButton
        function FixTransparentButtonValueChanged(app, event)
            app.plot_settings.updatefix = true;
            plotAlignment(app);
        end

        % Value changed function: RotTransparentButton
        function RotTransparentButtonValueChanged(app, event)
            plotAlignment(app);            
        end

        % Button pushed function: SavePdbButton
        function SavePdbButtonPushed(app, event)
            
            ZEALsettings = getZEALsettings(app);       
           
            switch app.StructureDropDown.Value     
                case 'All'
                    default_save_name = sprintf('%s_%s_ZEAL.pdb', ZEALsettings.fix.name, ZEALsettings.rot.name);
                    ZEALsettings.thisFile = 'all';
                case 'Rotating'
                    default_save_name = sprintf('%s_ZEAL.pdb', ZEALsettings.rot.name);
                    ZEALsettings.thisFile = 'rot';
                case 'Fixed'
                    default_save_name = sprintf('%s_ZEAL.pdb', ZEALsettings.fix.name);      
                    ZEALsettings.thisFile = 'fix';
                case 'ROI Rotating'
                    default_save_name = sprintf('%s_ZEALroi.pdb', ZEALsettings.rot.name);
                    ZEALsettings.thisFile = 'rot';
                case 'ROI Fixed'
                    default_save_name = sprintf('%s_ZEALroi.pdb', ZEALsettings.fix.name);
                    ZEALsettings.thisFile = 'fix';
            end
            
            app.ZEALUIFigure.Visible = 'off';
            [file,path] = uiputfile(fullfile(app.fix.path,'*.pdb'),'Save pdb file ',default_save_name);
            
            app.fix.path = path;
            app.rot.path = path;
            
            app.ZEALUIFigure.Visible = 'on';
            
            filename = fullfile(path, file);
            
            if isempty(file)
                uialert(app.ZEALUIFigure, 'No file exported. ')
                return         
            end
            
            switch app.StructureDropDown.Value
                
                case {'All'}
                    
                    fixModel = updatePDBdata(app, 'fixed');
                    rotModel = updatePDBdata(app, 'rotating');
                    ZEALexportPdb_all(filename, ZEALsettings, fixModel, rotModel)
                    
              
                case {'Rotating'}

                   if app.HETATOMSCheckBox.Value && app.AllrecordsCheckBox.Value
                   
                    pdbData = app.rot.pdb_data_all;
                   
                   elseif app.HETATOMSCheckBox.Value && ~app.AllrecordsCheckBox.Value
                       
                       pdbData = app.rot.pdb_data_incHetAtoms;
                       
                   elseif ~app.HETATOMSCheckBox.Value && app.AllrecordsCheckBox.Value 
                       
                       pdbData = app.rot.pdb_data_all_nohet;
                       
                   elseif ~app.HETATOMSCheckBox.Value && ~app.AllrecordsCheckBox.Value 
                       
                       pdbData = app.rot.pdb_data;
                       
                   end
                    
                    xyz = [pdbData.X' pdbData.Y' pdbData.Z'];
                    
                    R = computeRotationMatrix(app,'rotating');

                    T = computeTranslationMatrix(app, 'rotating');
                    
                    xyzNew = [ xyz ones(length(xyz),1)] * T * R;
                    
                    pdbData.X = xyzNew(:,1);
                    pdbData.Y = xyzNew(:,2);
                    pdbData.Z = xyzNew(:,3);

                    ZEALexportPdb(filename, ZEALsettings, pdbData);
                    
                case {'ROI Rotating'}
                    
                    if app.HETATOMSCheckBox.Value && app.AllrecordsCheckBox.Value
                   
                    pdbData = app.rot.pdb_data_all;
                   
                   elseif app.HETATOMSCheckBox.Value && ~app.AllrecordsCheckBox.Value
                       
                       pdbData = app.rot.pdb_data_incHetAtoms;
                       
                   elseif ~app.HETATOMSCheckBox.Value && app.AllrecordsCheckBox.Value 
                       
                       pdbData = app.rot.pdb_data_all_nohet;
                       
                   elseif ~app.HETATOMSCheckBox.Value && ~app.AllrecordsCheckBox.Value 
                       
                       pdbData = app.rot.ROI.pdb_data_sel;
                       
                   end
                    
                    xyz = [pdbData.X' pdbData.Y' pdbData.Z'];
                    
                    R = computeRotationMatrix(app,'ROIrotating');

                    T = computeTranslationMatrix(app, 'ROIrotating');
                    
                    xyzNew = [ xyz ones(length(xyz),1)] * T * R;
                    
                    pdbData.X = xyzNew(:,1);
                    pdbData.Y = xyzNew(:,2);
                    pdbData.Z = xyzNew(:,3);

                    ZEALexportPdb(filename, ZEALsettings, pdbData);
                                                            
                    %rotModel = updatePDBdata_ROI(app, 'rotating');
                    %ZEALexportPdb(filename, ZEALsettings, rotModel)
                    
                case {'Fixed'}
                    
                    if app.HETATOMSCheckBox.Value && app.AllrecordsCheckBox.Value
                   
                    pdbData = app.fix.pdb_data_all;
                   
                   elseif app.HETATOMSCheckBox.Value && ~app.AllrecordsCheckBox.Value
                       
                       pdbData = app.fix.pdb_data_incHetAtoms;
                       
                   elseif ~app.HETATOMSCheckBox.Value && app.AllrecordsCheckBox.Value 
                       
                       pdbData = app.fix.pdb_data_all_nohet;
                       
                   elseif ~app.HETATOMSCheckBox.Value && ~app.AllrecordsCheckBox.Value 
                       
                       pdbData = app.fix.ROI.pdb_data_sel;
                       
                    end
                    
                    xyz = [pdbData.X' pdbData.Y' pdbData.Z'];
                    
                    T = computeTranslationMatrix(app, 'fixed');
                     
                    xyzNew = [ xyz ones(length(xyz),1)] * T;
                    
                    %xyz0 = bsxfun(@minus, xyz, COM0);
                    
                    pdbData.X = xyzNew(:,1);
                    pdbData.Y = xyzNew(:,2);
                    pdbData.Z = xyzNew(:,3);
                    
                    ZEALexportPdb(filename, ZEALsettings, pdbData);
                    
                    %fixModel = updatePDBdata(app, 'fixed');
                    %ZEALexportPdb(filename, ZEALsettings, fixModel)
                    
                case {'ROI Fixed'}
                     if app.HETATOMSCheckBox.Value && app.AllrecordsCheckBox.Value
                   
                    pdbData = app.fix.pdb_data_all;
                   
                   elseif app.HETATOMSCheckBox.Value && ~app.AllrecordsCheckBox.Value
                       
                       pdbData = app.fix.pdb_data_incHetAtoms;
                       
                   elseif ~app.HETATOMSCheckBox.Value && app.AllrecordsCheckBox.Value 
                       
                       pdbData = app.fix.pdb_data_all_nohet;
                       
                   elseif ~app.HETATOMSCheckBox.Value && ~app.AllrecordsCheckBox.Value 
                       
                       pdbData = app.fix.ROI.pdb_data_sel;
                       
                   end
                    
                    xyz = [pdbData.X' pdbData.Y' pdbData.Z'];
                    
                    R = computeRotationMatrix(app,'ROIfixed');

                    T = computeTranslationMatrix(app, 'ROIfixed');
                    
                    xyzNew = [ xyz ones(length(xyz),1)] * T * R;
                    
                    pdbData.X = xyzNew(:,1);
                    pdbData.Y = xyzNew(:,2);
                    pdbData.Z = xyzNew(:,3);
                    
                    ZEALexportPdb(filename, ZEALsettings, pdbData);                    
                    
               
            end
            
        end

        % Window button down function: ZEALUIFigure
        function ZEALUIFigureWindowButtonDown(app, event)
           
            if app.trackball.active
                
                if app.TabGroup3.SelectedTab == app.ViewshapesTab
                    AxPos = app.UIAxes.Position;     
                elseif app.TabGroup3.SelectedTab == app.RegionOfInterestTab
                    AxPos = app.UIAxes3.Position; 
                end
                
                RightPanelPos = app.RightPanel.Position;
                
                if RightPanelPos(2)<1
                    RightPanelPos(2)=1;
                end
                
                centerXpos = RightPanelPos(1) + AxPos(1) + AxPos(3)/2;
                centerYpos = RightPanelPos(2) + AxPos(2) + AxPos(4)/2;                
                
                app.trackball.center = [centerXpos centerYpos];
                
                app.trackball.radius = 0.25 * sqrt(AxPos(3)^2 + AxPos(4)^2);
                
                app.trackball.move = true;
                currPoint = app.ZEALUIFigure.CurrentPoint;
                xy = [currPoint(1,1) currPoint(1,2)] - app.trackball.center;
                
                app.trackball.xyz0 = getPointOnTrackball(app, xy(1), xy(2), app.trackball.radius);
                
                % Rotation from eye to world coordinates
                if app.TabGroup3.SelectedTab == app.ViewshapesTab
                    v = normr(app.UIAxes.CameraTarget - app.UIAxes.CameraPosition);
                    u = normr(app.UIAxes.CameraUpVector);
                    r = normr(cross(v, u));
                    app.trackball.R0 = [r; u; -v]';
                    
                    app.trackball.campos0 = campos(app.UIAxes);
                    app.trackball.camup0 = camup(app.UIAxes);
                    
                elseif app.TabGroup3.SelectedTab == app.RegionOfInterestTab
                    v = normr(app.UIAxes3.CameraTarget - app.UIAxes3.CameraPosition);
                    u = normr(app.UIAxes3.CameraUpVector);
                    
                    r = normr(cross(v, u));
                    app.trackball.R0 = [r; u; -v]';
                    
                    app.trackball.campos0 = campos(app.UIAxes3);
                    app.trackball.camup0 = camup(app.UIAxes3);
                end
                                          
            end
        end

        % Callback function
        function rotatingROIButtonValueChanged(app, event)
            
            app.deleteROI.Enable = false;
            app.DefineButton.Enable = true;
           
            if app.rotatingROIButton.Value
                if app.brush.update
                    delete(app.brush.ph); delete(app.brush.ph2);
                end

                app.fixedROIButton.Value = false;
                
                app.brush.col = app.plot_settings.rotcolor;
                
                if app.rot.ROI.available
                    app.brush.MSmesh_sel = app.rot.ROI.MSmesh_sel;
                    app.brush.MSmesh_notsel = app.rot.ROI.MSmesh_notsel;
                    
                    app.deleteROI.Enable = true;
                    
                    [app.brush.ph, app.brush.ph2] = plotROI(app, app.UIAxes3, app.brush.MSmesh_sel, app.brush.MSmesh_notsel, app.brush.col, [1 1 1], app.RotTransparentButton.Value);
                end
                
            else
                if app.brush.update
                    delete(app.brush.ph); delete(app.brush.ph2);
                end
                
                app.fixedROIButton.Value=true;
                
                app.rotatingROIButton.Value = false;
                
                app.brush.col = app.plot_settings.fixcolor;
                
                if app.fix.ROI.available
                    app.brush.MSmesh_sel = app.fix.ROI.MSmesh_sel;
                    app.brush.MSmesh_notsel = app.fix.ROI.MSmesh_notsel;
                    
                    app.deleteROI.Enable = true;
                    
                    [app.brush.ph, app.brush.ph2] = plotROI(app, app.UIAxes3, app.brush.MSmesh_sel, app.brush.MSmesh_notsel, app.brush.col, [1 1 1], app.FixTransparentButton.Value);
                end
                
            end
            
            
        end

        % Callback function
        function fixedROIButtonValueChanged(app, event)
            
            app.DefineButton.Enable = true;
            app.deleteROI.Enable = false;
          
            
            if app.fixedROIButton.Value
                app.brush.MS = app.fix.MS;
                app.rotatingROIButton.Value = false;
                app.brush.col = app.plot_settings.fixcolor;
                
                
                if app.brush.update
                    delete(app.brush.ph);delete(app.brush.ph2);
                end
                
                if app.fix.ROI.available
                    app.brush.MSmesh_sel = app.fix.ROI.MSmesh_sel;
                    app.brush.MSmesh_notsel = app.fix.ROI.MSmesh_notsel;
                    
                    app.deleteROI.Enable = true;
                    
                    [app.brush.ph, app.brush.ph2] = plotROI(app, app.UIAxes3, app.brush.MSmesh_sel, app.brush.MSmesh_notsel, app.brush.col, [1 1 1], app.FixTransparentButton.Value);
                end
            else
                app.rotatingROIButton.Value=true;
                
                app.brush.MS = app.rot.MS;
                app.fixedROIButton.Value = false;
                app.brush.col = app.plot_settings.rotcolor;
                
                if app.brush.update
                    delete(app.brush.ph);delete(app.brush.ph2);
                end
                
                if app.rot.ROI.available
                    app.brush.MSmesh_sel = app.rot.ROI.MSmesh_sel;
                    app.brush.MSmesh_notsel = app.rot.ROI.MSmesh_notsel;
                    
                    app.deleteROI.Enable = true;
                    
                    [app.brush.ph, app.brush.ph2] = plotROI(app, app.UIAxes3, app.brush.MSmesh_sel, app.brush.MSmesh_notsel, app.brush.col, [1 1 1], app.RotTransparentButton.Value);
                end
            end
            
        end

        % Button pushed function: DefineButton
        function JSmolDefineButtonPushed(app, event)
            
             selectedButton = app.ROIselButton.SelectedObject;
             app.deleteROI.Enable = false;
            
            if strcmp(selectedButton.Text, 'ROTATING STRUCTURE')
                
                structureSel = 'rotating';
                
                color = round(app.plot_settings.rotcolor*255);
                
            elseif strcmp(selectedButton.Text, 'FIXED STRUCTURE')
                
                structureSel = 'fixed';
                
                color = round(app.plot_settings.fixcolor*255);
            end
            
            pdbModel = updatePDBdata(app, structureSel);
            
            % create JSON 
            pdbJSmolJSON = mat2JSON(pdbModel);
            
            % convert JSON to Matlab struct 
            pdbJSmolMatStruct = jsondecode(pdbJSmolJSON);
            
            scriptStr = sprintf(['set antialiasDisplay ON; set picking select group; set pickingstyle select drag; selectionHalos ON; select all; cartoon off; cpk 20%%; wireframe on;', ...
                    'background {0.94, 0.94, 0.94};', ...
                    'color [%d %d %d]'], ...
                    color(1), color(2), color(3));

            app.dialogs.JSmolSelection = JSmolSelectionDialog(app, pdbJSmolMatStruct, app.paths.JSmol, scriptStr, structureSel);
         
        end

        % Button pushed function: deleteROI
        function deleteROIButtonPushed(app, event)
            
            selectedButton = app.ROIselButton.SelectedObject;
            
            if strcmp(selectedButton.Text, 'FIXED STRUCTURE') % Fixed structure selected
                
                if app.fix.ROI.available
                    app.fix.ROI.MS_sel = [];
                    app.fix.ROI.MS_notsel = [];
                    app.fix.ROI.MSmesh_sel = [];
                    app.fix.ROI.MSmesh_notsel = [];
                    
                    app.fix.ROI.available = false;
                    app.deleteROI.Enable = false;
                    
                    app.fixROICheckBox.Enable = false;
                    app.fixROICheckBox.Value = false;
                    
                    app.viewROIdefinedPanel.Title = 'No ROI defined';
                    
                end
            else % Rotating structure selected
                
                if app.rot.ROI.available
                    app.rot.ROI.MS_sel = [];
                    app.rot.ROI.MS_notsel = [];
                    app.rot.ROI.MSmesh_sel = [];
                    app.rot.ROI.MSmesh_notsel = [];
                    
                    app.rot.ROI.available = false;
                    app.rotROICheckBox.Value = false;
                    app.deleteROI.Enable = false;
                    
                    app.rotROICheckBox.Enable = false;
                    app.rotROICheckBox.Value = false;
                    
                    app.viewROIdefinedPanel.Title = 'No ROI defined';
                    
                end
            end
            
            if app.brush.update
                delete(app.brush.ph); %delete(app.brush.ph2);
            end
                        
            app.brush.MS_sel = [];
            app.brush.MS_notsel = [];
            
        end

        % Value changed function: fixROICheckBox
        function fixROICheckBoxEnabled(app, event)
            
            % replot entire scene
            app.plot_settings.updatefix = true;
            plotAlignment(app);
            
            if ~app.fixROICheckBox.Value
                
                updateFixShape(app);
                [app.fix.ZC.mom_nlm_list ,app.fix.ZC.mom_list, app.fix.ZC.mom] = getZCmomFromShape(app.fix.MS, app.ZC_settings.order, app.ZC_settings.grid_res, app.ZC_settings.scale_option, app.alignment.chi_coeff_cell, app.alignment.chi_nlm_rst_cell);
                
                items = app.StructureDropDown.Items;
                
                itemFun=@(x) (strcmp(x,'ROI Fixed'));
                
                itemDeleteTF = cellfun(itemFun,items,'UniformOutput',false);
                
                items(cell2mat(itemDeleteTF)) = [];
                
                app.StructureDropDown.Items = items;
                
            else
                updateFixShape_ROI(app);
                [app.fix.ZC.mom_nlm_list ,app.fix.ZC.mom_list, app.fix.ZC.mom] = getZCmomFromShape(app.fix.ROI.MS_sel, app.ZC_settings.order, app.ZC_settings.grid_res, app.ZC_settings.scale_option, app.alignment.chi_coeff_cell, app.alignment.chi_nlm_rst_cell);
                
                items = app.StructureDropDown.Items;
                app.StructureDropDown.Items = [items {'ROI Fixed'}];
                
            end
            
            if ~app.rotROICheckBox.Value && ~app.fixROICheckBox.Value
                app.JSmolROIswitch.Enable = false;
            else
                app.JSmolROIswitch.Enable = true;
            end
            
            app.alignment.zcCC = computeCC(app);
            updateResultLabel(app);            
            
        end

        % Value changed function: rotROICheckBox
        function rotROICheckBoxValueChanged(app, event)
            
            % replot entire scene
            plotAlignment(app);
            
            if ~app.rotROICheckBox.Value
                
                updateRotShape(app,eye(3));
                [app.rot.ZC.mom_nlm_list ,app.rot.ZC.mom_list, app.rot.ZC.mom] = getZCmomFromShape(app.rot.MS, app.ZC_settings.order, app.ZC_settings.grid_res, app.ZC_settings.scale_option, app.alignment.chi_coeff_cell, app.alignment.chi_nlm_rst_cell);
                
                items = app.StructureDropDown.Items;
                
                itemFun=@(x) (strcmp(x,'ROI Rotating'));
                
                itemDeleteTF = cellfun(itemFun,items,'UniformOutput',false);
                
                items(cell2mat(itemDeleteTF)) = [];
                
                app.StructureDropDown.Items = items;
                
            else
                updateRotShape_ROI(app,eye(3));
                [app.rot.ZC.mom_nlm_list ,app.rot.ZC.mom_list, app.rot.ZC.mom] = getZCmomFromShape(app.rot.ROI.MS_sel, app.ZC_settings.order, app.ZC_settings.grid_res, app.ZC_settings.scale_option, app.alignment.chi_coeff_cell, app.alignment.chi_nlm_rst_cell);
                
                items = app.StructureDropDown.Items;
                app.StructureDropDown.Items = [items {'ROI Rotating'}];
                
            end
            
            if ~app.rotROICheckBox.Value && ~app.fixROICheckBox.Value
                app.JSmolROIswitch.Enable = false;
            else
                app.JSmolROIswitch.Enable = true;
            end
            
            [app.alignment.zcCC] = computeCC(app);
            updateResultLabel(app);


        end

        % Value changed function: ShapeRepDropDown
        function ShapeRepDropDownValueChanged(app, event)
            value = app.ShapeRepDropDown.Value;
            
            app.shape_settings.shape_rep_type = value;
            
            switch value
                case 1 % mol surface
                    
                    app.molSurfDropDown.Visible = true;
                    app.probeRadiusSpinner.Visible = true;
                    app.ProberadiusSpinnerLabel.Visible = true;
                    app.shellThicknessLabel.Visible = true;
                    app.shellThicknessSpinner.Visible = true;
                    
                    app.SmearfactorEditField.Visible = false;
                    app.SmearfactorEditFieldLabel.Visible = false;
                    app.RelativeisovalueEditField.Visible = false;
                    app.RelativeisovalueEditFieldLabel.Visible = false;
                               
                case 2 % density
                    
                    app.molSurfDropDown.Visible = false;
                    app.probeRadiusSpinner.Visible = false;
                    app.ProberadiusSpinnerLabel.Visible = false;
                    app.shellThicknessLabel.Visible = false;
                    app.shellThicknessSpinner.Visible = false;
                    
                    app.SmearfactorEditField.Visible = true;
                    app.SmearfactorEditFieldLabel.Visible = true;
                    app.RelativeisovalueEditField.Visible = true;
                    app.RelativeisovalueEditFieldLabel.Visible = true;

            end
            
            
            if app.fix.loaded && app.rot.loaded
                updateAllShapesAndZCcc(app);
            end
            
            
        end

        % Button pushed function: jmolAlignment
        function JSmolAlignmentButtonPushed(app, event)
            
            % NEW SOLUTION - load structure by sending the structure as a
            % JSON to the webpage and have JSmol load the JS variable
            % html file: jsmolWebGL_matlabDataTransfer
            
            % Set colors
            app.JSmol.fixStructure.color = round(app.plot_settings.fixcolor*255);
            app.JSmol.rotStructure.color = round(app.plot_settings.rotcolor*255);
                        
            % update coordinates in original pdb data
            if app.rotROICheckBox.Value
                rotModel = updatePDBdata_ROI(app, 'rotating');
                atomSelIDs = app.rot.ROI.selectedIDs;
                
                rotJSmolSelStr ='';
                for i = 1:numel(atomSelIDs)-1
                    
                    i_str = sprintf('%d,',atomSelIDs(i));
                    rotJSmolSelStr = [rotJSmolSelStr i_str]; 
                    
                end
                
                rotJSmolSelStr = [rotJSmolSelStr num2str(atomSelIDs(i+1))]; 
                
                rotJSmolSelStr = sprintf('select atomno=[%s] and model=1.2', rotJSmolSelStr);
                rotColnonROI = [208 227 242];
            else
                rotModel = updatePDBdata(app, 'rotating');
                rotColnonROI = app.JSmol.rotStructure.color;
                rotJSmolSelStr = 'select 1.2';
            end
            
            if app.fixROICheckBox.Value
                fixModel = updatePDBdata_ROI(app, 'fixed');
                atomSelIDs = app.fix.ROI.selectedIDs;
                
                fixJSmolSelStr ='';
                for i = 1:numel(atomSelIDs)-1
                    
                    i_str = sprintf('%d,',atomSelIDs(i));
                    fixJSmolSelStr = [fixJSmolSelStr i_str]; 
                    
                end
                
                fixJSmolSelStr = [fixJSmolSelStr num2str(atomSelIDs(i+1))]; 
                
                fixJSmolSelStr = sprintf('select atomno=[%s] and model=1.1', fixJSmolSelStr);
                
                fixColnonROI = [240 207 192];
                
            else
                fixModel = updatePDBdata(app, 'fixed');
                fixColnonROI = app.JSmol.fixStructure.color;
                fixJSmolSelStr = 'select 1.1';
            end
            
            if strcmp(app.JSmolROIswitch.Value, 'ROI')
                fixJSmolSelStr = 'select 1.1';
                rotJSmolSelStr = 'select 1.2';
            end
            
            % create JSON 
            pdbJSmolJSON = mat2JSONalignment(fixModel, rotModel);
            
            % convert JSON to Matlab struct 
            pdbJSmolMatStruct = jsondecode(pdbJSmolJSON);
                        
            % TO DO: the first JSON conversion is unnecessary (but the struct is converted to JSON when Matlab transfer the data to the html page)
            
            
            % Set commands to JSmol
            scriptStr = sprintf(['set antialiasDisplay ON; set antialiasTranslucent ON; set translucent %s;', ...
                    'background {0.94, 0.94, 0.94}; select 1.1, 1.2; cartoon ON; cpk off; wireframe off; frame all; select 1.1;', ...
                    'color [%d %d %d]; select 1.2; color [%d %d %d];', ...
                    '%s; color [%d %d %d]; %s; color [%d %d %d];'], ...
                    app.JSmol.trueTrans, ...
                    fixColnonROI(1), fixColnonROI(2), fixColnonROI(3), ...
                    rotColnonROI(1), rotColnonROI(2), rotColnonROI(3), ...
                    fixJSmolSelStr, app.JSmol.fixStructure.color(1), app.JSmol.fixStructure.color(2), app.JSmol.fixStructure.color(3), ...
                    rotJSmolSelStr, app.JSmol.rotStructure.color(1), app.JSmol.rotStructure.color(2), app.JSmol.rotStructure.color(3));
            
            % Start JSmol  
            app.dialogs.JSmolAlignment = JSmolViewer_alignment(app, pdbJSmolMatStruct, app.paths.JSmol, app.JSmol.webGL, app.JSmol.fixStructure, app.JSmol.rotStructure, scriptStr);

            fprintf('\n %s\n', scriptStr);
         
        end

        % Value changed function: SmearfactorEditField
        function SmearfactorEditFieldValueChanged(app, event)
            
            app.shape_settings.smear_factor = app.SmearfactorEditField.Value;
            updateAllShapesAndZCcc(app);
            
        end

        % Value changed function: RelativeisovalueEditField
        function RelativeisovalueEditFieldValueChanged(app, event)
            app.shape_settings.rel_iso = app.RelativeisovalueEditField.Value;
            updateAllShapesAndZCcc(app);
        end

        % Value changed function: manualSearchOp
        function manualSearchOpValueChanged(app, event)
            app.manualSearchOp.Value = true;
            app.RunSearchButton.Text = 'Manual search';
            app.solverSearchOp.Value = false;
            app.randOp.Value = false;
                      
            app.alignSettingsButton.Enable = false;
        end

        % Value changed function: hideFix, hideRot
        function hideValueChanged(app, event)
            plotAlignment(app);
            
        end

        % Value changed function: molSurfDropDown
        function molSurfDropDownValueChanged(app, event)
            
            switch app.molSurfDropDown.Value
                case '1'
                    app.shape_settings.surf_type = 'MS';
                case '2'
                    app.shape_settings.surf_type = 'SA';
            end
            
            updateAllShapesAndZCcc(app);
            
        end

        % Value changed function: solverSearchOp
        function globalSearchOpValueChanged(app, event)
            
        app.alignSettingsButton.Enable = true;
            app.solverSearchOp.Value = true;
            app.manualSearchOp.Value = false;
          
            app.randOp.Value = false;
          
            app.RunSearchButton.Text = 'Global search';
            
            
        end

        % Value changed function: randOp
        function randOpValueChanged(app, event)
            
            app.solverSearchOp.Value = false;
            app.manualSearchOp.Value = false;
            app.randOp.Value = true;
            
            app.RunSearchButton.Text = 'Randomize';
                       
            app.alignSettingsButton.Enable = true;
        end

        % Close request function: ZEALUIFigure
        function ZEALUIFigureCloseRequest(app, event)
            
            if isfield(app.dialogs,'surropt')
                delete(app.dialogs.surropt);
            end
            
            if isfield(app.dialogs,'manual')
                delete(app.dialogs.manual);
            end
            
            if isfield(app.dialogs,'JSmolSelection')
                delete(app.dialogs.JSmolSelection);
            end
            
            if isfield(app.dialogs,'JSmolAlignment')
                delete(app.dialogs.JSmolAlignment);
            end
            
            if isfield(app.dialogs,'JSmolViewerFix')
                delete(app.dialogs.JSmolViewerFix);
            end
            
            if isfield(app.dialogs,'JSmolViewerRot')
                delete(app.dialogs.JSmolViewerRot);
            end
            
            if isfield(app.dialogs,'surrOptSettings')
                delete(app.dialogs.surrOptSettings);
            end
            
            if isfield(app.dialogs,'randOpSettings')
                delete(app.dialogs.randOpSettings);
            end
            
           
            delete(app);
            
        end

        % Window button motion function: ZEALUIFigure
        function ZEALUIFigureWindowButtonMotion(app, event)
            
            if app.plot_settings.update_camlight_op
                camlight(app.plot_settings.ch,'left');
                camlight(app.brush.ch,'left');
            end
            
            
            if app.trackball.move
                
                currPoint = app.ZEALUIFigure.CurrentPoint;
                
                xy = [currPoint(1,1) currPoint(1,2)]-app.trackball.center;
                
                xyz = getPointOnTrackball(app, xy(1), xy(2), app.trackball.radius);
                                                            
                R = getTrackballRotmat(app, xyz);                               
                
                if app.trackball.scene
                    
                    if app.TabGroup3.SelectedTab == app.ViewshapesTab
                        
                        campos(app.UIAxes, R(1:3,1:3) * app.trackball.campos0');
                        camup(app.UIAxes, R(1:3,1:3) * app.trackball.camup0');
                        
                    elseif app.TabGroup3.SelectedTab == app.RegionOfInterestTab
                        
                        campos(app.UIAxes3, R(1:3,1:3) * app.trackball.campos0');
                        camup(app.UIAxes3, R(1:3,1:3) * app.trackball.camup0');
                        
                    end
                    
                else
                    
                    app.alignment.R = R(1:3,1:3)' * app.alignment.R;
                    
                    Rmat = real(app.alignment.R);
                    
                    if ~app.rotROICheckBox.Value
                        rotateAndUpdateRotMesh(app, Rmat);
                    else
                        rotateAndUpdateRotMesh_ROI(app, Rmat);                    
                    end
                    
                    % update R0 (rotation from eye to world coordinates)
                    currPoint = app.ZEALUIFigure.CurrentPoint;
                    xy = [currPoint(1,1) currPoint(1,2)] - app.trackball.center;
                    
                    app.trackball.xyz0 = getPointOnTrackball(app, xy(1), xy(2), app.trackball.radius);

                    v = normr(app.UIAxes.CameraTarget - app.UIAxes.CameraPosition);
                    u = normr(app.UIAxes.CameraUpVector);
                    r = normr(cross(v, u));
                    app.trackball.R0 = [r; u; -v]';
                    
                end
            end
            

        end

        % Window button up function: ZEALUIFigure
        function ZEALUIFigureWindowButtonUp(app, event)
            
            if app.trackball.active                                
                app.trackball.move = false;
               
                if app.trackball.manualAlignment
                    app.Label_momCCResults.Text = '[click evaluate button]';
                end
                
            end
                        
        end

        % Button pushed function: alignSettingsButton
        function alignSettingsButtonPushed(app, event)
            
            if app.solverSearchOp.Value
                app.dialogs.surrOptSettings = globalOptSettingsDialog(app);
            elseif app.randOp.Value
                app.dialogs.randOpSettings = randOpSettingsDialog(app);
            end
            
        end

        % Value changed function: shellThicknessSpinner
        function shellThicknessSpinnerValueChanged(app, event)
            app.shape_settings.shell_thickness = app.shellThicknessSpinner.Value;
            
            updateAllShapesAndZCcc(app);
            
        end

        % Button pushed function: graphicsSettings
        function graphicsSettingsPushed(app, event)
            graphicsSettingsDialog(app);
        end

        % Value changed function: fixSwitch
        function fixSwitchValueChanged(app, event)
            loadmode = app.fixSwitch.Value;
            
            resetFix(app);
            
            
            if strcmp(loadmode,'LOAD')
                app.fixPDBIDEditField.Visible = false;
                app.fixPDBIDEditFieldLabel.Visible = false;
                app.fix_pdbfile.Visible = true;
                app.fixFileNameLabel.Visible = true;
                
                app.importFix.Tooltip = 'Load pdb file for fixed structure.';
            else
                app.fix_pdbfile.Visible = false;
                app.fixFileNameLabel.Visible = false;
                
                app.fixPDBIDEditField.Visible = true;
                app.fixPDBIDEditFieldLabel.Visible = true;
                
                app.importFix.Tooltip = 'Download fixed structure from RCSB PDB.';
                
            end
        end

        % Value changed function: rotSwitch
        function rotSwitchValueChanged(app, event)
            loadmode = app.rotSwitch.Value;
            
            resetRot(app);
            
            if strcmp(loadmode,'LOAD')
                app.rotPDBIDEditField.Visible = false;
                app.rotPDBIDEditFieldLabel.Visible = false;
                
                app.rot_pdbfile.Visible = true;
                app.rotFileNameLabel.Visible = true;
                
                app.importRot.Tooltip = 'Load pdb file for rotating structure.';
            else
                app.rot_pdbfile.Visible = false;
                app.rotFileNameLabel.Visible = false;
                
                app.rotPDBIDEditField.Visible = true;
                app.rotPDBIDEditFieldLabel.Visible = true;
                
                app.importRot.Tooltip = 'Download rotating structure from RCSB PDB.';
                
            end
        end

        % Selection changed function: ROIselButton
        function ROIselButtonSelectionChanged(app, event)

            app.deleteROI.Enable = false;
            
            selectedButton = app.ROIselButton.SelectedObject;
            
            if strcmp(selectedButton.Text, 'FIXED STRUCTURE')
                
                app.brush.MS = app.fix.MS;                
                app.brush.col = app.plot_settings.fixcolor;
                                
                if app.brush.update
                    %delete(app.brush.ph);delete(app.brush.ph2);
                    delete(app.brush.ph);
                end
                
                if app.fix.ROI.available
                    app.brush.MSmesh_sel = app.fix.ROI.MSmesh_sel;
                    app.brush.MSmesh_notsel = app.fix.ROI.MSmesh_notsel;
                    
                    app.deleteROI.Enable = true;
                    app.viewROIdefinedPanel.Title = '';
                    
                    %[app.brush.ph, app.brush.ph2] = plotROI(app, app.UIAxes3, app.brush.MSmesh_sel, app.brush.MSmesh_notsel, app.brush.col, [1 1 1], app.FixTransparentButton.Value);
                    [app.brush.ph] = plotROI(app, app.UIAxes3, app.brush.MSmesh_sel, app.brush.col, app.FixTransparentButton.Value);
                end
                
            else
                
                if app.brush.update
                    %delete(app.brush.ph); delete(app.brush.ph2);
                    delete(app.brush.ph);
                end                
               
                app.brush.col = app.plot_settings.rotcolor;
                
                if app.rot.ROI.available
                    app.brush.MSmesh_sel = app.rot.ROI.MSmesh_sel;
                    app.brush.MSmesh_notsel = app.rot.ROI.MSmesh_notsel;
                    
                    app.deleteROI.Enable = true;
                    app.viewROIdefinedPanel.Title = '';
                    
                    %[app.brush.ph, app.brush.ph2] = plotROI(app, app.UIAxes3, app.brush.MSmesh_sel, app.brush.MSmesh_notsel, app.brush.col, [1 1 1], app.RotTransparentButton.Value);
                    [app.brush.ph] = plotROI(app, app.UIAxes3, app.brush.MSmesh_sel, app.brush.col, app.RotTransparentButton.Value);

                end
                
            end

        end

        % Image clicked function: ZEALlogo
        function ZEALlogoImageClicked(app, event)
            web('https://andre-lab.github.io/ZEAL/','-new','-notoolbar');
        end

        % Image clicked function: GitHubLogo
        function GitHubLogoClicked(app, event)
            web('https://github.com/Andre-lab/ZEAL','-new','-notoolbar');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ZEALUIFigure = uifigure('Visible', 'off');
            app.ZEALUIFigure.Name = 'ZEAL';
            app.ZEALUIFigure.CloseRequestFcn = createCallbackFcn(app, @ZEALUIFigureCloseRequest, true);
            app.ZEALUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.ZEALUIFigure.WindowButtonDownFcn = createCallbackFcn(app, @ZEALUIFigureWindowButtonDown, true);
            app.ZEALUIFigure.WindowButtonUpFcn = createCallbackFcn(app, @ZEALUIFigureWindowButtonUp, true);
            app.ZEALUIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @ZEALUIFigureWindowButtonMotion, true);
            app.GridLayout = uigridlayout(app.ZEALUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.LeftPanel);
            app.ZEALlogo = uiimage(app.GridLayout4);
            app.ZEALlogo.ImageClickedFcn = createCallbackFcn(app, @ZEALlogoImageClicked, true);
            app.ZEALlogo.Tooltip = {'View ZEAL documentation'};
            app.ZEALlogo.ImageSource = 'zealLogo.png';
            app.TabGroup = uitabgroup(app.GridLayout4);
            app.ImportTab = uitab(app.TabGroup);
            app.ImportTab.Title = 'Import';
            app.GridLayout5 = uigridlayout(app.ImportTab);
            app.FIXEDSTRUCTUREPanel = uipanel(app.GridLayout5);
            app.FIXEDSTRUCTUREPanel.Title = 'FIXED STRUCTURE';
            app.importFixPanel = uipanel(app.FIXEDSTRUCTUREPanel);
            app.importFix = uibutton(app.importFixPanel, 'push');
            app.importFix.ButtonPushedFcn = createCallbackFcn(app, @LoadpdbButton_fixPushed, true);
            app.importFix.Icon = 'import2_100px.png';
            app.importFix.Tooltip = {'Load pdb file for fixed structure.'};
            app.importFix.Text = 'Import';
            app.fixPDBIDEditFieldLabel = uilabel(app.importFixPanel);
            app.fixPDBIDEditFieldLabel.Text = 'PDB ID';
            app.fixPDBIDEditField = uieditfield(app.importFixPanel, 'text');
            app.fixPDBIDEditField.Tooltip = {''};
            app.fix_pdbfile = uitextarea(app.importFixPanel);
            app.fix_pdbfile.Enable = 'off';
            app.fix_pdbfile.Visible = 'off';
            app.fix_pdbfile.Value = {'no structure loaded'};
            app.CHAINIDEditFieldLabel = uilabel(app.importFixPanel);
            app.CHAINIDEditFieldLabel.Text = 'CHAIN';
            app.fixCHAINIDEditField = uieditfield(app.importFixPanel, 'text');
            app.fixCHAINIDEditField.Tooltip = {'Enter the chain ID to be imported. All chains will be imported if the field is empty.'};
            app.fixFileNameLabel = uilabel(app.importFixPanel);
            app.fixFileNameLabel.Visible = 'off';
            app.fixFileNameLabel.Text = 'FILENAME';
            app.fixSwitch = uiswitch(app.importFixPanel, 'slider');
            app.fixSwitch.Items = {'LOAD', 'FETCH'};
            app.fixSwitch.ValueChangedFcn = createCallbackFcn(app, @fixSwitchValueChanged, true);
            app.fixSwitch.Tooltip = {''};
            app.fixSwitch.Value = 'FETCH';
            app.ROTATINGSTRUCTUREPanel = uipanel(app.GridLayout5);
            app.ROTATINGSTRUCTUREPanel.Title = 'ROTATING STRUCTURE';
            app.importRotPanel = uipanel(app.ROTATINGSTRUCTUREPanel);
            app.rotPDBIDEditFieldLabel = uilabel(app.importRotPanel);
            app.rotPDBIDEditFieldLabel.Text = 'PDB ID';
            app.rotPDBIDEditField = uieditfield(app.importRotPanel, 'text');
            app.rotPDBIDEditField.Tooltip = {''};
            app.importRot = uibutton(app.importRotPanel, 'push');
            app.importRot.ButtonPushedFcn = createCallbackFcn(app, @LoadpdbButton_rotPushed, true);
            app.importRot.Icon = 'import2_100px.png';
            app.importRot.Tooltip = {'Load pdb file for rotating structure.'};
            app.importRot.Text = 'Import';
            app.rotCHAINIDEditFieldLabel = uilabel(app.importRotPanel);
            app.rotCHAINIDEditFieldLabel.Text = 'CHAIN';
            app.rotCHAINIDEditField = uieditfield(app.importRotPanel, 'text');
            app.rotCHAINIDEditField.Tooltip = {'Enter the chain ID to be imported. All chains will be imported if the field is empty.'};
            app.rot_pdbfile = uitextarea(app.importRotPanel);
            app.rot_pdbfile.Enable = 'off';
            app.rot_pdbfile.Visible = 'off';
            app.rot_pdbfile.Value = {'no structure loaded'};
            app.rotFileNameLabel = uilabel(app.importRotPanel);
            app.rotFileNameLabel.Visible = 'off';
            app.rotFileNameLabel.Text = 'FILENAME';
            app.rotSwitch = uiswitch(app.importRotPanel, 'slider');
            app.rotSwitch.Items = {'LOAD', 'FETCH'};
            app.rotSwitch.ValueChangedFcn = createCallbackFcn(app, @rotSwitchValueChanged, true);
            app.rotSwitch.Tooltip = {''};
            app.rotSwitch.Value = 'FETCH';
            app.AlignTab = uitab(app.TabGroup);
            app.AlignTab.Title = 'Align';
            app.HistoryGroup = uibuttongroup(app.AlignTab);
            app.HistoryGroup.SelectionChangedFcn = createCallbackFcn(app, @HistoryGroupSelectionChanged, true);
            app.HistoryGroup.Title = 'HISTORY';
            app.history_original = uitogglebutton(app.HistoryGroup);
            app.history_original.Enable = 'off';
            app.history_original.Tooltip = {'Show original shape alignment'};
            app.history_original.Icon = 'images/icons/skip_to_start_100px.png';
            app.history_original.Text = '';
            app.history_original.Value = true;
            app.history_manual = uitogglebutton(app.HistoryGroup);
            app.history_manual.Enable = 'off';
            app.history_manual.Tooltip = {'Show shape alignment after manual search'};
            app.history_manual.Icon = 'images/icons/gyro_100px.png';
            app.history_manual.Text = '';
            app.history_surropt = uitogglebutton(app.HistoryGroup);
            app.history_surropt.Enable = 'off';
            app.history_surropt.Tooltip = {'Show shape alignment after global search'};
            app.history_surropt.Icon = 'images/icons/the_flash_100px.png';
            app.history_surropt.Text = '';
            app.MODEPanel = uipanel(app.AlignTab);
            app.MODEPanel.Title = 'MODE';
            app.RunSearchButton = uibutton(app.MODEPanel, 'push');
            app.RunSearchButton.ButtonPushedFcn = createCallbackFcn(app, @RunSearchButtonPushed, true);
            app.RunSearchButton.Icon = 'next_96px.png';
            app.RunSearchButton.Enable = 'off';
            app.RunSearchButton.Text = '';
            app.manualSearchOp = uibutton(app.MODEPanel, 'state');
            app.manualSearchOp.ValueChangedFcn = createCallbackFcn(app, @manualSearchOpValueChanged, true);
            app.manualSearchOp.Enable = 'off';
            app.manualSearchOp.Tooltip = {'Manually align shapes. Click Play button to start.'};
            app.manualSearchOp.Icon = 'gyro_100px.png';
            app.manualSearchOp.Text = '';
            app.solverSearchOp = uibutton(app.MODEPanel, 'state');
            app.solverSearchOp.ValueChangedFcn = createCallbackFcn(app, @globalSearchOpValueChanged, true);
            app.solverSearchOp.Enable = 'off';
            app.solverSearchOp.Tooltip = {'Perform shape alignment. Click Play button to run.'};
            app.solverSearchOp.Icon = 'the_flash_100px.png';
            app.solverSearchOp.Text = '';
            app.solverSearchOp.Value = true;
            app.randOp = uibutton(app.MODEPanel, 'state');
            app.randOp.ValueChangedFcn = createCallbackFcn(app, @randOpValueChanged, true);
            app.randOp.Enable = 'off';
            app.randOp.Tooltip = {'Randomly rotate a shape. Click Play button to apply. '};
            app.randOp.Icon = 'dice_filled_60px.png';
            app.randOp.Text = '';
            app.alignSettingsButton = uibutton(app.MODEPanel, 'push');
            app.alignSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @alignSettingsButtonPushed, true);
            app.alignSettingsButton.Icon = 'settings_100px.png';
            app.alignSettingsButton.Enable = 'off';
            app.alignSettingsButton.Text = 'Settings';
            app.VisTab = uitab(app.TabGroup);
            app.VisTab.Title = 'Vis.';
            app.graphicsSettings = uibutton(app.VisTab, 'push');
            app.graphicsSettings.ButtonPushedFcn = createCallbackFcn(app, @graphicsSettingsPushed, true);
            app.graphicsSettings.Icon = 'settings_100px.png';
            app.graphicsSettings.Enable = 'off';
            app.graphicsSettings.Tooltip = {'Change graphics settings'; ''};
            app.graphicsSettings.Text = 'Settings';
            app.FixedstructurePanel = uipanel(app.VisTab);
            app.FixedstructurePanel.Title = 'Fixed structure';
            app.FixColorButton = uibutton(app.FixedstructurePanel, 'push');
            app.FixColorButton.ButtonPushedFcn = createCallbackFcn(app, @FixColorButtonPushed, true);
            app.FixColorButton.Icon = 'paint_palette_100px.png';
            app.FixColorButton.Enable = 'off';
            app.FixColorButton.Tooltip = {'select color'};
            app.FixColorButton.Text = '';
            app.hideFix = uibutton(app.FixedstructurePanel, 'state');
            app.hideFix.ValueChangedFcn = createCallbackFcn(app, @hideValueChanged, true);
            app.hideFix.Enable = 'off';
            app.hideFix.Tooltip = {'hide'};
            app.hideFix.Icon = 'hide_100px.png';
            app.hideFix.Text = '';
            app.FixTransparentButton = uibutton(app.FixedstructurePanel, 'state');
            app.FixTransparentButton.ValueChangedFcn = createCallbackFcn(app, @FixTransparentButtonValueChanged, true);
            app.FixTransparentButton.Enable = 'off';
            app.FixTransparentButton.Tooltip = {'set transparent surface'};
            app.FixTransparentButton.Icon = 'opacity_100px.png';
            app.FixTransparentButton.Text = '';
            app.RotatingstructurePanel = uipanel(app.VisTab);
            app.RotatingstructurePanel.Title = 'Rotating structure';
            app.RotColorButton = uibutton(app.RotatingstructurePanel, 'push');
            app.RotColorButton.ButtonPushedFcn = createCallbackFcn(app, @RotColorButtonPushed, true);
            app.RotColorButton.Icon = 'paint_palette_100px.png';
            app.RotColorButton.Enable = 'off';
            app.RotColorButton.Tooltip = {'select color'};
            app.RotColorButton.Text = '';
            app.hideRot = uibutton(app.RotatingstructurePanel, 'state');
            app.hideRot.ValueChangedFcn = createCallbackFcn(app, @hideValueChanged, true);
            app.hideRot.Enable = 'off';
            app.hideRot.Tooltip = {'hide'; ''};
            app.hideRot.Icon = 'hide_100px.png';
            app.hideRot.Text = '';
            app.RotTransparentButton = uibutton(app.RotatingstructurePanel, 'state');
            app.RotTransparentButton.ValueChangedFcn = createCallbackFcn(app, @RotTransparentButtonValueChanged, true);
            app.RotTransparentButton.Enable = 'off';
            app.RotTransparentButton.Tooltip = {'set transparent surface'};
            app.RotTransparentButton.Icon = 'opacity_100px.png';
            app.RotTransparentButton.Text = '';
            app.Panel_4 = uipanel(app.VisTab);
            app.jmolAlignment = uibutton(app.Panel_4, 'push');
            app.jmolAlignment.ButtonPushedFcn = createCallbackFcn(app, @JSmolAlignmentButtonPushed, true);
            app.jmolAlignment.Icon = 'JSmol_logo.png';
            app.jmolAlignment.Enable = 'off';
            app.jmolAlignment.Tooltip = {'Show aligned structures in the molecular viewer JSmol.'};
            app.jmolAlignment.Text = '';
            app.Label = uilabel(app.Panel_4);
            app.Label.Text = {'an open-source HTML5 viewer'; 'for chemical structures in 3D.'; 'http://wiki.jmol.org/index.php/JSmol'};
            app.JSmolROIswitch = uiswitch(app.Panel_4, 'slider');
            app.JSmolROIswitch.Items = {'ALL', 'ROI'};
            app.JSmolROIswitch.Enable = 'off';
            app.JSmolROIswitch.Value = 'ALL';
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.STRUCTUREPanel = uipanel(app.ExportTab);
            app.STRUCTUREPanel.Title = 'STRUCTURE';
            app.StructureDropDown = uidropdown(app.STRUCTUREPanel);
            app.StructureDropDown.Items = {'Rotating', 'Fixed', 'All'};
            app.StructureDropDown.Value = 'Rotating';
            app.INCLUDEPanel = uipanel(app.ExportTab);
            app.INCLUDEPanel.Title = 'INCLUDE';
            app.HETATOMSCheckBox = uicheckbox(app.INCLUDEPanel);
            app.HETATOMSCheckBox.Text = 'HETATOMS';
            app.HETATOMSCheckBox.Value = true;
            app.AllrecordsCheckBox = uicheckbox(app.INCLUDEPanel);
            app.AllrecordsCheckBox.Text = 'All records';
            app.SavePdbButton = uibutton(app.ExportTab, 'push');
            app.SavePdbButton.ButtonPushedFcn = createCallbackFcn(app, @SavePdbButtonPushed, true);
            app.SavePdbButton.Icon = 'export_100px.png';
            app.SavePdbButton.Enable = 'off';
            app.SavePdbButton.Tooltip = {'Save aligned structures as new pdb files. '};
            app.SavePdbButton.Text = 'Save to PDB file';
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.ZERNIKEPARAMETERSPanel = uipanel(app.SetupTab);
            app.ZERNIKEPARAMETERSPanel.Title = 'ZERNIKE PARAMETERS';
            app.GridresolutionLabel = uilabel(app.ZERNIKEPARAMETERSPanel);
            app.GridresolutionLabel.Enable = 'off';
            app.GridresolutionLabel.Text = ' Grid resolution';
            app.GridresolutionEditField = uieditfield(app.ZERNIKEPARAMETERSPanel, 'numeric');
            app.GridresolutionEditField.Limits = [32 400];
            app.GridresolutionEditField.ValueChangedFcn = createCallbackFcn(app, @GridresolutionEditFieldValueChanged2, true);
            app.GridresolutionEditField.Enable = 'off';
            app.GridresolutionEditField.Tooltip = {'The molecular surface is computed using a grid. The higher the resolution'; ' the higher the level of structural surface details. '};
            app.GridresolutionEditField.Value = 64;
            app.ZCmomentorderDropDownLabel = uilabel(app.ZERNIKEPARAMETERSPanel);
            app.ZCmomentorderDropDownLabel.Enable = 'off';
            app.ZCmomentorderDropDownLabel.Text = 'ZC-moment order';
            app.ZCmomentorderDropDown = uidropdown(app.ZERNIKEPARAMETERSPanel);
            app.ZCmomentorderDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '25', '30'};
            app.ZCmomentorderDropDown.ValueChangedFcn = createCallbackFcn(app, @ZCmomentorderDropDownValueChanged, true);
            app.ZCmomentorderDropDown.Enable = 'off';
            app.ZCmomentorderDropDown.Value = '20';
            app.SHAPEREPRESENTATIONPanel = uipanel(app.SetupTab);
            app.SHAPEREPRESENTATIONPanel.Title = 'SHAPE REPRESENTATION';
            app.molSurfDropDown = uidropdown(app.SHAPEREPRESENTATIONPanel);
            app.molSurfDropDown.Items = {'solvent excluded ', 'solvent accessible', ''};
            app.molSurfDropDown.ItemsData = {'1', '2'};
            app.molSurfDropDown.Editable = 'on';
            app.molSurfDropDown.ValueChangedFcn = createCallbackFcn(app, @molSurfDropDownValueChanged, true);
            app.molSurfDropDown.Enable = 'off';
            app.molSurfDropDown.Value = '1';
            app.RelativeisovalueEditFieldLabel = uilabel(app.SHAPEREPRESENTATIONPanel);
            app.RelativeisovalueEditFieldLabel.Visible = 'off';
            app.RelativeisovalueEditFieldLabel.Text = 'Relative iso value';
            app.probeRadiusSpinner = uispinner(app.SHAPEREPRESENTATIONPanel);
            app.probeRadiusSpinner.Step = 0.1;
            app.probeRadiusSpinner.Limits = [0 10];
            app.probeRadiusSpinner.ValueChangedFcn = createCallbackFcn(app, @probeRadiusSpinnerValueChanged, true);
            app.probeRadiusSpinner.Enable = 'off';
            app.probeRadiusSpinner.Value = 1.4;
            app.SmearfactorEditFieldLabel = uilabel(app.SHAPEREPRESENTATIONPanel);
            app.SmearfactorEditFieldLabel.Visible = 'off';
            app.SmearfactorEditFieldLabel.Text = 'Smear factor';
            app.SmearfactorEditField = uieditfield(app.SHAPEREPRESENTATIONPanel, 'numeric');
            app.SmearfactorEditField.Limits = [0 1];
            app.SmearfactorEditField.ValueChangedFcn = createCallbackFcn(app, @SmearfactorEditFieldValueChanged, true);
            app.SmearfactorEditField.Visible = 'off';
            app.SmearfactorEditField.Value = 0.3;
            app.RelativeisovalueEditField = uieditfield(app.SHAPEREPRESENTATIONPanel, 'numeric');
            app.RelativeisovalueEditField.Limits = [0 Inf];
            app.RelativeisovalueEditField.ValueChangedFcn = createCallbackFcn(app, @RelativeisovalueEditFieldValueChanged, true);
            app.RelativeisovalueEditField.Visible = 'off';
            app.RelativeisovalueEditField.Value = 0.1;
            app.ShapeRepDropDown = uidropdown(app.SHAPEREPRESENTATIONPanel);
            app.ShapeRepDropDown.Items = {'Molecular surface', 'Density'};
            app.ShapeRepDropDown.ItemsData = {'1', '2'};
            app.ShapeRepDropDown.ValueChangedFcn = createCallbackFcn(app, @ShapeRepDropDownValueChanged, true);
            app.ShapeRepDropDown.Enable = 'off';
            app.ShapeRepDropDown.Value = '1';
            app.shellThicknessSpinner = uispinner(app.SHAPEREPRESENTATIONPanel);
            app.shellThicknessSpinner.Limits = [0 Inf];
            app.shellThicknessSpinner.ValueDisplayFormat = '%.0f';
            app.shellThicknessSpinner.ValueChangedFcn = createCallbackFcn(app, @shellThicknessSpinnerValueChanged, true);
            app.shellThicknessSpinner.Enable = 'off';
            app.shellThicknessSpinner.Value = 2;
            app.shellThicknessLabel = uilabel(app.SHAPEREPRESENTATIONPanel);
            app.shellThicknessLabel.Enable = 'off';
            app.shellThicknessLabel.Text = {'Thickness'; ' (grid units)'};
            app.ProberadiusSpinnerLabel = uilabel(app.SHAPEREPRESENTATIONPanel);
            app.ProberadiusSpinnerLabel.Enable = 'off';
            app.ProberadiusSpinnerLabel.Text = 'Probe radius (Å)';
            app.USEREGIONOFINTERESTPanel = uipanel(app.SetupTab);
            app.USEREGIONOFINTERESTPanel.Title = 'USE REGION OF INTEREST';
            app.fixROICheckBox = uicheckbox(app.USEREGIONOFINTERESTPanel);
            app.fixROICheckBox.ValueChangedFcn = createCallbackFcn(app, @fixROICheckBoxEnabled, true);
            app.fixROICheckBox.Enable = 'off';
            app.fixROICheckBox.Text = 'Fixed';
            app.rotROICheckBox = uicheckbox(app.USEREGIONOFINTERESTPanel);
            app.rotROICheckBox.ValueChangedFcn = createCallbackFcn(app, @rotROICheckBoxValueChanged, true);
            app.rotROICheckBox.Enable = 'off';
            app.rotROICheckBox.Text = 'Rotating';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayoutRight = uigridlayout(app.RightPanel);
            app.TabGroup3 = uitabgroup(app.GridLayoutRight);
            app.ViewshapesTab = uitab(app.TabGroup3);
            app.ViewshapesTab.Title = 'View shapes';
            app.GridLayout3 = uigridlayout(app.ViewshapesTab);
            app.UIAxes = uiaxes(app.GridLayout3);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.Label_momCCResults = uilabel(app.GridLayout3);
            app.Label_momCCResults.Text = '';
            app.ZEALscoreLabel = uilabel(app.GridLayout3);
            app.ZEALscoreLabel.Visible = 'off';
            app.ZEALscoreLabel.Text = 'ZEALscore';
            app.RegionOfInterestTab = uitab(app.TabGroup3);
            app.RegionOfInterestTab.Title = 'Region Of Interest';
            app.Panel_3 = uipanel(app.RegionOfInterestTab);
            app.DefineButton = uibutton(app.Panel_3, 'push');
            app.DefineButton.ButtonPushedFcn = createCallbackFcn(app, @JSmolDefineButtonPushed, true);
            app.DefineButton.Icon = 'cursor_100px.png';
            app.DefineButton.Enable = 'off';
            app.DefineButton.Text = 'Define ';
            app.deleteROI = uibutton(app.Panel_3, 'push');
            app.deleteROI.ButtonPushedFcn = createCallbackFcn(app, @deleteROIButtonPushed, true);
            app.deleteROI.Icon = 'cancel_100px.png';
            app.deleteROI.Enable = 'off';
            app.deleteROI.Text = 'Delete ';
            app.ROIselButton = uibuttongroup(app.Panel_3);
            app.ROIselButton.SelectionChangedFcn = createCallbackFcn(app, @ROIselButtonSelectionChanged, true);
            app.rotROISelect = uiradiobutton(app.ROIselButton);
            app.rotROISelect.Text = 'ROTATING STRUCTURE';
            app.rotROISelect.Value = true;
            app.fixROISelect = uiradiobutton(app.ROIselButton);
            app.fixROISelect.Text = 'FIXED STRUCTURE';
            app.viewROIdefinedPanel = uipanel(app.RegionOfInterestTab);
            app.viewROIdefinedPanel.Title = 'No ROI defined';
            app.UIAxes3 = uiaxes(app.viewROIdefinedPanel);
            title(app.UIAxes3, '')
            xlabel(app.UIAxes3, '')
            ylabel(app.UIAxes3, '')
            app.UIAxes3.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.ZEALversionLabel = uilabel(app.GridLayoutRight);
            app.ZEALversionLabel.Text = 'ZErnike-based shape AlignmenL (1.0)';
            app.GitHubLogo = uiimage(app.GridLayoutRight);
            app.GitHubLogo.ImageClickedFcn = createCallbackFcn(app, @GitHubLogoClicked, true);
            app.GitHubLogo.Tooltip = {'View ZEAL source code on GitHub.'};
            app.GitHubLogo.ImageSource = 'GitHub-Mark-32px.png';
            % Show the figure after all components are created
            app.ZEALUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = zeal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ZEALUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ZEALUIFigure)
        end
    end
end